classdef SQZ_Delaunay_detail < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        SectionYZ_Slider      matlab.ui.control.Slider
        SectionYZLabel        matlab.ui.control.Label
        SectionXZ_Slider      matlab.ui.control.Slider
        SectionXZLabel        matlab.ui.control.Label
        SectionXY_Slider      matlab.ui.control.Slider
        SectionXYSliderLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SectionXY_Slider
        function SectionYZ_SliderValueChanged(app, event)
value = app.SectionXY_Slider.Value;
load('LGG3_Detail.mat')
xslice = [];   
yslice = [];
zslice = [value];
figure
s = slice(LGG3_Detail,xslice,yslice,zslice);
s.EdgeColor = 'none';
s.FaceColor = 'interp';
        end

        % Value changed function: SectionXZ_Slider
        function SectionXZ_SliderValueChanged(app, event)
value = app.SectionXZ_Slider.Value;
load('LGG3_Detail.mat')
xslice = [];   
yslice = [value];
zslice = [];
figure
s = slice(LGG3_Detail,xslice,yslice,zslice);
s.EdgeColor = 'none';
s.FaceColor = 'interp';
            
        end

        % Value changed function: SectionYZ_Slider
        function SectionXY_SliderValueChanged(app, event)
value = app.SectionYZ_Slider.Value;
load('LGG3_Detail.mat')
xslice = [value];   
yslice = [];
zslice = [];
figure
s = slice(LGG3_Detail,xslice,yslice,zslice);
s.EdgeColor = 'none';
s.FaceColor = 'interp';           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.SectionXYSliderLabel = uilabel(app.UIFigure);
            app.SectionXYSliderLabel.Text = 'Section XY';
            app.SectionXY_Slider = uislider(app.UIFigure);
            app.SectionXY_Slider.Limits = [1 69];
            app.SectionXY_Slider.ValueChangedFcn = createCallbackFcn(app, @SectionYZ_SliderValueChanged, true);
            app.SectionXY_Slider.Value = 1;
            app.SectionXZLabel = uilabel(app.UIFigure);
            app.SectionXZLabel.Text = 'Section XZ';
            app.SectionXZ_Slider = uislider(app.UIFigure);
            app.SectionXZ_Slider.Limits = [1 201];
            app.SectionXZ_Slider.ValueChangedFcn = createCallbackFcn(app, @SectionXZ_SliderValueChanged, true);
            app.SectionXZ_Slider.Value = 1;
            app.SectionYZLabel = uilabel(app.UIFigure);
            app.SectionYZLabel.Text = 'Section YZ';
            app.SectionYZ_Slider = uislider(app.UIFigure);
            app.SectionYZ_Slider.Limits = [1 200];
            app.SectionYZ_Slider.ValueChangedFcn = createCallbackFcn(app, @SectionXY_SliderValueChanged, true);
            app.SectionYZ_Slider.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SQZ_Delaunay_detail

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end