classdef Euler < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EulerUIFigure         matlab.ui.Figure
        HelpMenu              matlab.ui.container.Menu
        AspectRatioCheckBox   matlab.ui.control.CheckBox
        TheEulerproblemofthetwoattractivefixedpointsLabel  matlab.ui.control.Label
        Panel                 matlab.ui.container.Panel
        Image2                matlab.ui.control.Image
        gamma                 matlab.ui.control.NumericEditField
        yLabel_3              matlab.ui.control.Label
        H                     matlab.ui.control.NumericEditField
        yLabel_2              matlab.ui.control.Label
        Image                 matlab.ui.control.Image
        Primarymass1Label     matlab.ui.control.Label
        Step                  matlab.ui.control.NumericEditField
        StepEditFieldLabel    matlab.ui.control.Label
        Totaltime             matlab.ui.control.NumericEditField
        TotaltimeLabel        matlab.ui.control.Label
        m                     matlab.ui.control.NumericEditField
        mLabel                matlab.ui.control.Label
        V_y                   matlab.ui.control.NumericEditField
        V_yEditFieldLabel     matlab.ui.control.Label
        V_x                   matlab.ui.control.NumericEditField
        v_xLabel              matlab.ui.control.Label
        y                     matlab.ui.control.NumericEditField
        yLabel                matlab.ui.control.Label
        x                     matlab.ui.control.NumericEditField
        xLabel                matlab.ui.control.Label
        CalculateandplottrajectoryButton  matlab.ui.control.Button
        SecondarymassLabel    matlab.ui.control.Label
        InitialvelocityLabel  matlab.ui.control.Label
        StartingpointLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateandplottrajectoryButton
        function CalculateandplottrajectoryButtonPushed(app, event)
         %% The Euler Problem of the two Fixed Centers  %%
% The Euler problem consists in finding the plane motion of a particle which is 
% subjected to the gravitational attraction of two fixed masses: the primary, with 
% mass = 1 and lying in the origin of the Cartesian coordinates, and the secondary, 
% with mass = m and lying in the point (0,2). We use the method of the separation 
% of variables in the Hamilton-Jacobi equation written in elliptic-hyperbolic coordinates. 
% The problem is so reduced to the quadratures, i.e., to performing some integrals. 
% In the present case, the involved integrals are the elliptic ones of the first 
% kind, and the central question is to reduce them to the canonical form of Legendre. 
% See the book: B. Cordani, The Kepler Problem, 2003, Birkhäuser. 
% First of all we must define the initial position of the moving particle giving, 
% in Cartesian coordinates, its position x and y, its initial velocity Vx and 
% Vy, the value, between 0 and 1, of the second mass m. For a 
% better graphical result the parameter step may be decreased. 
% The evolutive parameter tau varies in [0,delta]; increasing delta more revolutions 
% are calculated. Be aware of the fact that with some initial conditions
% the program reports error, since the limit cases of multiple 
% roots in the integrand can not be treated; notice however that the integrals 
% involved in this limiting cases are elementary. When the total energy H is greater 
% than zero, the orbits are not periodic and the parameter delta must be choosen 
% not too great; proceed by trials. Sometimes, when the
% moving mass transits close to one of the fixed masses, it may
% follow the wrong path, continuing with an almost straight trajectory instead
% of circling the fixed mass; try to fix the drawback by reducing the step
% but take into account that the problem is "structural", since the x-coordinate
% is defined as a square root and the algorithm to avoid the "rebound" requires
% crowding the computed points that are close to the singularity.
tic
fig = app.EulerUIFigure; 
d = uiprogressdlg(fig,'Title','Please wait, EULER is computing ...','Indeterminate','on');
drawnow
syms u k z

x0 = get(app.x,'Value');
y0 = get(app.y,'Value');
Vx0 = get(app.V_x,'Value');
Vy0 = get(app.V_y,'Value');
m = get(app.m,'Value');
delta = get(app.Totaltime,'Value');
step = get(app.Step,'Value');
tau = [0 : step : delta];
if x0 == 0 & (y0 == 0 | y0 == 2)
    warndlg('The starting point can not be one the attractive masses.')
    return
end
 
% Calculate the initial value of the elliptic coordinates 
% and relative canonical momenta.
csi0=1/2*(sqrt(x0^2+y0^2)+sqrt(x0^2+(y0-2)^2));
eta0=1/2*(sqrt(x0^2+y0^2)-sqrt(x0^2+(y0-2)^2));
Vcsi=1/2*((x0*Vx0+y0*Vy0)/(sqrt(x0^2+y0^2))+(x0*Vx0+(y0-2)*Vy0)/sqrt(x0^2+(y0-2)^2));
Veta=1/2*((x0*Vx0+y0*Vy0)/(sqrt(x0^2+y0^2))-(x0*Vx0+(y0-2)*Vy0)/sqrt(x0^2+(y0-2)^2));
Pcsi=(csi0^2-eta0^2)/(csi0^2-1)*Vcsi;
Peta=(csi0^2-eta0^2)/(1-eta0^2)*Veta;

% Let us calculate the two first integrals of the moton H and gamma.
H = 1/2*(Vx0^2+Vy0^2) - 1/sqrt(x0^2+y0^2) - m/sqrt(x0^2+(y0-2)^2);
gamma = -Vx0*(y0*Vx0-x0*Vy0) + y0/sqrt(x0^2+y0^2) + 1/2*(y0*Vx0-x0*Vy0)^2 - m*(y0-2)/sqrt(x0^2+(y0-2)^2) + H;

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Let us consider the polynomial of the fourth degree in csi. Its roots are
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
csi1 = -1;
csi2 = 1;
csi3 = (-(1+m)+sqrt((1+m)^2+4*H*gamma))/(2*H);
csi4 = (-(1+m)-sqrt((1+m)^2+4*H*gamma))/(2*H);
if abs(csi3 + 1) < 1e-14 | abs(csi3 - 1) < 1e-14 | abs(csi4 + 1) < 1e-14 | abs(csi4 - 1) < 1e-14 | abs(csi3 - csi4) < 1e-14    
    warndlg('Multiple roots. I am unable to continue but the integrals involved in this limiting case are elementary.')
    return
end
csi_list = [csi1,csi2,csi3,csi4];

% To reduce the polynomial of the fourth degree to Legendre normal form the roots must
% not interlace. Otherwise we must consider the two polynomials obtained as 
% products of the bynomials with non interlacing roots.
% Let us consider the coefficients of these polynomials
% -- of the type: a*x^2 + 2*b*x + c = 0 -- and calculate the following 
% constants, necessary for the transformations that reduce the elliptic integral.
list = sort(csi_list);
a1=1;
b1=-(list(1)+list(2))/2;
c1=list(1)*list(2);
a2=H;
b2=-H*(list(3)+list(4))/2;
c2=H*list(3)*list(4);

L1=(-(a1*c2+a2*c1-2*b1*b2)+sqrt((a1*c2+a2*c1-2*b1*b2)^2-4*(b2^2-a2*c2)*(b1^2-a1*c1)))/(2*(b2^2-a2*c2));
L2=(-(a1*c2+a2*c1-2*b1*b2)-sqrt((a1*c2+a2*c1-2*b1*b2)^2-4*(b2^2-a2*c2)*(b1^2-a1*c1)))/(2*(b2^2-a2*c2));
alpha=(L1*b2-b1)/(a1-L1*a2);
beta=(L2*b2-b1)/(a1-L2*a2);
A1=L1*(L1*a2-a1)/(L1-L2)+a1-L1*a2;
B1=L1*(a1-L2*a2)/(L1-L2);
A2=(L1*a2-a1)/(L1-L2);
B2=(a1-L2*a2)/(L1-L2);
a=sqrt(abs(B1/A1));
b=sqrt(abs(B2/A2));

% Consider the following six cases, since the reducing transformations depend 
% on the coefficients of the polynomials, thus on the sign combinations of the 
% now calculated constants A1, B1, A2, B2. For each of the six cases we obtain 
% the elliptic coordinate csi as a function of tau.
% a) ----------------------------------------------------------------------
if A1>0 & B1>0 & A2>0 & B2>0    
    if a<b 
        a_=a; b_=b; 
    else
        a_=b; b_=a;
    end
    sol(u,k) = (beta*b_*jacobiCN(u,k) - alpha*jacobiSN(u,k))./(b_*jacobiCN(u,k) - jacobiSN(u,k));
    coeff_tau = -(alpha-beta)*sqrt(A1*A2)*b_;
    k=(b_^2-a_^2)/(b_^2);
    Compute_csi
% b) ----------------------------------------------------------------------
elseif A1>0 & B1<0 & A2>0 & B2>0
    a_=a; b_=b;
    sol(u,k) = (beta*a_*1./jacobiCN(u,k)-alpha)./(a_./jacobiCN(u,k)-1);
    coeff_tau = (alpha-beta)*sqrt(A1*A2)*sqrt(a_^2+b_^2);
    k=(b_^2)/(a_^2+b_^2);    
    Compute_csi 
% c) ----------------------------------------------------------------------
elseif A1<0 & B1>0 & A2>0 & B2>0
    a_=a; b_=b;
    sol(u,k) = (beta*a_*jacobiCN(u,k)-alpha)./(a_*jacobiCN(u,k)-1);
    coeff_tau = -(alpha-beta)*sqrt(abs(A1)*A2)*sqrt(a_^2+b_^2);
    k=a_^2/(a_^2+b_^2);
    Compute_csi
% d) ----------------------------------------------------------------------
elseif A1>0 & B1<0 & A2>0 &B2<0
    if a<b
        a_=a; b_=b; 
    else
        a_=b; b_=a;
    end
    sol(u,k) = (beta*b_ - alpha*jacobiSN(u,k))./(b_ - jacobiSN(u,k));
    coeff_tau = -(alpha-beta)*sqrt(A1*A2)*b_;
    k=(a_^2)/(b_^2);
    Compute_csi     
% e) ----------------------------------------------------------------------
elseif A1>0 & B1<0 & A2<0 & B2>0
    a_=a; b_=b;
    sol(u,k) = (beta*sqrt(b_^2-(b_^2-a_^2)*(jacobiSN(u,k)).^2)-alpha)./(sqrt(b_^2-(b_^2-a_^2)*(jacobiSN(u,k)).^2)-1);
    coeff_tau = -(alpha-beta)*sqrt(A1*abs(A2))*b_;
    k=(b_^2-a_^2)/(b_^2);
    Compute_csi   
% f) ----------------------------------------------------------------------
elseif A1<0 & B1>0 & A2<0 & B2>0
    if a<b
        a_=a; b_=b; 
    else
        a_=b; b_=a;
    end
    sol(u,k) = (beta*a_*jacobiSN(u,k)-alpha)./(a_*jacobiSN(u,k)-1);
    coeff_tau = (alpha-beta)*sqrt(A1*A2)*b_;
    k=(a_^2)/(b_^2);
    Compute_csi
end
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
% Analogously, let us consider the polynomial of the fourth degree in eta. Its roots are
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
syms u k z
eta1 = -1;
eta2 = 1;
eta3 = (-(1-m)+sqrt((1-m)^2+4*H*gamma))/(2*H);
eta4 = (-(1-m)-sqrt((1-m)^2+4*H*gamma))/(2*H);
if abs(eta3 + 1) < 1e-14 | abs(eta3 - 1) < 1e-14 | abs(eta4 + 1) < 1e-14 | abs(eta4 - 1) < 1e-14 | abs(eta3 - eta4) < 1e-14
    warndlg('Multiple roots. I am unable to continue but the integrals involved in this limiting case are elementary.')
    return
end
eta_list = [eta1,eta2,eta3,eta4];
if isreal(eta3)
    list = sort(eta_list);
end

% Since in this case the polynomial may have complex roots, let us control their nature.
% If the roots are real, they must not interlace. Let us control that and in 
% case consider the two polynomials obtained as products of the bynomials with 
% non interlacing roots
if isreal(eta3)
    if eta4 < eta0 | (Vx0 == 0 & Vy0 == 0 & abs(eta4-eta0) < 5*eps)
        a1=1;
        b1=-(list(1)+list(2))/2;
        c1=list(1)*list(2);
        a2=H;
        b2=-H*(list(3)+list(4))/2;
        c2=H*list(3)*list(4);
    elseif eta4 > eta0
        a1=H;
        b1=-H*(list(1)+list(2))/2;
        c1=H*list(1)*list(2);
        a2=1;
        b2=-(list(3)+list(4))/2;
        c2=list(3)*list(4);
    end
else
        a1=H;
        b1=-H*(eta1+eta2)/2;
        c1=H*eta1*eta2;
        a2=1;
        b2=-(eta3+eta4)/2;
        c2=eta3*eta4;    
end

% As before, calculate the constants for the reducing transformation 
if b1==0 & b2==0
    A1=a1; B1=c1; A2=a2; B2=c2; alpha=0;
else
    L1=(-(a1*c2+a2*c1-2*b1*b2)+sqrt((a1*c2+a2*c1-2*b1*b2)^2-4*(b2^2-a2*c2)*(b1^2-a1*c1)))/(2*(b2^2-a2*c2));
    L2=(-(a1*c2+a2*c1-2*b1*b2)-sqrt((a1*c2+a2*c1-2*b1*b2)^2-4*(b2^2-a2*c2)*(b1^2-a1*c1)))/(2*(b2^2-a2*c2));
    alpha=(L1*b2-b1)/(a1-L1*a2);  
    beta=(L2*b2-b1)/(a1-L2*a2);
    A1=L1*(L1*a2-a1)/(L1-L2)+a1-L1*a2;
    B1=L1*(a1-L2*a2)/(L1-L2);
    A2=(L1*a2-a1)/(L1-L2);
    B2=(a1-L2*a2)/(L1-L2);
end
a=sqrt(abs(B1/A1)); b=sqrt(abs(B2/A2));

% As before we have six cases, but with two subcases. Afterwards we obtain
% a) ---------------------------------------------------------------------- 
if A1>0 & B1>0 & A2>0 & B2>0
    if a<b
        a_=a; b_=b;
    else
        a_=b; b_=a;
    end
    sol(u,k) = (beta*b_*jacobiCN(u,k) - alpha*jacobiSN(u,k))./(b_*jacobiCN(u,k) - jacobiSN(u,k));
    coeff_tau = (alpha-beta)*sqrt(A1*A2)*b_;
    k=(b_^2-a_^2)/(b_^2);
    Compute_eta
% b) ----------------------------------------------------------------------
elseif A1>0 & B1<0 & A2>0 & B2>0
    a_=a; b_=b;
    sol(u,k) = ((beta*a_)./jacobiCN(u,k)-alpha)./(a_./jacobiCN(u,k)-1);
    coeff_tau = -(alpha-beta)*sqrt(A1*A2)*sqrt(a_^2+b_^2);
    k=b_^2/(a_^2+b_^2);
    Compute_eta
% c1) ---------------------------------------------------------------------
elseif A1<0 & B1>0 & A2>0 & B2>0 & alpha~=0 
    a_=a; b_=b;   
    sol(u,k) = ((beta*a_)./jacobiCN(u,k)-alpha)./(a_./jacobiCN(u,k)-1);
    coeff_tau = -(alpha-beta)*sqrt(A1*A2)*sqrt(a_^2+b_^2);
    k=b_^2/(a_^2+b_^2);
    Compute_eta
% c2) ---------------------------------------------------------------------
elseif A1<0 & B1>0 & A2>0 & B2>0 & alpha==0 
    a_=a; b_=b;    
    sol(u,k) = a_*jacobiCN(u,k);
    coeff_tau = sqrt(abs(A1)*A2)*sqrt(a_^2+b_^2);
    k=a_^2/(a_^2+b_^2);
    Compute_eta
% d) ----------------------------------------------------------------------
elseif A1>0 & B1<0 & A2>0 & B2<0 
    if a<b 
        a_=a; b_=b 
    else
        a_=b; b_=a;
    end
    sol(u,k) = (beta*b_ - alpha*jacobiSN(u,k))./(b_ - jacobiSN(u,k));
    coeff_tau = (alpha-beta)*sqrt(A1*A2)*b_;
    k=(a_^2)/(b_^2);
    Compute_eta
% e1) ---------------------------------------------------------------------
elseif A1>0 & B1<0 & A2<0 & B2>0 & H<0 
    a_=a; b_=b;    
    sol(u,k) = (beta*sqrt((b_^2)-(b_^2-a_^2)*(jacobiSN(u,k)).^2)-alpha)./(sqrt((b_^2)-(b_^2-a_^2)*(jacobiSN(u,k)).^2)-1);
    coeff_tau = (alpha-beta)*sqrt(A1*abs(A2))*b_;
    k=(b_^2-a_^2)/(b_^2);
    Compute_eta  
% e2) ---------------------------------------------------------------------
elseif A1>0 & B1<0 & A2<0 & B2>0 & H>0 
    a_=a; b_=b;    
    sol(u,k) = (-beta*sqrt((b_^2)-(b_^2-a_^2)*(jacobiSN(u,k)).^2)-alpha)./(-sqrt((b_^2)-(b_^2-a_^2)*(jacobiSN(u,k)).^2)-1);
    coeff_tau = (alpha-beta)*sqrt(A1*abs(A2))*b_;
    k=(b_^2-a_^2)/(b_^2);
    Compute_eta     
% f) ----------------------------------------------------------------------
elseif A1<0 & B1>0 & A2<0 & B2>0 
    if a<b 
        a_=a; b_=b 
    else
        a_=b; b_=a;
    end
    sol(u,k) = (beta*a_*jacobiSN(u,k)-alpha)./(a_*jacobiSN(u,k)-1);
    coeff_tau = -(alpha-beta)*sqrt(abs(A1*A2))*b_;
    k=(a_^2)/(b_^2);
    Compute_eta   
end
% -------------------------------------------------------------------------

% From definition of elliptic coordinates
x = sqrt((csi.^2-1).*(1-eta.^2));
if x0 < 0
    x = -x;
end
y = csi.*eta+1;

% To avoid the rebound
numpoint = floor(delta/step + 1);
for h = 3 : numpoint
    if abs(x(h)) > abs(x(h-1)) & abs(x(h-1)) < abs(x(h-2))
        if abs(x(h)) > abs(x(h-2))
            for r = h-1 : numpoint
                x(r) = -x(r);
            end
        else
            for r = h : numpoint
                x(r) = -x(r);
            end
        end
    end
end

% To avoid error when x0 = 0 and Vx0 < 0
if x0 == 0 & Vx0 < 0
    x = -x;
end

%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
%               And finally plot the orbit                             %
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
figure
hold on
plot(x,y);
if get(app.AspectRatioCheckBox,'Value')
    daspect([1 1 1]);
end
title('Euler problem of the two attractive fixed centers', 'FontSize',12);
xlabel('\itx', 'FontSize',18); ylabel('\ity', 'FontSize',18);
plot(0,0,'-mo','MarkerEdgeColor','black','MarkerFaceColor','black','MarkerSize',5)
if m ~= 0
    plot(0,2,'-mo','MarkerEdgeColor','black','MarkerFaceColor','black','MarkerSize',5)
end
plot(x(1),y(1),'*','MarkerEdgeColor','red','MarkerFaceColor','red','MarkerSize',8)
quiver(x(1),y(1),Vx0,Vy0, 0.4, Color=[1 0 0], LineWidth=0.5)
hold off
close(d)
toc
        end

        % Value changed function: x
        function xValueChanged(app, event)
x0 = get(app.x,'Value');
y0 = get(app.y,'Value');
if x0 == 0 & (y0 == 0 | y0 == 2)
    warndlg('The starting point can not be one the attractive masses.')
%    return
end
Display_H_gamma
        end

        % Value changed function: y
        function yValueChanged(app, event)
x0 = get(app.x,'Value');
y0 = get(app.y,'Value');
if x0 == 0 & (y0 == 0 | y0 == 2)
    warndlg('The starting point can not be one the attractive masses.')
%    return
end
Display_H_gamma
        end

        % Value changed function: V_x
        function V_xValueChanged(app, event)
Display_H_gamma
        end

        % Value changed function: V_y
        function V_yValueChanged(app, event)
Display_H_gamma
        end

        % Value changed function: m
        function mValueChanged(app, event)
Display_H_gamma
        end

        % Value changed function: Totaltime
        function TotaltimeValueChanged(app, event)
            value = app.Totaltime.Value;
            if value < 6
                warndlg(['It is possible that the ''Total time'' is too small and the starting point is not correct. '...
                         'In this case increase the ''Total time''.'], 'Warning')
            end
        end

        % Menu selected function: HelpMenu
        function Help(app, event)
            !README.txt
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.EulerUIFigure = uifigure('Visible', 'off');
            app.EulerUIFigure.Name = 'Euler';
            app.EulerUIFigure.Icon = fullfile(pathToMLAPP, 'Euler.jpg');
            app.HelpMenu = uimenu(app.EulerUIFigure);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @Help, true);
            app.HelpMenu.Text = 'Help';
            app.StartingpointLabel = uilabel(app.EulerUIFigure);
            app.StartingpointLabel.Text = 'Starting point:';
            app.InitialvelocityLabel = uilabel(app.EulerUIFigure);
            app.InitialvelocityLabel.Text = 'Initial velocity:';
            app.SecondarymassLabel = uilabel(app.EulerUIFigure);
            app.SecondarymassLabel.Text = 'Secondary mass:';
            app.CalculateandplottrajectoryButton = uibutton(app.EulerUIFigure, 'push');
            app.CalculateandplottrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateandplottrajectoryButtonPushed, true);
            app.CalculateandplottrajectoryButton.Icon = fullfile(pathToMLAPP, 'Integrate.jpg');
            app.CalculateandplottrajectoryButton.Text = 'Calculate and plot trajectory';
            app.xLabel = uilabel(app.EulerUIFigure);
            app.xLabel.Interpreter = 'latex';
            app.xLabel.Text = '$x=$';
            app.x = uieditfield(app.EulerUIFigure, 'numeric');
            app.x.ValueChangedFcn = createCallbackFcn(app, @xValueChanged, true);
            app.x.Value = 1;
            app.yLabel = uilabel(app.EulerUIFigure);
            app.yLabel.Interpreter = 'latex';
            app.yLabel.Text = '$y=$';
            app.y = uieditfield(app.EulerUIFigure, 'numeric');
            app.y.ValueChangedFcn = createCallbackFcn(app, @yValueChanged, true);
            app.v_xLabel = uilabel(app.EulerUIFigure);
            app.v_xLabel.Interpreter = 'latex';
            app.v_xLabel.Text = '$V_x=$';
            app.V_x = uieditfield(app.EulerUIFigure, 'numeric');
            app.V_x.ValueChangedFcn = createCallbackFcn(app, @V_xValueChanged, true);
            app.V_yEditFieldLabel = uilabel(app.EulerUIFigure);
            app.V_yEditFieldLabel.Interpreter = 'latex';
            app.V_yEditFieldLabel.Text = '$V_y=$';
            app.V_y = uieditfield(app.EulerUIFigure, 'numeric');
            app.V_y.ValueChangedFcn = createCallbackFcn(app, @V_yValueChanged, true);
            app.V_y.Value = 1;
            app.mLabel = uilabel(app.EulerUIFigure);
            app.mLabel.Text = 'm =';
            app.m = uieditfield(app.EulerUIFigure, 'numeric');
            app.m.ValueChangedFcn = createCallbackFcn(app, @mValueChanged, true);
            app.m.Value = 0.1;
            app.TotaltimeLabel = uilabel(app.EulerUIFigure);
            app.TotaltimeLabel.Text = 'Total time =';
            app.Totaltime = uieditfield(app.EulerUIFigure, 'numeric');
            app.Totaltime.ValueChangedFcn = createCallbackFcn(app, @TotaltimeValueChanged, true);
            app.Totaltime.Value = 10;
            app.StepEditFieldLabel = uilabel(app.EulerUIFigure);
            app.StepEditFieldLabel.Text = 'Step =';
            app.Step = uieditfield(app.EulerUIFigure, 'numeric');
            app.Step.Value = 0.01;
            app.Primarymass1Label = uilabel(app.EulerUIFigure);
            app.Primarymass1Label.Text = 'Primary mass = 1';
            app.Image = uiimage(app.EulerUIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Euler_orbits.jpg');
            app.yLabel_2 = uilabel(app.EulerUIFigure);
            app.yLabel_2.Interpreter = 'latex';
            app.yLabel_2.Text = '$H=$';
            app.H = uieditfield(app.EulerUIFigure, 'numeric');
            app.H.Editable = 'off';
            app.H.Value = -0.5447;
            app.yLabel_3 = uilabel(app.EulerUIFigure);
            app.yLabel_3.Interpreter = 'latex';
            app.yLabel_3.Text = '$\gamma=$';
            app.gamma = uieditfield(app.EulerUIFigure, 'numeric');
            app.gamma.Editable = 'off';
            app.gamma.Value = -0.04472;
            app.Image2 = uiimage(app.EulerUIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'arrow_right.png');
            app.Panel = uipanel(app.EulerUIFigure);
            app.TheEulerproblemofthetwoattractivefixedpointsLabel = uilabel(app.EulerUIFigure);
            app.TheEulerproblemofthetwoattractivefixedpointsLabel.Text = 'The Euler problem of the two attractive fixed points';
            app.AspectRatioCheckBox = uicheckbox(app.EulerUIFigure);
            app.AspectRatioCheckBox.Text = 'Aspect ratio 1:1';
            % Show the figure after all components are created
            app.EulerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Euler

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EulerUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EulerUIFigure)
        end
    end
end