classdef Cr3b < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Circularrestricted3bodyproblemUIFigure  matlab.ui.Figure
        uipanel5              matlab.ui.container.Panel
        pushbutton_AAG        matlab.ui.control.Button
        pushbutton_AAR        matlab.ui.control.Button
        PointnumberAA         matlab.ui.control.EditField
        Trajectnumber         matlab.ui.control.EditField
        text21                matlab.ui.control.Label
        text20                matlab.ui.control.Label
        uipanel4              matlab.ui.container.Panel
        pushbutton_VerticalR  matlab.ui.control.Button
        pushbutton_VerticalG  matlab.ui.control.Button
        Surfnumber            matlab.ui.control.EditField
        text19                matlab.ui.control.Label
        uipanel2              matlab.ui.container.Panel
        text22                matlab.ui.control.Label
        pushbutton_3DR        matlab.ui.control.Button
        pushbutton_3DG        matlab.ui.control.Button
        Clip                  matlab.ui.control.EditField
        Shift                 matlab.ui.control.EditField
        Lngth                 matlab.ui.control.EditField
        Height                matlab.ui.control.EditField
        Pointnumber3D         matlab.ui.control.EditField
        Lightpoint_el         matlab.ui.control.EditField
        Lightpoint_az         matlab.ui.control.EditField
        Viewpoint_el          matlab.ui.control.EditField
        Viewpoint_az          matlab.ui.control.EditField
        text10                matlab.ui.control.Label
        text9                 matlab.ui.control.Label
        text8                 matlab.ui.control.Label
        text7                 matlab.ui.control.Label
        text5                 matlab.ui.control.Label
        uipanel1              matlab.ui.container.Panel
        edit_dist             matlab.ui.control.EditField
        G3_Label              matlab.ui.control.Label
        d_3Label              matlab.ui.control.Label
        d_2Label              matlab.ui.control.Label
        d_1Label              matlab.ui.control.Label
        s_3Label              matlab.ui.control.Label
        s_2Label              matlab.ui.control.Label
        s_1Label              matlab.ui.control.Label
        text27                matlab.ui.control.Label
        text26                matlab.ui.control.Label
        text25                matlab.ui.control.Label
        edit_p2               matlab.ui.control.EditField
        edit_Zeeman3          matlab.ui.control.EditField
        edit_Euler1           matlab.ui.control.EditField
        text24                matlab.ui.control.Label
        text23                matlab.ui.control.Label
        edit_m                matlab.ui.control.EditField
        pushbutton_Update     matlab.ui.control.Button
        D3                    matlab.ui.control.EditField
        D2                    matlab.ui.control.EditField
        D1                    matlab.ui.control.EditField
        S3                    matlab.ui.control.EditField
        S2                    matlab.ui.control.EditField
        S1                    matlab.ui.control.EditField
        G3                    matlab.ui.control.EditField
        text1                 matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: G3
        function edit_G3_callback(app, event)
            set(app.pushbutton_Update, 'Enable','on') 
        end

        % Value changed function: edit_m
        function edit_m_callback(app, event)
            set(app.pushbutton_Update, 'Enable','on')
        end

        % Value changed function: edit_dist
        function edit_dist_callback(app, event)
            if isdeployed
                set(app.edit_dist,'Value','2');
                warndlg('In the compiled version it is not allowed to change the value of the distance between the two primaries.');
            else
                warndlg('Check if the "distance" value is synchronized with the "dist1" value in Euler 1.m.');
            end
            set(app.pushbutton_Update, 'Enable','on')            
        end

        % Button pushed function: pushbutton_Update
        function pushbutton_update(app, event)
            m = str2num(get(app.edit_m,'Value'));
            d = str2num(get(app.edit_dist,'Value'));
            Euler1 = m;
            Zeeman3 = sqrt((1+m)/d^3);
            p2 = -m/sqrt(d*(1+m));
            set(app.edit_Euler1,'Value', num2str(Euler1));
            set(app.edit_Zeeman3,'Value', num2str(Zeeman3));
            set(app.edit_p2,'Value', num2str(p2));
            alpha = 3/2*m/d^2;  beta = sqrt((1+m)/d^3);
            set(app.S1,'Value', num2str(alpha/sqrt(alpha^2+beta^2)));
            set(app.S2,'Value', num2str(0));
            set(app.S3,'Value', num2str(beta/sqrt(alpha^2+beta^2)));
            set(app.D1,'Value', num2str(-alpha/sqrt(alpha^2+beta^2)));
            set(app.D2,'Value', num2str(0));
            set(app.D3,'Value', num2str(beta/sqrt(alpha^2+beta^2)));
            set(app.pushbutton_Update, 'Enable','off')
            Cr3bstruct
            save('NormalForm/Cr3bsetting.mat', '-struct', 'Cr3bstructure')
        end

        % Button pushed function: pushbutton_3DR
        function plot_3DR(app, event)
            n = str2num(get(app.Pointnumber3D,'Value'));
            c = str2num(get(app.G3,'Value'));
            lngth = str2num(get(app.Lngth,'Value'));
            shift = str2num(get(app.Shift,'Value'));
            height = str2num(get(app.Height,'Value'));
            clip = str2num(get(app.Clip,'Value'));
            figure
            hold on
            if c ~= 0
                s = (0 : acosh((1+c^2)/2/c)/n : acosh((1+c^2)/2/c));
                t = (0 : 2*pi/n : 2*pi)';
                S = ones(size(s))'*s;
                T = t*ones(size(t))';
                X = sqrt(1 + c^2 - 2*c*cosh(S));
                Y = 2*c*sinh(S).*sin(T);
                Z = 2*c*sinh(S).*cos(T);
                surf(X,Y,Z,'FaceColor',[0.7 0.7 0.7],...
                    'EdgeColor','none',...
                    'FaceLighting','phong')
                %----------------------------------------------------------------------
                X = -X;
                surf(X,Y,Z,'FaceColor',[0.7 0.7 0.7],...
                    'EdgeColor','none',...
                    'FaceLighting','phong')
                %----------------------------------------------------------------------
            elseif c == 0
                s = (-1 : 2/n : 1);
                t = (0 : 2*pi/n : 2*pi)';
                S = ones(size(s))'*s;
                T = t*ones(size(t))';
                X = S;
                Y = (1-S.^2).*sin(T);
                Z = (1-S.^2).*cos(T);
                surf(X,Y,Z,'FaceColor',[0.7 0.7 0.7],...
                    'EdgeColor','none',...
                    'FaceLighting','phong')
            end
            xlabel('\it\xi_1', 'FontSize',14)
            ylabel('\it\xi_2', 'FontSize',14)
            zlabel('\it\xi_3', 'FontSize',14)
            %%%%%%%%%%%% Level surface Hp of type R %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            u = lngth*(-(1 - c) + shift : 2*(1 - c)/n : (1 - c) + shift);
            v = 1.05*(-(1 - c^2) : 2*(1 - c^2)/n : (1 - c^2))';
            U = ones(size(u))'*u;
            V = v*ones(size(v))';
            X = U;
            Y = V;
            Z = -4*U.^2 + height*(1-c^2);
            surf(X,Y,Z,'FaceColor',[0.85 0.85 0.85],...
                'EdgeColor','none',...
                'FaceLighting','phong')
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            title('Kepler manifold of \itR\rm - type', 'FontSize',14)
            colormap gray
            axis equal
            grid on
            viewpoint_az = str2num(get(app.Viewpoint_az,'Value'));
            viewpoint_el = str2num(get(app.Viewpoint_el,'Value'));
            view(viewpoint_az, viewpoint_el)
            lightpoint_az = str2num(get(app.Lightpoint_az,'Value'));
            lightpoint_el = str2num(get(app.Lightpoint_el,'Value'));
            camlight(lightpoint_az, lightpoint_el)
            material dull
            %material shiny
            axis(clip*[c-1 1-c c^2-1 1-c^2 c^2-1 1-c^2])            
        end

        % Button pushed function: pushbutton_3DG
        function plot_3DG(app, event)
            n = str2num(get(app.Pointnumber3D,'Value'));
            c = str2num(get(app.G3,'Value'));
            lngth = str2num(get(app.Lngth,'Value'));
            shift = str2num(get(app.Shift,'Value'));
            height = str2num(get(app.Height,'Value'));
            clip = str2num(get(app.Clip,'Value'));
            figure
            hold on
            s = (-pi/2 : pi/n : pi/2);
            t = (0 : 2*pi/n : 2*pi)';
            S = ones(size(s))'*s;
            T = t*ones(size(t))';
            X = 1/2*(1-c^2)*cos(S).*cos(T);
            Y = 1/2*(1-c^2)*cos(S).*sin(T);
            Z = real(sqrt(1/2*(1+c^2)+1/2*(1-c^2)*sin(S)));
            surf(X,Y,Z,'FaceColor',[0.7 0.7 0.7],...
                'EdgeColor','none',...
                'FaceLighting','phong')
            %%%%%%%%%%%%%%%%% Level surface Hp of type G %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            u = lngth*(c-1 + shift : 2*(1-c)/n : (1-c) + shift);
            v = 1.1*(-1/2*(1-c^2) : (1-c^2)/n : 1/2*(1-c^2))';
            U = ones(size(u))'*u;
            V = v*ones(size(v))';
            X = U.*real(sqrt(1/2*(-5*U.^2+ height*(1-c^2) + 1 + c^2)));
            Y = V;
            Z = real(sqrt(1/2*(-5*U.^2 + height*(1-c^2) + 1 + c^2)));
            surf(X,Y,Z,'FaceColor',[0.85 0.85 0.85],...
                'EdgeColor','none',...
                'FaceLighting','phong')
            xlabel('\it\eta_1', 'FontSize',14)
            ylabel('\it\eta_2', 'FontSize',14)
            zlabel('\it\eta_3', 'FontSize',14)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            title('Kepler manifold of \itG\rm - type', 'FontSize',14)
            colormap gray
            axis equal
            grid on
            viewpoint_az = str2num(get(app.Viewpoint_az,'Value'));
            viewpoint_el = str2num(get(app.Viewpoint_el,'Value'));
            view(viewpoint_az, viewpoint_el)
            lightpoint_az = str2num(get(app.Lightpoint_az,'Value'));
            lightpoint_el = str2num(get(app.Lightpoint_el,'Value'));
            camlight(lightpoint_az, lightpoint_el)
            material dull
            %material shiny
            %axis(clip*[-1/2*(1-c^2) 1/2*(1-c^2) -1/2*(1-c^2) 1/2*(1-c^2)  0.8*c 1])
            axis([clip*[-1/2*(1-c^2) 1/2*(1-c^2) -1/2*(1-c^2) 1/2*(1-c^2)]  1/2*(c+1)-clip*1/2*(1-c)  1/2*(c+1)+clip*1/2*(1-c)]) 
        end

        % Button pushed function: pushbutton_VerticalR
        function plot_VerticalR(app, event)
            n = str2num(get(app.Pointnumber3D,'Value'));
            c = str2num(get(app.G3,'Value'));
            Surfnumber = str2num(get(app.Surfnumber,'Value'));
            figure
            hold on
            [X,Y] = meshgrid(((c - 1) : 2*(1 - c)/n : (1 - c)),...
                       ((c^2 - 1) : 2*(1 - c^2)/n : (1 - c^2)));
            Z = Y + 4*X.^2;
            contour(X,Y,Z, Surfnumber, '-k');
            if c ~= 0
                s = (-acosh((1+c^2)/2/c) : acosh((1+c^2)/2/c)/n : acosh((1+c^2)/2/c));
                X = real(sqrt(1 + c^2 - 2*c*cosh(s)));
                Y = real(2*c*sinh(s));
                plot(X,Y,'b','LineWidth',3);
                X = -X;
                plot(X,Y,'b','LineWidth',3);
            elseif c == 0
                s = (-1 : 2/n : 1);
                X = s;
                Y = (1-s.^2);
                plot(X,Y,'b','LineWidth',3);
                Y = -Y;
                plot(X,Y,'b','LineWidth',3);
            end
            %{
            xlim(1.1*[c-1  1-c]);
            ylim(1.1*[c^2-1  1-c^2]);
            axis equal
            %}
            title('Kepler manifold of \itR\rm - type (vertical section)', 'FontSize',14)
            xlabel('\it\xi_1', 'FontSize',14), ylabel('\it\xi_3', 'FontSize',14)            
        end

        % Button pushed function: pushbutton_VerticalG
        function plot_VerticalG(app, event)
            n = str2num(get(app.Pointnumber3D,'Value'));
            c = str2num(get(app.G3,'Value'));
            Surfnumber = str2num(get(app.Surfnumber,'Value'));
            figure
            hold on
            [X,Y] = meshgrid(((c - 1) : 2*(1 - c)/n : (1 - c)),...
                       ((c^2 - 1) : 2*(1 - c^2)/n : (1 - c^2)));
            Z = Y + 4*X.^2;
            X1 = X.*real(sqrt(1/2*(Y-X.^2+1+c^2)));
            Y1 = real(sqrt(1/2*(Y-X.^2+1+c^2)));
            contour(X1,Y1,Z, Surfnumber, '-k');
            
            s = (-pi/2 : pi/n : pi/2);
            X = 1/2*(1-c^2)*cos(s);
            Y = real(sqrt(1/2*(1+c^2)+1/2*(1-c^2)*sin(s)));
            plot(X,Y,'b','LineWidth',3);
            X = -X;
            plot(X,Y,'b','LineWidth',3);
            %{
            xlim(1.1/2*[c^2-1  1-c^2]);
            ylim([0.9*c  1.1]);
            axis equal
            %}
            title('Kepler manifold of \itG\rm - type (vertical section)', 'FontSize',14)
            xlabel('\it\eta_1', 'FontSize',14), ylabel('\it\eta_3', 'FontSize',14)            
        end

        % Button pushed function: pushbutton_AAR
        function plot_AAR(app, event)
            N = str2num(get(app.PointnumberAA,'Value'));
            c = str2num(get(app.G3,'Value'));
            Trj = str2num(get(app.Trajectnumber,'Value'));
            figure
            [X,Y] = meshgrid(-180 : 360/N : 180, 1.02*(c-1) : 2*(1-c)/N : 1.02*(1-c));
            Z = cos(pi/180*X).*real(sqrt((1 + c^2 -Y.^2).^2 - 4*c^2)) + 4*Y.^2;
            contour(X,Y,Z, Trj, '-k');
            title('Action-Angle: \itR - \phi_R', 'FontSize',14)
            Degree_x
            xlabel('\it\phi_R \rm(deg)', 'FontSize',14), ylabel('\itR', 'FontSize',14)            
        end

        % Button pushed function: pushbutton_AAG
        function plot_AAG(app, event)
            N = str2num(get(app.PointnumberAA,'Value'));
            c = str2num(get(app.G3,'Value'));
            Trj = str2num(get(app.Trajectnumber,'Value'));
            figure
            [X,Y] = meshgrid(-180 : 360/N : 180, c+eps : (1-c)/N : 1);
            Z = 2*Y.^2 + 5*(sin(pi/180*X).*real(sqrt(1/4*(1-c^2)^2 - (Y.^2-1/2*(1+c^2)).^2))).^2./Y.^2;
            contour(X,Y,Z, Trj, '-k');
            title('Action-Angle: \itG - \omega', 'FontSize',14)
            Degree_x
            xlabel('\it\omega \rm(deg)', 'FontSize',14), ylabel('\itG', 'FontSize',14)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Circularrestricted3bodyproblemUIFigure = uifigure('Visible', 'off');
            app.Circularrestricted3bodyproblemUIFigure.Name = 'Circular restricted 3-body problem';
            app.Circularrestricted3bodyproblemUIFigure.Icon = 'Kepler.jpg';
            app.uipanel1 = uipanel(app.Circularrestricted3bodyproblemUIFigure);
            app.uipanel1.Title = 'Input parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Vertical component of rotated angular momentum';
            app.G3 = uieditfield(app.uipanel1, 'text');
            app.G3.ValueChangedFcn = createCallbackFcn(app, @edit_G3_callback, true);
            app.G3.Tag = 'G3';
            app.G3.Value = '0.9';
            app.S1 = uieditfield(app.uipanel1, 'text');
            app.S1.Tag = 'S1';
            app.S2 = uieditfield(app.uipanel1, 'text');
            app.S2.Tag = 'S2';
            app.S3 = uieditfield(app.uipanel1, 'text');
            app.S3.Tag = 'S3';
            app.D1 = uieditfield(app.uipanel1, 'text');
            app.D1.Tag = 'D1';
            app.D2 = uieditfield(app.uipanel1, 'text');
            app.D2.Tag = 'D2';
            app.D3 = uieditfield(app.uipanel1, 'text');
            app.D3.Tag = 'D3';
            app.pushbutton_Update = uibutton(app.uipanel1, 'push');
            app.pushbutton_Update.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_update, true);
            app.pushbutton_Update.Tag = 'pushbutton_Update';
            app.pushbutton_Update.Text = '◄  Update';
            app.edit_m = uieditfield(app.uipanel1, 'text');
            app.edit_m.ValueChangedFcn = createCallbackFcn(app, @edit_m_callback, true);
            app.edit_m.Tag = 'edit_m';
            app.edit_m.Value = '0.05';
            app.text23 = uilabel(app.uipanel1);
            app.text23.Tag = 'text23';
            app.text23.Text = 'mass =';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Tag = 'text24';
            app.text24.Text = 'distance =';
            app.edit_Euler1 = uieditfield(app.uipanel1, 'text');
            app.edit_Euler1.Tag = 'edit_Euler1';
            app.edit_Zeeman3 = uieditfield(app.uipanel1, 'text');
            app.edit_Zeeman3.Tag = 'edit_Zeeman3';
            app.edit_p2 = uieditfield(app.uipanel1, 'text');
            app.edit_p2.Tag = 'edit_p2';
            app.text25 = uilabel(app.uipanel1);
            app.text25.Tag = 'text25';
            app.text25.Text = 'Euler1';
            app.text26 = uilabel(app.uipanel1);
            app.text26.Tag = 'text26';
            app.text26.Text = 'Zeeman3';
            app.text27 = uilabel(app.uipanel1);
            app.text27.Tag = 'text27';
            app.text27.Text = 'p_2';
            app.s_1Label = uilabel(app.uipanel1);
            app.s_1Label.Interpreter = 'latex';
            app.s_1Label.Text = '$s_1$';
            app.s_2Label = uilabel(app.uipanel1);
            app.s_2Label.Interpreter = 'latex';
            app.s_2Label.Text = '$s_2$';
            app.s_3Label = uilabel(app.uipanel1);
            app.s_3Label.Interpreter = 'latex';
            app.s_3Label.Text = '$s_3$';
            app.d_1Label = uilabel(app.uipanel1);
            app.d_1Label.Interpreter = 'latex';
            app.d_1Label.Text = '$d_1$';
            app.d_2Label = uilabel(app.uipanel1);
            app.d_2Label.Interpreter = 'latex';
            app.d_2Label.Text = '$d_2$';
            app.d_3Label = uilabel(app.uipanel1);
            app.d_3Label.Interpreter = 'latex';
            app.d_3Label.Text = '$d_3$';
            app.G3_Label = uilabel(app.uipanel1);
            app.G3_Label.Interpreter = 'latex';
            app.G3_Label.Text = '$G_3=$';
            app.edit_dist = uieditfield(app.uipanel1, 'text');
            app.edit_dist.ValueChangedFcn = createCallbackFcn(app, @edit_dist_callback, true);
            app.edit_dist.Value = '2';
            app.uipanel2 = uipanel(app.Circularrestricted3bodyproblemUIFigure);
            app.uipanel2.Title = 'Plot 3D';
            app.uipanel2.Tag = 'uipanel2';
            app.text5 = uilabel(app.uipanel2);
            app.text5.Tag = 'text5';
            app.text5.Text = {'Viewpoint'; '(Distance,Az, El)'};
            app.text7 = uilabel(app.uipanel2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Point number';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Height';
            app.text9 = uilabel(app.uipanel2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Length';
            app.text10 = uilabel(app.uipanel2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Shift';
            app.Viewpoint_az = uieditfield(app.uipanel2, 'text');
            app.Viewpoint_az.Tag = 'Viewpoint_az';
            app.Viewpoint_az.Value = '30';
            app.Viewpoint_el = uieditfield(app.uipanel2, 'text');
            app.Viewpoint_el.Tag = 'Viewpoint_el';
            app.Viewpoint_el.Value = '20';
            app.Lightpoint_az = uieditfield(app.uipanel2, 'text');
            app.Lightpoint_az.Tag = 'Lightpoint_az';
            app.Lightpoint_az.Value = '30';
            app.Lightpoint_el = uieditfield(app.uipanel2, 'text');
            app.Lightpoint_el.Tag = 'Lightpoint_el';
            app.Lightpoint_el.Value = '50';
            app.Pointnumber3D = uieditfield(app.uipanel2, 'text');
            app.Pointnumber3D.Tag = 'Pointnumber3D';
            app.Pointnumber3D.Value = '50';
            app.Height = uieditfield(app.uipanel2, 'text');
            app.Height.Tag = 'Height';
            app.Height.Value = '0';
            app.Lngth = uieditfield(app.uipanel2, 'text');
            app.Lngth.Tag = 'Lngth';
            app.Lngth.Value = '1.05';
            app.Shift = uieditfield(app.uipanel2, 'text');
            app.Shift.Tag = 'Shift';
            app.Shift.Value = '0';
            app.Clip = uieditfield(app.uipanel2, 'text');
            app.Clip.Tag = 'Clip';
            app.Clip.Value = '1.1';
            app.pushbutton_3DG = uibutton(app.uipanel2, 'push');
            app.pushbutton_3DG.ButtonPushedFcn = createCallbackFcn(app, @plot_3DG, true);
            app.pushbutton_3DG.Tag = 'pushbutton_3DG';
            app.pushbutton_3DG.Text = 'Plot G';
            app.pushbutton_3DR = uibutton(app.uipanel2, 'push');
            app.pushbutton_3DR.ButtonPushedFcn = createCallbackFcn(app, @plot_3DR, true);
            app.pushbutton_3DR.Tag = 'pushbutton_3DR';
            app.pushbutton_3DR.Text = 'Plot R';
            app.text22 = uilabel(app.uipanel2);
            app.text22.Tag = 'text22';
            app.text22.Text = {'Lightpoint'; '(Az, El)'};
            app.uipanel4 = uipanel(app.Circularrestricted3bodyproblemUIFigure);
            app.uipanel4.Title = 'Plot vertical section';
            app.uipanel4.Tag = 'uipanel4';
            app.text19 = uilabel(app.uipanel4);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Number of level surfaces';
            app.Surfnumber = uieditfield(app.uipanel4, 'text');
            app.Surfnumber.Tag = 'Surfnumber';
            app.Surfnumber.Value = '20';
            app.pushbutton_VerticalG = uibutton(app.uipanel4, 'push');
            app.pushbutton_VerticalG.ButtonPushedFcn = createCallbackFcn(app, @plot_VerticalG, true);
            app.pushbutton_VerticalG.Tag = 'pushbutton_VerticalG';
            app.pushbutton_VerticalG.Text = 'Plot G';
            app.pushbutton_VerticalR = uibutton(app.uipanel4, 'push');
            app.pushbutton_VerticalR.ButtonPushedFcn = createCallbackFcn(app, @plot_VerticalR, true);
            app.pushbutton_VerticalR.Tag = 'pushbutton_VerticalR';
            app.pushbutton_VerticalR.Text = 'Plot R';
            app.uipanel5 = uipanel(app.Circularrestricted3bodyproblemUIFigure);
            app.uipanel5.Title = 'Plot Action-Angle dynamics';
            app.uipanel5.Tag = 'uipanel5';
            app.text20 = uilabel(app.uipanel5);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Trajectory number';
            app.text21 = uilabel(app.uipanel5);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Point number';
            app.Trajectnumber = uieditfield(app.uipanel5, 'text');
            app.Trajectnumber.Tag = 'Trajectnumber';
            app.Trajectnumber.Value = '20';
            app.PointnumberAA = uieditfield(app.uipanel5, 'text');
            app.PointnumberAA.Tag = 'PointnumberAA';
            app.PointnumberAA.Value = '100';
            app.pushbutton_AAR = uibutton(app.uipanel5, 'push');
            app.pushbutton_AAR.ButtonPushedFcn = createCallbackFcn(app, @plot_AAR, true);
            app.pushbutton_AAR.Tag = 'pushbutton_AAR';
            app.pushbutton_AAR.Text = 'Plot R';
            app.pushbutton_AAG = uibutton(app.uipanel5, 'push');
            app.pushbutton_AAG.ButtonPushedFcn = createCallbackFcn(app, @plot_AAG, true);
            app.pushbutton_AAG.Tag = 'pushbutton_AAG';
            app.pushbutton_AAG.Text = 'Plot G';
            % Show the figure after all components are created
            app.Circularrestricted3bodyproblemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Cr3b

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Circularrestricted3bodyproblemUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Circularrestricted3bodyproblemUIFigure)
        end
    end
end