classdef Sat < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SatelliteproblemUIFigure  matlab.ui.Figure
        uipanel5                  matlab.ui.container.Panel
        pushbutton_AAG            matlab.ui.control.Button
        PointnumberAA             matlab.ui.control.EditField
        Trajectnumber             matlab.ui.control.EditField
        text6                     matlab.ui.control.Label
        text5                     matlab.ui.control.Label
        uipanel4                  matlab.ui.container.Panel
        PointnumberSection        matlab.ui.control.EditField
        text4                     matlab.ui.control.Label
        pushbutton_VerticalG      matlab.ui.control.Button
        Surfnumber                matlab.ui.control.EditField
        text3                     matlab.ui.control.Label
        uipanel1                  matlab.ui.container.Panel
        G_3Label                  matlab.ui.control.Label
        text2                     matlab.ui.control.Label
        edit_epsilon              matlab.ui.control.EditField
        G3                        matlab.ui.control.EditField
        text1                     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: pushbutton_VerticalG
        function plot_VerticalG(app, event)
            c = str2num(get(app.G3,'Value'));
            epsilon = str2num(get(app.edit_epsilon,'Value'));
            Surfn = str2num(get(app.Surfnumber,'Value'));
            n = str2num(get(app.PointnumberSection,'Value'));
            figure
            hold on
            if c > 0.44
                axis([-1+c  1-c  c  1]);
                [X,Y] = meshgrid((-1+c: 2*(1-c)/n :1-c), c : (1-c)/n :1);
            else
                axis([-1+c  1-c  2*c  1]);
                [X,Y] = meshgrid((-1+c: 2*(1-c)/n :1-c), 2*c : (1-2*c)/n :1);
            end
            %{
            %Cushman: moltiplicando il ternine del primo ordine per -2 e
            %il termine del secondo ordine per 4 si ottiene Brower-Deprit.
            Z = (1/2-3/4*(1-c^2./Y.^2))./Y.^3+...
            epsilon/2*((-15/8+81/16*(1-c^2./Y.^2)-195/64*(1-c^2./Y.^2).^2)+...
            Y.*(-3/4+9/4*(1-c^2./Y.^2)-27/16*(1-c^2./Y.^2).^2)+...
            (Y.^2).*(3/8-27/16*(1-c^2./Y.^2)+75/64*(1-c^2./Y.^2).^2)-...
            2*(21/16-45/32*(1-c^2./Y.^2)).*X.^2./Y.^2)./Y.^7;
            %}
            %%{
            %Brower-Deprit
            Z = (1/2-3/2*c^2./Y.^2)./Y.^3+...
            epsilon*(-3/32*(5-18*c^2./Y.^2+5*c^4./Y.^4)./Y.^5+...
            -3/8*(1-6*c^2./Y.^2+9*c^4./Y.^4)./Y.^6+...
            15/32*(1-2*c^2./Y.^2-7*c^4./Y.^4)./Y.^7+...
            -3/16*((1-15*c^2./Y.^2).*(1-c^2./Y.^2).*(1-Y.^2)-2*(1-15*c^2./Y.^2).*X.^2./Y.^2)./Y.^7);
            %%}
            contour(X,Y,Z,Surfn,'-k');
            
            s = (-pi/2 : pi/n : pi/2);
            X = 1/2*(1-c^2)*cos(s);
            Y = sqrt(1/2*(1+c^2)+1/2*(1-c^2)*sin(s));
            plot(X,Y,'b','LineWidth',3);
            X = -X;
            plot(X,Y,'b','LineWidth',3);
            title('Kepler manifold of \itG\rm - type (vertical section)', 'FontSize',14)
            xlabel('\it\eta_1', 'FontSize',14), ylabel('\it\eta_3', 'FontSize',14)           
        end

        % Button pushed function: pushbutton_AAG
        function plot_AAG(app, event)
            c = str2num(get(app.G3,'Value'));
            epsilon = str2num(get(app.edit_epsilon,'Value'));
            Trj = str2num(get(app.Trajectnumber,'Value'));
            N = str2num(get(app.PointnumberAA,'Value'));
            figure
            [X,Y] = meshgrid(-180 : 360/N : 180, c+eps : (1-c)/N : 1);
            
            if c > 0.44
                axis([-180  180  c  1]);
                [X,Y] = meshgrid(-180 : 360/N : 180, c : (1-c)/N :1);
            else
                axis([-180  180  2*c  1]);
                [X,Y] = meshgrid(-180 : 360/N : 180, 2*c : (1-2*c)/N :1);
            end
            %{
            %Cushman
            Z = (1/2-3/4*(1-c^2./Y.^2))./Y.^3+...
            epsilon/2*((-15/8+81/16*(1-c^2./Y.^2)-195/64*(1-c^2./Y.^2).^2)+...
            Y.*(-3/4+9/4*(1-c^2./Y.^2)-27/16*(1-c^2./Y.^2).^2)+...
            (Y.^2).*(3/8-27/16*(1-c^2./Y.^2)+75/64*(1-c^2./Y.^2).^2)-...
            2*(21/16-45/32*(1-c^2./Y.^2)).*(cos(pi/180*X).*sqrt(1/4*(1-c^2)^2-...
            (Y.^2-1/2*(1+c^2)).^2)).^2./Y.^2)./Y.^7;
            %}
            %%{
            %Brower-Deprit
            Z = (1/2-3/2*c^2./Y.^2)./Y.^3+...
            epsilon*(-3/32*(5-18*c^2./Y.^2+5*c^4./Y.^4)./Y.^5+...
            -3/8*(1-6*c^2./Y.^2+9*c^4./Y.^4)./Y.^6+...
            15/32*(1-2*c^2./Y.^2-7*c^4./Y.^4)./Y.^7+...
            -3/16*((1-15*c^2./Y.^2).*(1-c^2./Y.^2).*(1-Y.^2)-...
            2*(1-15*c^2./Y.^2).*(sin(pi/180*X).*sqrt(1/4*(1-c^2)^2-(Y.^2-1/2*(1+c^2)).^2)).^2./Y.^2)./Y.^7);
            %%}
            contour(X,Y,Z, Trj, '-k');
            title('Action-Angle: \itG - \omega', 'FontSize',14)
            xlabel('\it\omega \rm(deg)', 'FontSize',14), ylabel('\itG', 'FontSize',14)
            Degree_x             
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SatelliteproblemUIFigure = uifigure('Visible', 'off');
            app.SatelliteproblemUIFigure.Name = 'Satellite problem';
            app.SatelliteproblemUIFigure.Icon = 'Kepler.jpg';
            app.uipanel1 = uipanel(app.SatelliteproblemUIFigure);
            app.uipanel1.Title = 'Input parameters';
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = {'Vertical component'; 'of angular momentum'};
            app.G3 = uieditfield(app.uipanel1, 'text');
            app.G3.Tag = 'G3';
            app.G3.Value = '0.4';
            app.edit_epsilon = uieditfield(app.uipanel1, 'text');
            app.edit_epsilon.Tag = 'edit_epsilon';
            app.edit_epsilon.Value = '0.05';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = {'Oblateness'; '(perturbative parameter)'};
            app.G_3Label = uilabel(app.uipanel1);
            app.G_3Label.Interpreter = 'latex';
            app.G_3Label.Text = '$G_3=$';
            app.uipanel4 = uipanel(app.SatelliteproblemUIFigure);
            app.uipanel4.Title = 'Plot vertical section';
            app.uipanel4.Tag = 'uipanel4';
            app.text3 = uilabel(app.uipanel4);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Number of level surfaces';
            app.Surfnumber = uieditfield(app.uipanel4, 'text');
            app.Surfnumber.Tag = 'Surfnumber';
            app.Surfnumber.Value = '20';
            app.pushbutton_VerticalG = uibutton(app.uipanel4, 'push');
            app.pushbutton_VerticalG.ButtonPushedFcn = createCallbackFcn(app, @plot_VerticalG, true);
            app.pushbutton_VerticalG.Tag = 'pushbutton_VerticalG';
            app.pushbutton_VerticalG.Text = 'Plot G';
            app.text4 = uilabel(app.uipanel4);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Point number';
            app.PointnumberSection = uieditfield(app.uipanel4, 'text');
            app.PointnumberSection.Tag = 'PointnumberSection';
            app.PointnumberSection.Value = '100';
            app.uipanel5 = uipanel(app.SatelliteproblemUIFigure);
            app.uipanel5.Title = 'Plot Action-Angle dynamics';
            app.uipanel5.Tag = 'uipanel5';
            app.text5 = uilabel(app.uipanel5);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Trajectory number';
            app.text6 = uilabel(app.uipanel5);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Point number';
            app.Trajectnumber = uieditfield(app.uipanel5, 'text');
            app.Trajectnumber.Tag = 'Trajectnumber';
            app.Trajectnumber.Value = '20';
            app.PointnumberAA = uieditfield(app.uipanel5, 'text');
            app.PointnumberAA.Tag = 'PointnumberAA';
            app.PointnumberAA.Value = '100';
            app.pushbutton_AAG = uibutton(app.uipanel5, 'push');
            app.pushbutton_AAG.ButtonPushedFcn = createCallbackFcn(app, @plot_AAG, true);
            app.pushbutton_AAG.Tag = 'pushbutton_AAG';
            app.pushbutton_AAG.Text = 'Plot G';
            % Show the figure after all components are created
            app.SatelliteproblemUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Sat

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SatelliteproblemUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SatelliteproblemUIFigure)
        end
    end
end