classdef Laplace_source < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LAPLACEUIFigure          matlab.ui.Figure
        File                     matlab.ui.container.Menu
        Load                     matlab.ui.container.Menu
        Save                     matlab.ui.container.Menu
        closeall                 matlab.ui.container.Menu
        Save_exit                matlab.ui.container.Menu
        Save_now                 matlab.ui.container.Menu
        Window                   matlab.ui.container.Menu
        CloseFig                 matlab.ui.container.Menu
        verbose                  matlab.ui.container.Menu
        View                     matlab.ui.container.Menu
        Percentage_of_Column     matlab.ui.container.Menu
        Column_Completed         matlab.ui.container.Menu
        Total_bars               matlab.ui.container.Menu
        Wait_bars                matlab.ui.container.Menu
        Figure                   matlab.ui.container.Menu
        no_degree                matlab.ui.container.Menu
        Rectify                  matlab.ui.container.Menu
        NormalForm               matlab.ui.container.Menu
        Global3body              matlab.ui.container.Menu
        La_La                    matlab.ui.container.Menu
        Help                     matlab.ui.container.Menu
        HelpMenu                 matlab.ui.container.Menu
        About                    matlab.ui.container.Menu
        uipanel1_2               matlab.ui.container.Panel
        Image                    matlab.ui.control.Image
        edit_mass1               matlab.ui.control.EditField
        text15                   matlab.ui.control.Label
        edit_asc10               matlab.ui.control.EditField
        edit_asc9                matlab.ui.control.EditField
        edit_asc8                matlab.ui.control.EditField
        edit_asc7                matlab.ui.control.EditField
        edit_asc6                matlab.ui.control.EditField
        edit_asc5                matlab.ui.control.EditField
        edit_asc4                matlab.ui.control.EditField
        edit_asc3                matlab.ui.control.EditField
        edit_asc2                matlab.ui.control.EditField
        edit_asc1                matlab.ui.control.EditField
        text17                   matlab.ui.control.Label
        text16                   matlab.ui.control.Label
        text14                   matlab.ui.control.Label
        text13                   matlab.ui.control.Label
        text12                   matlab.ui.control.Label
        text11                   matlab.ui.control.Label
        text4                    matlab.ui.control.Label
        text5                    matlab.ui.control.Label
        text2                    matlab.ui.control.Label
        text3                    matlab.ui.control.Label
        text6                    matlab.ui.control.Label
        text1                    matlab.ui.control.Label
        text10                   matlab.ui.control.Label
        text7                    matlab.ui.control.Label
        text8                    matlab.ui.control.Label
        text9                    matlab.ui.control.Label
        edit_longmean10          matlab.ui.control.EditField
        edit_longmean9           matlab.ui.control.EditField
        edit_longmean8           matlab.ui.control.EditField
        edit_longmean7           matlab.ui.control.EditField
        edit_longmean6           matlab.ui.control.EditField
        edit_longmean5           matlab.ui.control.EditField
        edit_longmean4           matlab.ui.control.EditField
        edit_longmean3           matlab.ui.control.EditField
        edit_longmean2           matlab.ui.control.EditField
        edit_longmean1           matlab.ui.control.EditField
        edit_peri10              matlab.ui.control.EditField
        edit_peri9               matlab.ui.control.EditField
        edit_peri8               matlab.ui.control.EditField
        edit_peri7               matlab.ui.control.EditField
        edit_peri6               matlab.ui.control.EditField
        edit_peri5               matlab.ui.control.EditField
        edit_peri4               matlab.ui.control.EditField
        edit_peri3               matlab.ui.control.EditField
        edit_peri2               matlab.ui.control.EditField
        edit_peri1               matlab.ui.control.EditField
        edit_incl10              matlab.ui.control.EditField
        edit_incl9               matlab.ui.control.EditField
        edit_incl8               matlab.ui.control.EditField
        edit_incl7               matlab.ui.control.EditField
        edit_incl6               matlab.ui.control.EditField
        edit_incl5               matlab.ui.control.EditField
        edit_incl4               matlab.ui.control.EditField
        edit_incl3               matlab.ui.control.EditField
        edit_incl2               matlab.ui.control.EditField
        edit_incl1               matlab.ui.control.EditField
        edit_ecc10               matlab.ui.control.EditField
        edit_ecc9                matlab.ui.control.EditField
        edit_ecc8                matlab.ui.control.EditField
        edit_ecc7                matlab.ui.control.EditField
        edit_ecc6                matlab.ui.control.EditField
        edit_ecc5                matlab.ui.control.EditField
        edit_ecc4                matlab.ui.control.EditField
        edit_ecc3                matlab.ui.control.EditField
        edit_ecc2                matlab.ui.control.EditField
        edit_ecc1                matlab.ui.control.EditField
        edit_sma10               matlab.ui.control.EditField
        edit_sma9                matlab.ui.control.EditField
        edit_sma8                matlab.ui.control.EditField
        edit_sma7                matlab.ui.control.EditField
        edit_sma6                matlab.ui.control.EditField
        edit_sma5                matlab.ui.control.EditField
        edit_sma4                matlab.ui.control.EditField
        edit_sma3                matlab.ui.control.EditField
        edit_sma2                matlab.ui.control.EditField
        edit_sma1                matlab.ui.control.EditField
        edit_mass10              matlab.ui.control.EditField
        edit_mass9               matlab.ui.control.EditField
        edit_mass8               matlab.ui.control.EditField
        edit_mass7               matlab.ui.control.EditField
        edit_mass6               matlab.ui.control.EditField
        edit_mass5               matlab.ui.control.EditField
        edit_mass4               matlab.ui.control.EditField
        edit_mass3               matlab.ui.control.EditField
        edit_mass2               matlab.ui.control.EditField
        uipanel15                matlab.ui.container.Panel
        pushbutton_Lyapunov      matlab.ui.control.Button
        pushbutton_Resonance     matlab.ui.control.Button
        pushbutton_ClearAppend   matlab.ui.control.Button
        pushbutton_Plot          matlab.ui.control.Button
        uipanel19                matlab.ui.container.Panel
        edit_Variance            matlab.ui.control.NumericEditField
        edit_max_freq            matlab.ui.control.NumericEditField
        edit_min_freq            matlab.ui.control.NumericEditField
        text52                   matlab.ui.control.Label
        text51                   matlab.ui.control.Label
        text50                   matlab.ui.control.Label
        pushbutton_Wavelet       matlab.ui.control.Button
        uipanel17                matlab.ui.container.Panel
        pushbutton_FFT           matlab.ui.control.Button
        check_log                matlab.ui.control.CheckBox
        uipanel16                matlab.ui.container.Panel
        edit_tofreq              matlab.ui.control.NumericEditField
        edit_fromfreq            matlab.ui.control.NumericEditField
        edit_nfreq               matlab.ui.control.NumericEditField
        pushbutton_FMFT          matlab.ui.control.Button
        popupmenu_FMFT_flag      matlab.ui.control.DropDown
        text28                   matlab.ui.control.Label
        text27                   matlab.ui.control.Label
        text26                   matlab.ui.control.Label
        text25                   matlab.ui.control.Label
        text24                   matlab.ui.control.Label
        text23                   matlab.ui.control.Label
        checkboxUser             matlab.ui.control.CheckBox
        text22                   matlab.ui.control.Label
        popupmenu_planet         matlab.ui.control.DropDown
        popupmenu_element        matlab.ui.control.DropDown
        pushbutton_StartLFA      matlab.ui.control.Button
        pushbutton_StopLFA       matlab.ui.control.Button
        uipanel14                matlab.ui.container.Panel
        pushbutton_Slave         matlab.ui.control.Button
        uipanel22                matlab.ui.container.Panel
        text54                   matlab.ui.control.Label
        text53                   matlab.ui.control.Label
        check2nd3                matlab.ui.control.CheckBox
        check2nd2                matlab.ui.control.CheckBox
        check2nd1                matlab.ui.control.CheckBox
        check1st3                matlab.ui.control.CheckBox
        check1st2                matlab.ui.control.CheckBox
        check1st1                matlab.ui.control.CheckBox
        pushbutton_Calculate     matlab.ui.control.Button
        uipanel10                matlab.ui.container.Panel
        text31_2                 matlab.ui.control.Label
        leqLabel_4               matlab.ui.control.Label
        leqLabel_3               matlab.ui.control.Label
        edit_intstep             matlab.ui.control.NumericEditField
        edit_intto               matlab.ui.control.NumericEditField
        edit_intfrom             matlab.ui.control.NumericEditField
        text32                   matlab.ui.control.Label
        text33                   matlab.ui.control.Label
        popupmenu_action_int     matlab.ui.control.DropDown
        popupmenu_planetFMI_int  matlab.ui.control.DropDown
        uipanel11                matlab.ui.container.Panel
        leqLabel_2               matlab.ui.control.Label
        leqLabel                 matlab.ui.control.Label
        edit_extstep             matlab.ui.control.NumericEditField
        edit_extto               matlab.ui.control.NumericEditField
        edit_extfrom             matlab.ui.control.NumericEditField
        text29                   matlab.ui.control.Label
        text30                   matlab.ui.control.Label
        popupmenu_action_ext     matlab.ui.control.DropDown
        popupmenu_planetFMI_ext  matlab.ui.control.DropDown
        text31                   matlab.ui.control.Label
        uipanel7                 matlab.ui.container.Panel
        pushbuttonFMI            matlab.ui.control.Button
        pushbutton_LevelLines    matlab.ui.control.Button
        pushbutton_Frequencies   matlab.ui.control.Button
        uipanel6                 matlab.ui.container.Panel
        edit_stepFMI3            matlab.ui.control.NumericEditField
        edit_maxfreq3            matlab.ui.control.NumericEditField
        edit_minfreq3            matlab.ui.control.NumericEditField
        text46                   matlab.ui.control.Label
        text43                   matlab.ui.control.Label
        text45                   matlab.ui.control.Label
        text44                   matlab.ui.control.Label
        uipanel5                 matlab.ui.container.Panel
        edit_stepFMI2            matlab.ui.control.NumericEditField
        edit_maxfreq2            matlab.ui.control.NumericEditField
        edit_minfreq2            matlab.ui.control.NumericEditField
        text42                   matlab.ui.control.Label
        text39                   matlab.ui.control.Label
        text41                   matlab.ui.control.Label
        text40                   matlab.ui.control.Label
        uipanel4                 matlab.ui.container.Panel
        edit_stepFMI1            matlab.ui.control.NumericEditField
        edit_maxfreq1            matlab.ui.control.NumericEditField
        edit_minfreq1            matlab.ui.control.NumericEditField
        text38                   matlab.ui.control.Label
        text37                   matlab.ui.control.Label
        text36                   matlab.ui.control.Label
        text35                   matlab.ui.control.Label
        uipanel2                 matlab.ui.container.Panel
        edit_outstep             matlab.ui.control.NumericEditField
        edit_totint              matlab.ui.control.NumericEditField
        edit_integstep           matlab.ui.control.NumericEditField
        PostNewton               matlab.ui.control.CheckBox
        pushbutton_Integrate     matlab.ui.control.Button
        text21                   matlab.ui.control.Label
        text20                   matlab.ui.control.Label
        text19                   matlab.ui.control.Label
        text18                   matlab.ui.control.Label
        popupmenu_ODE            matlab.ui.control.DropDown
        uipanel20                matlab.ui.container.Panel
        edit_k9                  matlab.ui.control.NumericEditField
        edit_k8                  matlab.ui.control.NumericEditField
        edit_k7                  matlab.ui.control.NumericEditField
        edit_k6                  matlab.ui.control.NumericEditField
        edit_k5                  matlab.ui.control.NumericEditField
        edit_k4                  matlab.ui.control.NumericEditField
        edit_k3                  matlab.ui.control.NumericEditField
        edit_k2                  matlab.ui.control.NumericEditField
        edit_k1                  matlab.ui.control.NumericEditField
        pushbutton_close         matlab.ui.control.Button
        pushbutton_PlotRes       matlab.ui.control.Button
        popupmenu_P9             matlab.ui.control.DropDown
        popupmenu_P8             matlab.ui.control.DropDown
        popupmenu_P7             matlab.ui.control.DropDown
        popupmenu_P6             matlab.ui.control.DropDown
        popupmenu_P5             matlab.ui.control.DropDown
        popupmenu_P4             matlab.ui.control.DropDown
        popupmenu_P3             matlab.ui.control.DropDown
        popupmenu_P2             matlab.ui.control.DropDown
        popupmenu_P1             matlab.ui.control.DropDown
        popupmenu_R9             matlab.ui.control.DropDown
        popupmenu_R8             matlab.ui.control.DropDown
        popupmenu_R7             matlab.ui.control.DropDown
        popupmenu_R6             matlab.ui.control.DropDown
        popupmenu_R5             matlab.ui.control.DropDown
        popupmenu_R4             matlab.ui.control.DropDown
        popupmenu_R3             matlab.ui.control.DropDown
        popupmenu_R2             matlab.ui.control.DropDown
        popupmenu_R1             matlab.ui.control.DropDown
    end

    
    properties (Access = private)
        LaplaceDir
        PhSpGeoDir
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Laplace_startup(app)
format('long')
delete *.dat *.dmp *.tmp *.out *.txt *.aei *.asv
if isdeployed
    %do nothing
else
    if exist('custom.hnb','file')
        delete('custom.hnb')
    end
    if exist('big.in','file')
        delete('big.in')
    end
    if exist('small.in','file')
        delete('small.in')
    end
    if exist('param.in','file')
        delete('param.in')
    end
    if exist('element.in','file')
        delete('element.in')
    end
end
app.LaplaceDir = cd;
cd ..
cd ..
cd ..
app.PhSpGeoDir = cd;
cd(app.LaplaceDir);

if isdeployed
    % do nothing
else
%    path([app.PhSpGeoDir,'/AddedODEsolver/bin'], path);
    path([app.PhSpGeoDir,'/Util'], path);
    path([app.PhSpGeoDir,'/Util/Multicore'], path);
    path([app.PhSpGeoDir,'/Portraits'], path);
%    path([app.PhSpGeoDir,'/AddedODEsolver/dop853'], path);
%    path([app.PhSpGeoDir,'/AddedODEsolver/BulirschStoer'], path);
%    path([app.PhSpGeoDir,'/AddedODEsolver/ABM8'], path);
%    path([app.PhSpGeoDir,'/AddedODEsolver/ShampineGordon'], path);    
    path([app.LaplaceDir,'/Help'], path);
    path([app.LaplaceDir,'/FunctionScript'], path);
    path([app.LaplaceDir,'/NormalForm'], path);
    path(app.LaplaceDir, path);
end

app.pushbutton_Integrate.Icon = 'Integrate.jpg';
app.pushbutton_Calculate.Icon = 'PhSpGeo.jpg';
app.pushbutton_Slave.Icon = 'Schiavo.jpg';
app.pushbutton_FFT.Icon = 'FFT.jpg';
app.pushbutton_FMFT.Icon = 'FMFT.jpg';
app.pushbutton_Wavelet.Icon = 'onda.jpg';
app.Image.ImageSource = 'Saturn.jpg';
set(app.pushbutton_PlotRes, 'Icon','Resonance.jpg','IconAlignment','top');
%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
if ~isnan(findstr(app.LaplaceDir, 'Slave'))
    set(app.pushbutton_Slave, 'Visible', 'on','Enable', 'on')
    set(app.pushbutton_Integrate, 'Enable', 'off')
    set(app.pushbutton_Calculate, 'Visible', 'off')
else
cd ..
if exist('multicorefiles','dir')
    rmdir('multicorefiles','s')
end
mkdir('multicorefiles')
cd(app.LaplaceDir)
end
            
list = strread(cd, '%s','delimiter',strrep(filesep,'\','\\'));
dim = size(list);
curdir = [list{dim(1,1), dim(1,2)},' - ',list{dim(1,1) - 1, dim(1,2)}];
set(app.LAPLACEUIFigure, 'Name', curdir)
%%%%%%%%%%%%%%%% Load what was saved on exit %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
cd(app.LaplaceDir)
cd ..
if exist('Setting.mat')
    load('Setting.mat')
    SetInit
end
cd(app.LaplaceDir)
if get(app.edit_intstep,'Value') == 0
    set(app.leqLabel_3,'Text','=') 
    set(app.leqLabel_4,'Enable','off')
    set(app.edit_intto,'Enable','off')
end
        end

        % Button pushed function: pushbutton_Integrate
        function exec_Integrate(app, event)
            fig = app.LAPLACEUIFigure;
            d = uiprogressdlg(fig,'Title','Please wait, LAPLACE is computing ...','Indeterminate','on');
            set(app.pushbutton_Integrate,'Enable','off')
            DisableAll
            delete *.dat *.dmp *.tmp *.out
            global jumprow planet
            planet = get(app.popupmenu_planet,'Items');
            cd(app.LaplaceDir)
            
            N = 10;
            for k = 1:10
                if strcmp(planet(k),'Absent')
                    N = N-1;
                end
            end
         
            for k = 1:N
                while strcmp(planet(k),'Absent')
                    planet(k) = '';
                end
            end
            
            while size(planet,2) > N
                planet(N+1) = '';
            end
            
            %%%%%%%%%%%%%%%%%%%% Mercury %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            mass1   = get(app.edit_mass1,'Value');
            sma1    = get(app.edit_sma1,'Value');
            ecc1    = get(app.edit_ecc1,'Value');
            incl1   = get(app.edit_incl1,'Value');
            asc1    = get(app.edit_asc1,'Value');
            peri1    = get(app.edit_peri1,'Value');
            longmean1 = get(app.edit_longmean1,'Value');
            %%%%%%%%%%%%%%%%%%%% Venus %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            mass2   = get(app.edit_mass2,'Value');
            sma2    = get(app.edit_sma2,'Value');
            ecc2    = get(app.edit_ecc2,'Value');
            incl2   = get(app.edit_incl2,'Value');
            asc2    = get(app.edit_asc2,'Value');
            peri2    = get(app.edit_peri2,'Value');
            longmean2 = get(app.edit_longmean2,'Value');
            %%%%%%%%%%%%%%%%%%%% Earth-Moon %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            mass3   = get(app.edit_mass3,'Value');
            sma3    = get(app.edit_sma3,'Value');
            ecc3    = get(app.edit_ecc3,'Value');
            incl3   = get(app.edit_incl3,'Value');
            asc3    = get(app.edit_asc3,'Value');
            peri3    = get(app.edit_peri3,'Value');
            longmean3 = get(app.edit_longmean3,'Value');
            %%%%%%%%%%%%%%%%%%%%% Mars %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            mass4   = get(app.edit_mass4,'Value');
            sma4    = get(app.edit_sma4,'Value');
            ecc4    = get(app.edit_ecc4,'Value');
            incl4   = get(app.edit_incl4,'Value');
            asc4    = get(app.edit_asc4,'Value');
            peri4    = get(app.edit_peri4,'Value');
            longmean4 = get(app.edit_longmean4,'Value');
            %%%%%%%%%%%%%%%%%%%% Asteroid %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            mass5   = get(app.edit_mass5,'Value');
            sma5    = get(app.edit_sma5,'Value');
            ecc5    = get(app.edit_ecc5,'Value');
            incl5   = get(app.edit_incl5,'Value');
            asc5    = get(app.edit_asc5,'Value');
            peri5    = get(app.edit_peri5,'Value');
            longmean5 = get(app.edit_longmean5,'Value');
            %%%%%%%%%%%%%%%%%%%% Jupiter %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            mass6   = get(app.edit_mass6,'Value');
            sma6    = get(app.edit_sma6,'Value');
            ecc6    = get(app.edit_ecc6,'Value');
            incl6   = get(app.edit_incl6,'Value');
            asc6    = get(app.edit_asc6,'Value');
            peri6    = get(app.edit_peri6,'Value');
            longmean6 = get(app.edit_longmean6,'Value');
            %%%%%%%%%%%%%%%%%%%% Saturn %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            mass7   = get(app.edit_mass7,'Value');
            sma7    = get(app.edit_sma7,'Value');
            ecc7    = get(app.edit_ecc7,'Value');
            incl7   = get(app.edit_incl7,'Value');
            asc7    = get(app.edit_asc7,'Value');
            peri7    = get(app.edit_peri7,'Value');
            longmean7 = get(app.edit_longmean7,'Value');
            %%%%%%%%%%%%%%%%%%%% Uranus %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            mass8   = get(app.edit_mass8,'Value');
            sma8    = get(app.edit_sma8,'Value');
            ecc8    = get(app.edit_ecc8,'Value');
            incl8   = get(app.edit_incl8,'Value');
            asc8    = get(app.edit_asc8,'Value');
            peri8    = get(app.edit_peri8,'Value');
            longmean8 = get(app.edit_longmean8,'Value');
            %%%%%%%%%%%%%%%%%%%% Neptune %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            mass9   = get(app.edit_mass9,'Value');
            sma9    = get(app.edit_sma9,'Value');
            ecc9    = get(app.edit_ecc9,'Value');
            incl9   = get(app.edit_incl9,'Value');
            asc9    = get(app.edit_asc9,'Value');
            peri9    = get(app.edit_peri9,'Value');
            longmean9 = get(app.edit_longmean9,'Value');
            %%%%%%%%%%%%%%%%%%%% Pluto %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            mass10   = get(app.edit_mass10,'Value');
            sma10    = get(app.edit_sma10,'Value');
            ecc10    = get(app.edit_ecc10,'Value');
            incl10   = get(app.edit_incl10,'Value');
            asc10    = get(app.edit_asc10,'Value');
            peri10    = get(app.edit_peri10,'Value');
            longmean10 = get(app.edit_longmean10,'Value');
            %%%%%%%%%%%%%%%%% ODE integration %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            item = str2num(get(app.popupmenu_ODE,'Value'));
            if item == 1;
                Method = 'Symplectic';
            elseif item == 2;
                Method = 'Bulirsch-Stoer';
            elseif item == 3;
                Method = 'Runge-Kutta';
            elseif item == 4;
                Method = 'MVS';
            elseif item == 5;
                Method = 'Radau';
            elseif item == 6;
                Method = 'BS2';
            elseif item == 7;
                Method = 'Hybrid';
            end
            
            integstep = get(app.edit_integstep,'Value');
            totint = get(app.edit_totint,'Value');
            outstep = get(app.edit_outstep,'Value');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            if item < 4
                jumprow = 17;
                fid = fopen( 'custom.hnb', 'wt');
                fprintf(fid, ['Integrator: ',Method,'\n']);
                fprintf(fid, ['StepSize: ',num2str(integstep),'\n']);
                fprintf(fid, ['Tfinal: ',num2str(totint),'\n']);
                fprintf(fid, ['OutputInterval: ',num2str(outstep),'\n']);
                fprintf(fid, ['EnergyInterval: ',num2str(100*outstep),'\n']);
                if get(app.PostNewton, 'Value') == 1;
                    fprintf(fid,'PostNewtonian = True\n');
                end
            
                if mass1
                    fprintf(fid, [mass1,'   ',sma1,'   ',ecc1,'   ',incl1,'   ',asc1,'   ',peri1,'   ',longmean1,'\n']);
                else
            % do nothing
                end
                if mass2
                    fprintf(fid, [mass2,'   ',sma2,'   ',ecc2,'   ',incl2,'   ',asc2,'   ',peri2,'   ',longmean2,'\n']);
                else
            % do nothing
                end
                if mass3
                    fprintf(fid, [mass3,'   ',sma3,'   ',ecc3,'   ',incl3,'   ',asc3,'   ',peri3,'   ',longmean3,'\n']);
                else
            % do nothing
                end
                if mass4
                    fprintf(fid, [mass4,'   ',sma4,'   ',ecc4,'   ',incl4,'   ',asc4,'   ',peri4,'   ',longmean4,'\n']);
                else
            % do nothing
                end
                if mass5
                    fprintf(fid, [mass5,'   ',sma5,'   ',ecc5,'   ',incl5,'   ',asc5,'   ',peri5,'   ',longmean5,'\n']);
                else
            % do nothing
                end
                if mass6
                    fprintf(fid, [mass6,'   ',sma6,'   ',ecc6,'   ',incl6,'   ',asc6,'   ',peri6,'   ',longmean6,'\n']);
                else
            % do nothing
                end
                if mass7
                    fprintf(fid, [mass7,'   ',sma7,'   ',ecc7,'   ',incl7,'   ',asc7,'   ',peri7,'   ',longmean7,'\n']);
                else
            % do nothing
                end
                if mass8
                    fprintf(fid, [mass8,'   ',sma8,'   ',ecc8,'   ',incl8,'   ',asc8,'   ',peri8,'   ',longmean8,'\n']);
                else
            % do nothing
                end
                if mass9
                    fprintf(fid, [mass9,'   ',sma9,'   ',ecc9,'   ',incl9,'   ',asc9,'   ',peri9,'   ',longmean9,'\n']);
                else
            % do nothing
                end
                if mass10
                    fprintf(fid, [mass10,'   ',sma10,'   ',ecc10,'   ',incl10,'   ',asc10,'   ',peri10,'   ',longmean10,'\n']);
                else
            % do nothing
                end
                fclose(fid);
            %%%%%%%%%%%%%%%%%%%%%%%% Hnbody %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if strcmp(get(app.verbose, 'Checked'),'on')
                    cmd_string = ['!',app.LaplaceDir,'/hnbody init.hnb'];
                else
                    cmd_string = ['!',app.LaplaceDir,'/hnbody -q init.hnb'];
                end
                eval(cmd_string)
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            movefile('0.dat','Sun.dat')
                for k = 1:N
                    k_str = num2str(k);
                    movefile([k_str,'.dat'],[planet{k},'.dat'])
                end
            else
                jumprow = 4;
                fid = fopen( 'param.in', 'wt');
                fprintf(fid, ')O+_06 Integration parameters  (WARNING: Do not delete this line!!)\n');
                fprintf(fid, ['algorithm = ',Method,'\n']);
                fprintf(fid, ['start time = 0\n']);
                fprintf(fid, ['stop time = ',num2str(365.25*totint),'\n']);
                fprintf(fid, ['output interval = ',num2str(365.25*outstep),'\n']);
                fprintf(fid, ['timestep = ',num2str(365.25*integstep),'\n']);
                fprintf(fid, ['accuracy parameter=1.d-12\n']);
                fprintf(fid, ['stop integration after a close encounter = no\n']);
                fprintf(fid, ['allow collisions to occur = no\n']);
                fprintf(fid, ['include collisional fragmentation = no\n']);
                fprintf(fid, ['express time in days or years = years\n']);
                fprintf(fid, ['express time relative to integration start time = yes\n']);
                fprintf(fid, ['output precision = medium\n']);
                fprintf(fid, ['< not used at present >\n']);
                fprintf(fid, ['include relativity in integration= no\n']);
                fprintf(fid, ['include user-defined force = no\n']);
                fprintf(fid, ['ejection distance (AU)= 100\n']);
                fprintf(fid, ['radius of central body (AU) = 0.005\n']);
                fprintf(fid, ['central mass (solar) = 1.0\n']);
                fprintf(fid, ['central J2 = 0\n']);
                fprintf(fid, ['central J4 = 0\n']);
                fprintf(fid, ['central J6 = 0\n']);
                fprintf(fid, ['< not used at present >\n']);
                fprintf(fid, ['< not used at present >\n']);
                fprintf(fid, ['Hybrid integrator changeover (Hill radii) = 3.\n']);
                numts = num2str(ceil(totint/integstep/1000));
                fprintf(fid, ['number of timesteps between data dumps = ' numts '\n']);
                fprintf(fid, ['number of timesteps between periodic effects = 100\n']);
                fclose(fid);
            
                fid = fopen( 'element.in', 'wt');
                fprintf(fid, ')O+_06 element  (WARNING: Do not delete this line!!)\n');
                fprintf(fid, 'number of input files = 1\n');
                fprintf(fid, 'xv.out\n');
                fprintf(fid, 'type of elements (central body, barycentric, Jacobi) = Cen\n');
                fprintf(fid, 'minimum interval between outputs (days) = 3.6525\n');
                fprintf(fid, 'express time in days or years = years\n');
                fprintf(fid, 'express time relative to integration start time = yes\n');
                fprintf(fid, 'a10.7 e10.8 i10.6 g10.6 n10.6 l12.8 m13e\n');
                for k = 1:N
                    fprintf(fid, [char(planet(k)),'\n']);
                end
                fclose(fid);
            
                fid = fopen( 'big.in', 'wt');
                fprintf(fid, [')O+_06 Big-body initial data  (WARNING: Do not delete this line!!)\n']);
                fprintf(fid, ['style = Asteroidal\n']);
                fprintf(fid, ['epoch (in days) = 0.\n']);
                if mass1
                    periarg1 = num2str(str2num(peri1) - str2num(asc1),14);
                    meanan1 = num2str(str2num(longmean1) - str2num(peri1),14);
                    fprintf(fid,['Mercury','   m=',mass1,'\n']);
                    fprintf(fid, [sma1,'   ',ecc1,'   ',incl1,'   ',periarg1,'   ',asc1,'   ',meanan1,'  0.  0.  0.\n']);
                else
            % do nothing
                end
                if mass2
                    periarg2 = num2str(str2num(peri2) - str2num(asc2),14);
                    meanan2 = num2str(str2num(longmean2) - str2num(peri2),14);
                    fprintf(fid,['Venus','   m=',mass2,'\n']);
                    fprintf(fid, [sma2,'   ',ecc2,'   ',incl2,'   ',periarg2,'   ',asc2,'   ',meanan2,'  0.  0.  0.\n']);
                else
            % do nothing
                end
                if mass3
                    periarg3 = num2str(str2num(peri3) - str2num(asc3),14);
                    meanan3 = num2str(str2num(longmean3) - str2num(peri3),14);
                    fprintf(fid,['Earth-Moon','   m=',mass3,'\n']);
                    fprintf(fid, [sma3,'   ',ecc3,'   ',incl3,'   ',periarg3,'   ',asc3,'   ',meanan3,'  0.  0.  0.\n']);
                else
            % do nothing
                end
                if mass4
                    periarg4 = num2str(str2num(peri4) - str2num(asc4),14);
                    meanan4 = num2str(str2num(longmean4) - str2num(peri4),14);
                    fprintf(fid,['Mars','   m=',mass4,'\n']);
                    fprintf(fid, [sma4,'   ',ecc4,'   ',incl4,'   ',periarg4,'   ',asc4,'   ',meanan4,'  0.  0.  0.\n']);
                else
            % do nothing
                end
                if mass6
                    periarg6 = num2str(str2num(peri6) - str2num(asc6),14);
                    meanan6 = num2str(str2num(longmean6) - str2num(peri6),14);
                    fprintf(fid,['Jupiter','   m=',mass6,'\n']);
                    fprintf(fid, [sma6,'   ',ecc6,'   ',incl6,'   ',periarg6,'   ',asc6,'   ',meanan6,'  0.  0.  0.\n']);
                else
            % do nothing
                end
                if mass7
                    periarg7 = num2str(str2num(peri7) - str2num(asc7),14);
                    meanan7 = num2str(str2num(longmean7) - str2num(peri7),14);
                    fprintf(fid,['Saturn','   m=',mass7,'\n']);
                    fprintf(fid, [sma7,'   ',ecc7,'   ',incl7,'   ',periarg7,'   ',asc7,'   ',meanan7,'  0.  0.  0.\n']);
                else
            % do nothing
                end
                if mass8
                    periarg8 = num2str(str2num(peri8) - str2num(asc8),14);
                    meanan8 = num2str(str2num(longmean8) - str2num(peri8),14);
                    fprintf(fid,['Uranus','   m=',mass8,'\n']);
                    fprintf(fid, [sma8,'   ',ecc8,'   ',incl8,'   ',periarg8,'   ',asc8,'   ',meanan8,'  0.  0.  0.\n']);
                else
            % do nothing
                end
                if mass9
                    periarg9 = num2str(str2num(peri9) - str2num(asc9),14);
                    meanan9 = num2str(str2num(longmean9) - str2num(peri9),14);
                    fprintf(fid,['Neptune','   m=',mass9,'\n']);
                    fprintf(fid, [sma9,'   ',ecc9,'   ',incl9,'   ',periarg9,'   ',asc9,'   ',meanan9,'  0.  0.  0.\n']);
                else
            % do nothing
                end
                if mass10
                    periarg10 = num2str(str2num(peri10) - str2num(asc10),14);
                    meanan10 = num2str(str2num(longmean10) - str2num(peri10),14);
                    fprintf(fid,['Pluto','   m=',mass10,'\n']);
                    fprintf(fid, [sma10,'   ',ecc10,'   ',incl10,'   ',periarg10,'   ',asc10,'   ',meanan10,'  0.  0.  0.\n']);
                else
            % do nothing
                end
                fclose(fid);
            
                fid = fopen( 'small.in', 'wt');
                fprintf(fid, [')O+_06 Small-body initial data  (WARNING: Do not delete this line!!)\n']);
                fprintf(fid, ['style = Asteroidal\n']);
                if mass5
                    periarg5 = num2str(str2num(peri5) - str2num(asc5),14);
                    meanan5 = num2str(str2num(longmean5) - str2num(peri5),14);
                    fprintf(fid,['Asteroid','\n']);
                    fprintf(fid, [sma5,'   ',ecc5,'   ',incl5,'   ',periarg5,'   ',asc5,'   ',meanan5,'  0.  0.  0.\n']);
                else
            % do nothing
                end
                fclose(fid);
            %%%%%%%%%%%%%%%%%%%%%%%%%%% Mercury6 %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if strcmp(get(app.verbose, 'Checked'),'on')
                    cmd_string = ['!',app.LaplaceDir,'/mercury6_2 1'];
                else
                    cmd_string = ['!',app.LaplaceDir,'/mercury6_2 2'];
                end
                eval(cmd_string)
                cmd_string = ['!',app.LaplaceDir,'/element6'];
                eval(cmd_string)         
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                if exist('Earth-Mo.aei')
                    movefile('Earth-Mo.aei','Earth-Moon.aei')
                end
                for k = 1:N
                    movefile([char(planet(k)) '.aei'], [char(planet(k)) '.dat'])
                end
            
                delete *.dmp *.tmp *.out
            end
            set(app.pushbutton_Integrate,'Enable','on')
            set(app.pushbutton_Plot,'Enable','on');
            set(app.pushbutton_Resonance,'Enable','on');
            if item < 4
                set(app.pushbutton_Lyapunov,'Enable','on');
            else
                set(app.pushbutton_Lyapunov,'Enable','off');
            end
            set(app.popupmenu_element,'Enable','on');
            set(app.popupmenu_planet,'Enable','on');
            set(app.checkboxUser,'Enable','on','Value',0);
            set(app.pushbutton_FFT,'Enable','on');
            set(app.pushbutton_FMFT,'Enable','on');
            set(app.pushbutton_Wavelet,'Enable','on');
            set(app.pushbutton_PlotRes,'Enable','on');
            set(app.pushbutton_StartLFA,'Enable','on');            
        end

        % Value changed function: checkboxUser
        function checkboxUserValueChanged(app, event)
            if get(app.checkboxUser,'Value')
                set(app.popupmenu_element,'Enable','off');
                set(app.popupmenu_planet,'Enable','off');
                set(app.pushbutton_FFT,'Enable','off');
                set(app.pushbutton_FMFT,'Enable','off');
                set(app.pushbutton_Wavelet,'Enable','off');
                set(app.pushbutton_Resonance,'Enable','off');
                set(app.pushbutton_Lyapunov,'Enable','off');
                set(app.pushbutton_StartLFA,'Enable','off');
            else
                set(app.popupmenu_element,'Enable','on');
                set(app.popupmenu_planet,'Enable','on');
                set(app.pushbutton_FFT,'Enable','on');
                set(app.pushbutton_FMFT,'Enable','on');
                set(app.pushbutton_Wavelet,'Enable','on');
                set(app.pushbutton_Resonance,'Enable','on');
                set(app.pushbutton_Lyapunov,'Enable','on');
                set(app.pushbutton_StartLFA,'Enable','on');
            end
        end

        % Button pushed function: pushbutton_ClearAppend
        function pushbutton_ClearAppendButton(app, event)
            clear global
            set(app.pushbutton_ClearAppend,'Enable','off')            
        end

        % Button pushed function: pushbutton_Plot
        function exec_plot(app, event)
            global jumprow
            if get(app.checkboxUser,'Value')
                currDir = cd;
                [filename, pathname] = uigetfile( ...
                    {'*.m', 'All M-Files (*.m)'; ...
                    '*.*','All Files (*.*)'}, ...
                    'Choose function(s) to plot','UserFunctions/');
                if isequal([filename,pathname],[0,0])
                    return
                else
                    [pathstr, name, ext] = fileparts(filename);
                    cd(pathname)
                    eval(name)
                    cd(currDir)
                end
            else
                item_element = str2num(get(app.popupmenu_element,'Value'));
                Elements = get(app.popupmenu_element,'Items');
                element = Elements{item_element};
                item_planet = str2num(get(app.popupmenu_planet,'Value'));
                Planets =  get(app.popupmenu_planet,'Items');
                planet = Planets{item_planet};
                file = [planet,'.dat'];
                if strcmp(planet,'Absent')
                    warndlg('Planet absent. Please, change item.')
                    return
                end
                [time semi ecc incl argperi longasc meananom] = ...
                    textread(file,'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
            %-------------- Rectify argperi -------------------------------------------
                if strcmp(get(app.Rectify,'Checked'),'on')
                    x = argperi;
                    Rectify
                    argperi = x;
                end
            %-------------- Rectify longasc -------------------------------------------
                if strcmp(get(app.Rectify,'Checked'),'on')
                    x = longasc;
                    Rectify
                    longasc = x;
                end
            %-------------- Rectify meananom -------------------------------------------
                if strcmp(get(app.Rectify,'Checked'),'on')
                    x = meananom;
                    Rectify
                    meananom = x;
                end
            %---------------------------------------------------------
                longperi = longasc + argperi;
                meanlong = longasc + argperi + meananom;
                angle = [meananom meanlong argperi longperi longasc];
                if strcmp(get(app.Rectify,'Checked'),'off')
                    angle = angle - 360*floor(angle/360);
                end
                Elements = [semi  ecc  incl  angle  time];
                %----------------------------------------------------------------------
                clear time semi ecc incl longasc  argperi meananom longperi  meanlong
                if item_element == 1;
                    um = ' (AU)';
                elseif item_element == 2;
                    um = '';
                else
                    um = ' (deg)';
                end
                figure
                plot(Elements(:,9),Elements(:,item_element),'r')
                title([planet,' - ',element,um],'FontSize',12)
                xlabel('time (yr)','FontSize',12)
                if (item_element == 1) | (item_element == 2)
                    % do nothing
                else
                    if strcmp(get(app.no_degree, 'Checked'),'off')
                        Degree_y
                    end
                end
            end          
        end

        % Button pushed function: pushbutton_Resonance
        function exec_Resonances(app, event)
            set(app.uipanel15,'Visible','off');
            set(app.uipanel20,'Visible','on');            
        end

        % Button pushed function: pushbutton_PlotRes
        function exec_PlotRes(app, event)
            global jumprow
            for rr = 1:9
                rr = num2str(rr);
                P_rr = ['k',rr,' = get(app.edit_k', rr, ',''Value'');'];
                eval(P_rr)
            end

            item_element = get(app.popupmenu_R1,'Value');
            Items = get(app.popupmenu_R1,'Items');
            element = Items{str2num(item_element)};
            item_planet = get(app.popupmenu_P1,'Value');
            Items = get(app.popupmenu_P1,'Items');            
            planet = Items{str2num(item_planet)};            

            if k1 & ~strcmp(planet,'Absent')
                [time semi ecc incl argperi longasc meananom] = ...
                    textread([planet,'.dat'],'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
                longperi = longasc + argperi;
                meanlong = longasc + argperi + meananom;
                Angles = [meanlong longperi longasc];
                Res_angle = k1*Angles(:,str2num(item_element));
            else
                warndlg('You must select at least two planets on the right pop-up menu and two integers on the left column');
                return
            end
            
            item_element = get(app.popupmenu_R2,'Value');
            Items = get(app.popupmenu_R2,'Items');
            element = Items{str2num(item_element)};
            item_planet = get(app.popupmenu_P2,'Value');
            Items = get(app.popupmenu_P2,'Items');            
            planet = Items{str2num(item_planet)};            

            if k2 & ~strcmp(planet,'Absent')
                [time semi ecc incl argperi longasc meananom] = ...
                    textread([planet,'.dat'],'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
                longperi = longasc + argperi;
                meanlong = longasc + argperi + meananom;
                Angles = [meanlong longperi longasc];
                Res_angle = Res_angle + k2*Angles(:,str2num(item_element));
            else
                warndlg('You must select at least two planets on the right pop-up menu and two integers on the left column');
                return
            end

            item_element = get(app.popupmenu_R3,'Value');
            Items = get(app.popupmenu_R3,'Items');
            element = Items{str2num(item_element)};
            item_planet = get(app.popupmenu_P3,'Value');
            Items = get(app.popupmenu_P3,'Items');            
            planet = Items{str2num(item_planet)};            

            if k3 & ~strcmp(planet,'Absent')
                [time semi ecc incl argperi longasc meananom] = ...
                    textread([planet,'.dat'],'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
                longperi = longasc + argperi;
                meanlong = longasc + argperi + meananom;
                Angles = [meanlong longperi longasc];
                Res_angle = Res_angle + k3*Angles(:,str2num(item_element));
            end

            item_element = get(app.popupmenu_R4,'Value');
            Items = get(app.popupmenu_R4,'Items');
            element = Items{str2num(item_element)};
            item_planet = get(app.popupmenu_P4,'Value');
            Items = get(app.popupmenu_P4,'Items');            
            planet = Items{str2num(item_planet)};            

            if k4 & ~strcmp(planet,'Absent')
                [time semi ecc incl argperi longasc meananom] = ...
                    textread([planet,'.dat'],'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
                longperi = longasc + argperi;
                meanlong = longasc + argperi + meananom;
                Angles = [meanlong longperi longasc];
                Res_angle = Res_angle + k4*Angles(:,str2num(item_element));
            end

            item_element = get(app.popupmenu_R5,'Value');
            Items = get(app.popupmenu_R5,'Items');
            element = Items{str2num(item_element)};
            item_planet = get(app.popupmenu_P5,'Value');
            Items = get(app.popupmenu_P5,'Items');            
            planet = Items{str2num(item_planet)};            

            if k5 & ~strcmp(planet,'Absent')
                [time semi ecc incl argperi longasc meananom] = ...
                    textread([planet,'.dat'],'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
                longperi = longasc + argperi;
                meanlong = longasc + argperi + meananom;
                Angles = [meanlong longperi longasc];
                Res_angle = Res_angle + k5*Angles(:,str2num(item_element));
            end

            item_element = get(app.popupmenu_R6,'Value');
            Items = get(app.popupmenu_R6,'Items');
            element = Items{str2num(item_element)};
            item_planet = get(app.popupmenu_P6,'Value');
            Items = get(app.popupmenu_P6,'Items');            
            planet = Items{str2num(item_planet)};            

            if k6 & ~strcmp(planet,'Absent')
                [time semi ecc incl argperi longasc meananom] = ...
                    textread([planet,'.dat'],'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
                longperi = longasc + argperi;
                meanlong = longasc + argperi + meananom;
                Angles = [meanlong longperi longasc];
                Res_angle = Res_angle + k6*Angles(:,str2num(item_element));
            end

            item_element = get(app.popupmenu_R7,'Value');
            Items = get(app.popupmenu_R7,'Items');
            element = Items{str2num(item_element)};
            item_planet = get(app.popupmenu_P7,'Value');
            Items = get(app.popupmenu_P7,'Items');            
            planet = Items{str2num(item_planet)};            

            if k7 & ~strcmp(planet,'Absent')
                [time semi ecc incl argperi longasc meananom] = ...
                    textread([planet,'.dat'],'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
                longperi = longasc + argperi;
                meanlong = longasc + argperi + meananom;
                Angles = [meanlong longperi longasc];
                Res_angle = Res_angle + k7*Angles(:,str2num(item_element));
            end

            item_element = get(app.popupmenu_R8,'Value');
            Items = get(app.popupmenu_R8,'Items');
            element = Items{str2num(item_element)};
            item_planet = get(app.popupmenu_P8,'Value');
            Items = get(app.popupmenu_P8,'Items');            
            planet = Items{str2num(item_planet)};            

            if k8 & ~strcmp(planet,'Absent')
                [time semi ecc incl argperi longasc meananom] = ...
                    textread([planet,'.dat'],'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
                longperi = longasc + argperi;
                meanlong = longasc + argperi + meananom;
                Angles = [meanlong longperi longasc];
                Res_angle = Res_angle + k8*Angles(:,str2num(item_element));
            end

            item_element = get(app.popupmenu_R9,'Value');
            Items = get(app.popupmenu_R9,'Items');
            element = Items{str2num(item_element)};
            item_planet = get(app.popupmenu_P9,'Value');
            Items = get(app.popupmenu_P9,'Items');            
            planet = Items{str2num(item_planet)};            

            if k9 & ~strcmp(planet,'Absent')
                [time semi ecc incl argperi longasc meananom] = ...
                    textread([planet,'.dat'],'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
                longperi = longasc + argperi;
                meanlong = longasc + argperi + meananom;
                Angles = [meanlong longperi longasc];
                Res_angle = Res_angle + k9*Angles(:,str2num(item_element));
            end                       

            Res_angle = Res_angle - 360*floor(Res_angle/360);
            %-------------- Rectify angle -------------------------------------------
            if strcmp(get(app.Rectify,'Checked'),'on')
                x = Res_angle;
                Rectify
                Res_angle = x;
            end
            %---------------------------------------------------------
            if Res_angle(1:10) == -180
                warndlg('Please, select at least two planets and two non null corresponding harmonics');
                return
            end
            figure
            plot(time, Res_angle)
            if strcmp(get(app.no_degree, 'Checked'),'off')
                Degree_y
            else
                ylabel('(deg)', 'FontSize',14)
            end
            xlabel('time (yr)', 'FontSize',14)
            title('Resonant angle', 'FontSize',14);            
        end

        % Button pushed function: pushbutton_close
        function exec_close(app, event)
            set(app.uipanel15,'Visible','on');
            set(app.uipanel20,'Visible','off');            
        end

        % Button pushed function: pushbutton_Lyapunov
        function exec_Lyapunov(app, event)
            global jumprow

            item_planet = get(app.popupmenu_planet,'Value');
            Items = get(app.popupmenu_planet,'Items');            
            planet_plot = Items{str2num(item_planet)}; 
            
            if strcmp(planet_plot,'Absent')
                warndlg('Planet absent. Please, change item.')
                return
            end
            hh = msgbox(['Please wait, LAPLACE is computing the shadow orbit for ' planet_plot]);
            
            if exist ('0.dat')
                delete 0.dat
            end
            if exist ('energy.dat')
                delete energy.dat
            end

            for k = 1:10
                if ~strcmp(Items{k}, 'Absent')
                    movefile([Items{k}, '.dat'], [Items{k}, '.txt'])
                end
            end
            
            d_o = 1e-12;
            eval(['set(app.edit_sma' item_planet ',''Value'',num2str(str2num(get(app.edit_sma' item_planet ',''Value'')) + d_o,''%3.16g\n''))']);
            exec_Integrate(app)
            close(hh);            
            eval(['set(app.edit_sma' item_planet ',''Value'',num2str(str2num(get(app.edit_sma' item_planet ',''Value'')) - d_o,''%3.10g\n''))']);

            [time semi ecc incl argperi longasc meananom x1 x2 x3] = ...
                textread([planet_plot,'.txt'],'%f %f %f %f %f %f %f %*f %f %f %f','headerlines',jumprow);
            longperi = longasc + argperi;
            meanlong = meananom + longperi;

            [time_p semi_p ecc_p incl_p argperi_p longasc_p meananom_p x1_p x2_p x3_p] = ...
                textread([planet_plot,'.dat'],'%f %f %f %f %f %f %f %*f %f %f %f','headerlines',jumprow);
            longperi_p = longasc_p + argperi_p;
            meanlong_p = meananom_p + longperi_p;

            dist = sqrt((x1_p - x1).^2 + (x2_p - x2).^2 + (x3_p - x3).^2);

            figure
            time(1) = eps;
            plot(time,time./log(dist/dist(1)))
            title(planet_plot,'FontSize',12)
            xlabel('time (yr)','FontSize',12)
            ylabel('Lyapunov time (yr)','FontSize',12)
            
            figure
            plot(time,dist)
            title(planet_plot,'FontSize',12)
            xlabel('time (yr)','FontSize',12)
            ylabel('Shadow orbit distance (AU)','FontSize',12)

            figure
            delta = meanlong - meanlong_p;
            delta = delta - 360*floor(delta/360);
            if  (delta(1) <= (360 + 1e-12)) & (delta(1) >= (360 - 1e-12))
                delta = delta - 360;
            end
            if  (delta(1) <= (-360 + 1e-12)) & (delta(1) >= (-360 - 1e-12))
                delta = delta + 360;
            end
            x = delta;
            Rectify;
            delta = x;
            plot(time,delta)
            title(planet_plot,'FontSize',12)
            xlabel('time (yr)','FontSize',12)
            ylabel('Shadow orbit distance for meanlong (deg)','FontSize',12)

            figure
            plot(time,semi_p - semi)
            title(planet_plot,'FontSize',12)
            xlabel('time (yr)','FontSize',12)
            ylabel('Shadow orbit distance for sma (AU)','FontSize',12)
        
            figure
            plot(time,ecc_p - ecc)
            title(planet_plot,'FontSize',12)
            xlabel('time (yr)','FontSize',12)
            ylabel('Shadow orbit distance for ecc','FontSize',12)
            
            figure
            plot(time,incl_p - incl)
            title(planet_plot,'FontSize',12)
            xlabel('time (yr)','FontSize',12)
            ylabel('Shadow orbit distance for incl (deg)','FontSize',12)
             
            delete *.dat
            
            for k = 1:10
                if ~strcmp(Items{k}, 'Absent')
                    movefile([Items{k}, '.txt'], [Items{k}, '.dat'])
                end
            end  
        end

        % Button pushed function: pushbutton_StartLFA
        function exec_StartLFA(app, event)
            if get(app.edit_totint,'Value')/get(app.edit_outstep,'Value') < 2^13
                 warndlg('For a good precision, the Point number (i.e., Total integration time/Output step) must be at least equal to 2^13 = 8192',...
                     'Increase the point number')
                 return
            end
            
            set(app.pushbutton_StartLFA,'Enable','off');
            set(app.pushbutton_StopLFA,'Enable','on');
            set(app.pushbutton_StartLFA,'Visible','off');
            set(app.pushbutton_StopLFA,'Visible','on');
            set(app.pushbutton_Plot,'Enable','off');
            set(app.pushbutton_Lyapunov,'Enable','off');
            set(app.pushbutton_Resonance,'Enable','off');
            
            planet = get(app.popupmenu_planet,'Items');

            cd(app.LaplaceDir)
            
            N = 10;
            for k = 1:10
                if strcmp(planet(k),'Absent')
                    N = N-1;
                end
            end
            
            for k = 1:N
                while strcmp(planet(k),'Absent')
                    planet(k) = '';
                end
            end
            
            while size(planet,1) > N
                planet(N+1) = '';
            end
            
            item = str2num(get(app.popupmenu_ODE,'Value'));
            if item == 1;
                Method = 'Symplectic';
            elseif item == 2;
                Method = 'Bulirsch-Stoer';
            elseif item == 3;
                Method = 'Runge-Kutta';
            elseif item == 4;
                Method = 'MVS';
            elseif item == 5;
                Method = 'Radau';
            elseif item == 6;
                Method = 'BS2';
            elseif item == 7;
                Method = 'Hybrid';
            end
            
            integstep = num2str(get(app.edit_integstep,'Value'));
            totint = num2str(get(app.edit_totint,'Value'));
            outstep = num2str(get(app.edit_outstep,'Value'));
            
            global ktime
            ktime_str = num2str(ktime);
            if strcmp(ktime_str,'')
                ktime = 0;
                ktime_str = num2str(ktime);
            end
            
            if item < 4
                jumprow = floor(str2num(totint)/str2num(outstep)) + 17;
                jpr = 17;
            elseif item> 3
                jumprow = floor(str2num(totint)/str2num(outstep)) + 4;
                jpr = 4;
            end
            
            while strcmp(get(app.pushbutton_StopLFA,'Visible'),'on')
                if item < 4
                    fid = fopen( 'custom.hnb', 'wt');
                    fprintf(fid, ['Integrator: ',Method,'\n']);
                    fprintf(fid, ['StepSize: ',integstep,'\n']);
                    fprintf(fid, ['Tfinal: ',totint,'\n']);
                    fprintf(fid, ['OutputInterval: ',outstep,'\n']);
                    fprintf(fid, ['EnergyInterval: ',outstep,'\n']);
                    if get(app.PostNewton, 'Value') == 1;
                        fprintf(fid,'PostNewtonian = True\n');
                    end
                    for k = 1:N
                        file = [char(planet(k)) '.dat'];
                        [time semi ecc incl argperi longasc meananom mass] = ...
                            textread(file,'%f %f %f %f %f %f %f %f %*f %*f %*f','headerlines',jumprow);
                        longperi = longasc + argperi;
                        meanlong = meananom + longperi;
                        fseek(fid, 0, 'eof');
                        fprintf(fid, [num2str(mass,20),' ', num2str(semi,20),' ',num2str(ecc,20),' ',num2str(incl,20),' ',num2str(longasc,20),' ',num2str(longperi,20),' ',num2str(meanlong,20) '\n']);
                    end
                    fclose(fid);
                    cmd_string = ['!',app.LaplaceDir,'/hnbody -q init.hnb'];
                    eval(cmd_string)
                    for k = 1:N
                        k_str = num2str(k);
                        delete([planet{k},'.dat'])
                        movefile([k_str,'.dat'],[planet{k},'.dat'])
                    end
                elseif item > 3
                    fid = fopen( 'param.in', 'wt');
                    fprintf(fid, ')O+_06 Integration parameters  (WARNING: Do not delete this line!!)\n');
                    fprintf(fid, ['algorithm = ',Method,'\n']);
                    fprintf(fid, ['start time = 0\n']);
                    fprintf(fid, ['stop time = ',num2str(365.25*str2num(totint)),'\n']);
                    fprintf(fid, ['output interval = ',num2str(365.25*str2num(outstep)),'\n']);
                    fprintf(fid, ['timestep = ',num2str(365.25*str2num(integstep)),'\n']);
                    fprintf(fid, ['accuracy parameter=1.d-12\n']);
                    fprintf(fid, ['stop integration after a close encounter = no\n']);
                    fprintf(fid, ['allow collisions to occur = no\n']);
                    fprintf(fid, ['include collisional fragmentation = no\n']);
                    fprintf(fid, ['express time in days or years = years\n']);
                    fprintf(fid, ['express time relative to integration start time = yes\n']);
                    fprintf(fid, ['output precision = medium\n']);
                    fprintf(fid, ['< not used at present >\n']);
                    fprintf(fid, ['include relativity in integration= no\n']);
                    fprintf(fid, ['include user-defined force = no\n']);
                    fprintf(fid, ['ejection distance (AU)= 100\n']);
                    fprintf(fid, ['radius of central body (AU) = 0.005\n']);
                    fprintf(fid, ['central mass (solar) = 1.0\n']);
                    fprintf(fid, ['central J2 = 0\n']);
                    fprintf(fid, ['central J4 = 0\n']);
                    fprintf(fid, ['central J6 = 0\n']);
                    fprintf(fid, ['< not used at present >\n']);
                    fprintf(fid, ['< not used at present >\n']);
                    fprintf(fid, ['Hybrid integrator changeover (Hill radii) = 3.\n']);
                    numts = num2str(ceil(get(app.edit_totint,'Value')/get(app.edit_integstep,'Value')/1000));
                    fprintf(fid, ['number of timesteps between data dumps = ' numts '\n']);
                    fprintf(fid, ['number of timesteps between periodic effects = 100\n']);
                    fclose(fid);
            
                    fid = fopen( 'element.in', 'wt');
                    fprintf(fid, ')O+_06 element  (WARNING: Do not delete this line!!)\n');
                    fprintf(fid, 'number of input files = 1\n');
                    fprintf(fid, 'xv.out\n');
                    fprintf(fid, 'type of elements (central body, barycentric, Jacobi) = Cen\n');
                    fprintf(fid, 'minimum interval between outputs (days) = 3.6525\n');
                    fprintf(fid, 'express time in days or years = years\n');
                    fprintf(fid, 'express time relative to integration start time = yes\n');
                    fprintf(fid, 'a20.16 e20.16 i20.16 g20.16 n20.16 l20.16 m13e\n');
                    for k = 1:N
                        fprintf(fid, [char(planet(k)),'\n']);
                    end
                    fclose(fid);
            
                    fid = fopen( 'big.in', 'wt');
                    fprintf(fid, [')O+_06 Big-body initial data  (WARNING: Do not delete this line!!)\n']);
                    fprintf(fid, ['style = Asteroidal\n']);
                    fprintf(fid, ['epoch (in days) = 0.\n']);
                    for k = 1:N
                        file = [char(planet(k)) '.dat'];
                        if ~strcmp(file,'Asteroid.dat')
                            [time semi ecc incl argperi longasc meananom mass] = ...
                                textread(file,'%f %f %f %f %f %f %f %f %*f %*f %*f','headerlines',jumprow);
                            fseek(fid, 0, 'eof');
                            fprintf(fid,[char(planet(k)),'   m=',num2str(mass,20),'\n']);
                            fprintf(fid, [num2str(semi,20),' ',num2str(ecc,20),' ',num2str(incl,20),' ',num2str(argperi,20),' ',num2str(longasc,20),' ',num2str(meananom,20),'  0.  0.  0. \n']);
                        end
                    end
                    fclose(fid);
            
                    fid = fopen( 'small.in', 'wt');
                    fprintf(fid, [')O+_06 Small-body initial data  (WARNING: Do not delete this line!!)\n']);
                    fprintf(fid, ['style = Asteroidal\n']);
                    for k = 1:N
                        file = [char(planet(k)) '.dat'];
                        if strcmp(file,'Asteroid.dat')
                            [time semi ecc incl argperi longasc meananom mass] = ...
                                textread(file,'%f %f %f %f %f %f %f %f %*f %*f %*f','headerlines',jumprow);
                            fseek(fid, 0, 'eof');
                            fprintf(fid,['Asteroid   m=',num2str(mass,20),'\n']);
                            fprintf(fid, [num2str(semi,20),' ',num2str(ecc,20),' ',num2str(incl,20),' ',num2str(argperi,20),' ',num2str(longasc,20),' ',num2str(meananom,20),'  0.  0.  0. \n']);
                        end
                    end
                    fclose(fid);
            
                    cmd_string = ['!',app.LaplaceDir,'/mercury6_2 2'];
                    eval(cmd_string)
                    cmd_string = ['!',app.LaplaceDir,'/element6'];
                    eval(cmd_string)
                    delete *.dat *.dmp *.tmp *.out
            
                    if exist('Earth-Mo.aei')
                        movefile('Earth-Mo.aei','Earth-Moon.aei')
                    end
                    for k = 1:N
                        movefile([char(planet(k)) '.aei'], [char(planet(k)) '.dat'])
                    end
                end
            
                min_f = '-5000';
                max_f = '5000';
                flag_FMFT = get(app.popupmenu_FMFT_flag,'Value');
                nfreq = '1';
                data_sep = num2str(2*pi*100000*str2num(outstep));
                for k = 1:N
                    [time semi ecc incl argperi longasc meananom] = ...
                        textread([planet{k},'.dat'],'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jpr);
                    longperi = longasc + argperi;
                    meanlong = longperi + meananom;
                    d = size(time,1);
                    ndata = num2str(2^(floor(log2(d))));
            
                    if strcmp(planet(k),'Mercury')
                        sma = str2num(get(app.edit_sma1,'Value'));
                    elseif strcmp(planet(k),'Venus')
                        sma = str2num(get(app.edit_sma2,'Value'));
                    elseif strcmp(planet(k),'Earth-Moon')
                        sma = str2num(get(app.edit_sma3,'Value'));
                    elseif strcmp(planet(k),'Mars')
                        sma = str2num(get(app.edit_sma4,'Value'));
                    elseif strcmp(planet(k),'Asteroid')
                        sma = str2num(get(app.edit_sma5,'Value'));
                    elseif strcmp(planet(k),'Jupiter')
                        sma = str2num(get(app.edit_sma6,'Value'));
                    elseif strcmp(planet(k),'Saturn')
                        sma = str2num(get(app.edit_sma7,'Value'));
                    elseif strcmp(planet(k),'Uranus')
                        sma = str2num(get(app.edit_sma8,'Value'));
                    elseif strcmp(planet(k),'Neptune')
                        sma = str2num(get(app.edit_sma9,'Value'));
                    elseif strcmp(planet(k),'Pluto')
                        sma = str2num(get(app.edit_sma10,'Value'));
                    end
                    period = sqrt(sma^3);
                    tm = ktime*str2num(totint);
                    if str2num(outstep) < 1/2*period
                        semi_x = semi.*cos(pi/180*meanlong);
                        semi_y = semi.*sin(pi/180*meanlong);
                        fid = fopen(['input_semi.txt'], 'wt');
                        fprintf(fid, '%14.10f %14.10f %14.10f\n',...
                            [time'; semi_x'; semi_y']);
                        fclose(fid);
            
                        cmd_string = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                            data_sep,' ',ndata,' <input_semi.txt >FMFT_of_semi.txt'];
                        eval(cmd_string)
                        delete input_semi.txt
            
                        Freq = textread('FMFT_of_semi.txt', '%f %*f %*f', 'headerlines', 4);
                        Freq = Freq/1000;
                        set(figure(k), 'Visible', 'off');
                        hold on
                        plot(tm,Freq,'.k','MarkerSize',10);
                        title(char(planet(k)))
                        xlabel('Time (yr)', 'FontSize',10)
                        ylabel('Frequency  (revolution/yr)')
                    end
            
                    if str2num(totint) > 500000
                        ecc_x = ecc.*cos(pi/180*longperi);
                        ecc_y = ecc.*sin(pi/180*longperi);
                        fid = fopen(['input_ecc.txt'], 'wt');
                        fprintf(fid, '%14.10f %14.10f %14.10f\n',...
                            [time'; ecc_x'; ecc_y']);
                        fclose(fid);
                        cmd_string = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                            data_sep,' ',ndata,' <input_ecc.txt >FMFT_of_ecc.txt'];
                        eval(cmd_string)
                        delete input_ecc.txt
                        Freq = textread('FMFT_of_ecc.txt', '%f %*f %*f', 'headerlines', 4);
                        Freq = Freq/1000;
                        set(figure(k), 'Visible', 'off');
                        hold on
                        plot(tm,Freq,'.r','MarkerSize',10);
                        title(char(planet(k)))
                        xlabel('Time (yr)', 'FontSize',10)
                        ylabel('Frequency  (revolution/yr)')
            
                        incl_x = sin(pi/180*incl).*cos(pi/180*longasc);
                        incl_y = sin(pi/180*incl).*sin(pi/180*longasc);
                        fid = fopen(['input_incl.txt'], 'wt');
                        fprintf(fid, '%14.10f %14.10f %14.10f\n',...
                            [time'; incl_x'; incl_y']);
                        fclose(fid);
                        cmd_string = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                        data_sep,' ',ndata,' <input_incl.txt >FMFT_of_incl.txt'];
                        eval(cmd_string)
                        delete input_incl.txt
                        Freq = textread('FMFT_of_incl.txt', '%f %*f %*f', 'headerlines', 4);
                        Freq = Freq/1000;
                        set(figure(k), 'Visible', 'off');
                        hold on
                        plot(tm,Freq,'.b','MarkerSize',10);
                    end
                end
                display(['Integration time (yr) = ',num2str(tm)])
                ktime = ktime + 1;
                drawnow
            end
            for k = 1:N
                set(figure(k), 'Visible', 'on');
            
                annotation(figure(k),'textbox',...
                    [0.7 0.85 0.16 0.07],...
                    'Color',[0 0 0],...
                    'String',{'Frequency 1'},...
                    'EdgeColor',[0 0 0]);
            
                annotation(figure(k),'textbox',...
                    [0.7 0.75 0.16 0.07],...
                    'Color',[1 0 0],...
                    'String',{'Frequency 2'},...
                    'EdgeColor',[1 0 0]);
            
                annotation(figure(k),'textbox',...
                    [0.7 0.65 0.16 0.07],...
                    'Color',[0 0 1],...
                    'String',{'Frequency 3'},...
                    'EdgeColor',[0 0 1]);
            
            end
            set(app.pushbutton_StartLFA,'Enable','on');            
        end

        % Button pushed function: pushbutton_StopLFA
        function exec_StopLFA(app, event)
            display('Stop')
            set(app.pushbutton_StopLFA,'Enable','off');
            set(app.pushbutton_StopLFA,'Visible','off');
            set(app.pushbutton_StartLFA,'Enable','off');
            set(app.pushbutton_StartLFA,'Visible','on');            
        end

        % Button pushed function: pushbutton_FFT
        function exec_FFT(app, event)
            global jumprow
            item_planet = get(app.popupmenu_planet,'Value');
            Items = get(app.popupmenu_planet,'Items');            
            planet = Items{str2num(item_planet)}; 
            file = [planet,'.dat'];
            if strcmp(planet,'Absent')
                warndlg('Planet absent. Please, change item.')
                return
            end
            [time semi ecc incl argperi longasc meananom] = ...
                textread(file,'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
            longperi = longasc + argperi;
            meanlong = longasc + argperi + meananom;
            d = size(time,1);
            nump = 2^(floor(log2(d)));
            OutStep = get(app.edit_outstep, 'Value');
            freq_max = 2*pi/OutStep;
            freq = [0 :freq_max/nump: freq_max];
            freq(nump) = [];
            freq = (freq - freq_max/2)/(2*pi);
            valCheck = get(app.check_log,'Value');
            
            if strcmp(planet,'Mercury')
                sma = str2num(get(app.edit_sma1,'Value'));
            elseif strcmp(planet,'Venus')
                sma = str2num(get(app.edit_sma2,'Value'));
            elseif strcmp(planet,'Earth-Moon')
                sma = str2num(get(app.edit_sma3,'Value'));
            elseif strcmp(planet,'Mars')
                sma = str2num(get(app.edit_sma4,'Value'));
            elseif strcmp(planet,'Asteroid')
                sma = str2num(get(app.edit_sma5,'Value'));
            elseif strcmp(planet,'Jupiter')
                sma = str2num(get(app.edit_sma6,'Value'));
            elseif strcmp(planet,'Saturn')
                sma = str2num(get(app.edit_sma7,'Value'));
            elseif strcmp(planet,'Uranus')
                sma = str2num(get(app.edit_sma8,'Value'));
            elseif strcmp(planet,'Neptune')
                sma = str2num(get(app.edit_sma9,'Value'));
            elseif strcmp(planet,'Pluto')
                sma = str2num(get(app.edit_sma10,'Value'));
            end
            period = sqrt(sma^3);
            
            if OutStep > 1/2*period
                warndlg(['Output step > ', num2str(1/2*period), '.  FFT for Semimajor axis not correct (Nyquist-Shannon theorem)'], 'Output step too large')
            end
            
            F = fft(semi.*cos(pi/180*meanlong) + i*(semi.*sin(pi/180*meanlong)), nump);
            figure
            Farray = sqrt(real(F).^2 + imag(F).^2);
            Farray1 = Farray(1:nump/2, 1);
            Farray2 = Farray(nump/2+1:nump, 1);
            Farray = [Farray2;  Farray1];
            if valCheck == 0;
                plot(freq, Farray/nump)
            else
                semilogy(freq, Farray/nump)
            end
            title(['FFT for Semimajor axis of ', planet])
            xlabel('Frequency', 'FontSize',10), ylabel('Amplitude', 'FontSize',10)
            
            
            F = fft(ecc.*cos(pi/180*longperi) + i*(ecc.*sin(pi/180*longperi)), nump);
            figure
            Farray = sqrt(real(F).^2 + imag(F).^2);
            Farray1 = Farray(1:nump/2, 1);
            Farray2 = Farray(nump/2+1:nump, 1);
            Farray = [Farray2;  Farray1];
            if valCheck == 0;
                plot(freq, Farray/nump)
            else
                semilogy(freq, Farray/nump)
            end
            title(['FFT for Eccentricity of ', planet])
            xlabel('Frequency', 'FontSize',10), ylabel('Amplitude', 'FontSize',10)
            
            F = fft(sin(pi/180*incl).*cos(pi/180*longasc) + i*(sin(pi/180*incl).*sin(pi/180*longasc)), nump);
            figure
            Farray = sqrt(real(F).^2 + imag(F).^2);
            Farray1 = Farray(1:nump/2, 1);
            Farray2 = Farray(nump/2+1:nump, 1);
            Farray = [Farray2;  Farray1];
            if valCheck == 0;
                plot(freq, Farray/nump)
            else
                semilogy(freq, Farray/nump)
            end
            title(['FFT for Inclination of ', planet])
            xlabel('Frequency', 'FontSize',10), ylabel('Amplitude', 'FontSize',10)            
        end

        % Button pushed function: pushbutton_FMFT
        function exec_FMFT(app, event)
            if (get(app.edit_totint,'Value')/get(app.edit_outstep,'Value')) < 2^13
                 warndlg('For a good precision, the Point number (i.e., Total integration time/Output step) must be at least equal to 2^13 = 8192',...
                     'Increase the point number')
                 return
            end                        
            global jumprow
            item_element = get(app.popupmenu_element,'Value');
            Items = get(app.popupmenu_element,'Items');            
            element = Items{str2num(item_element)}; 
            item_planet = get(app.popupmenu_planet,'Value');
            Items = get(app.popupmenu_planet,'Items');            
            planet = Items{str2num(item_planet)};
            file = [planet,'.dat'];
            if strcmp(planet,'Absent')
                warndlg('Planet absent. Please, change item.')
                return
            end
            
            if isdeployed
                hhh = helpdlg(sprintf(['Please wait, LAPLACE is calculating. \n',...
                    'When this box will automatically shut down,\n'...
                    'in the LAPLACE folder you will find\n'...
                    'a text file containing the frequencies on KAM tori,\n'...
                    'along with a graphical representation.']),...
                    'Frequency Analysis');
            else
                hhh = helpdlg(sprintf(['Please wait, LAPLACE is calculating. \n',...
                    'When this box will automatically shut down,\n'...
                    'in the MATLAB editor you will find\n'...
                    'a text file containing the frequencies on KAM tori,\n'...
                    'along with a graphical representation.']),...
                    'Frequency Analysis');
            end
            min_f = num2str(1000*get(app.edit_fromfreq,'Value'),'%6.12f');
            max_f = num2str(1000*get(app.edit_tofreq,'Value'),'%6.12f');
            
            flag_FMFT = num2str(get(app.popupmenu_FMFT_flag,'Value'));
            nfreq = num2str(get(app.edit_nfreq,'Value'));
            outstep = get(app.edit_outstep,'Value');
            data_sep = num2str(2*pi*100000*outstep,'%6.12f');
            [time semi ecc incl argperi longasc meananom] = ...
                textread(file,'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
            longperi = longasc + argperi;
            meanlong = longasc + argperi + meananom;
            d = size(time,1);
            ndata = num2str(2^(floor(log2(d))));
            
            if strcmp(element,'Semimajor axis');
                if strcmp(planet,'Mercury')
                    sma = str2num(get(app.edit_sma1,'Value'));
                elseif strcmp(planet,'Venus')
                    sma = str2num(get(app.edit_sma2,'Value'));
                elseif strcmp(planet,'Earth-Moon')
                    sma = str2num(get(app.edit_sma3,'Value'));
                elseif strcmp(planet,'Mars')
                    sma = str2num(get(app.edit_sma4,'Value'));
                elseif strcmp(planet,'Asteroid')
                    sma = str2num(get(app.edit_sma5,'Value'));
                elseif strcmp(planet,'Jupiter')
                    sma = str2num(get(app.edit_sma6,'Value'));
                elseif strcmp(planet,'Saturn')
                    sma = str2num(get(app.edit_sma7,'Value'));
                elseif strcmp(planet,'Uranus')
                    sma = str2num(get(app.edit_sma8,'Value'));
                elseif strcmp(planet,'Neptune')
                    sma = str2num(get(app.edit_sma9,'Value'));
                elseif strcmp(planet,'Pluto')
                    sma = str2num(get(app.edit_sma10,'Value'));
                elseif strcmp(planet,'Sun')
                    sma = str2num(get(app.edit_sma1,'Value'));
                end
                period = sqrt(sma^3);
            
                if outstep > 1/2*period
                    warndlg(['Output step > ', num2str(1/2*period), '.  FMFT for Semimajor axis not correct (Nyquist-Shannon theorem)'], 'Output step too large')
                end
                semi_x = semi.*cos(pi/180*meanlong);
                semi_y = semi.*sin(pi/180*meanlong);
                fid = fopen(['input_semi.txt'], 'wt');
                fprintf(fid, '%14.10f %14.10f %14.10f\n',...
                    [time'; semi_x'; semi_y']);
                fclose(fid);
            
                cmd_string = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                    data_sep,' ',ndata,' <input_semi.txt >FMFT_of_semi.txt'];
                eval(cmd_string)
                delete input_semi.txt
            
                [Freq  Amp Phase] = textread('FMFT_of_semi.txt', '%f %f %f', 'headerlines', 4);
                Freq = Freq/1000;
                figure
                bar(Freq,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title(['FMFT for Semimajor axis of ', planet])
                xlabel('Frequency (rev/yr)', 'FontSize',10)
            
                fid = fopen(['FMFT_of_semi.txt'], 'a');
                fprintf(fid,'   \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for the semimajor axis of ',planet,'\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                if isdeployed
                    % do nothing
                else
                    edit FMFT_of_semi.txt
                end
            elseif strcmp(element,'Eccentricity');
                ecc_x = ecc.*cos(pi/180*longperi);
                ecc_y = ecc.*sin(pi/180*longperi);
                fid = fopen(['input_ecc.txt'], 'wt');
                fprintf(fid, '%14.10f %14.10f %14.10f\n',...
                    [time'; ecc_x'; ecc_y']);
                fclose(fid);
            
                cmd_string = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                    data_sep,' ',ndata,' <input_ecc.txt >FMFT_of_ecc.txt'];
                eval(cmd_string)
                delete input_ecc.txt
            
                [Freq  Amp Phase] = textread('FMFT_of_ecc.txt', '%f %f %f', 'headerlines', 4);
                Freq = Freq/1000;
                figure
                bar(Freq,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title(['FMFT for Eccentricity of ', planet])
                xlabel('Frequency (revolution/yr)', 'FontSize',10)
            
                fid = fopen(['FMFT_of_ecc.txt'], 'a');
                fprintf(fid,'   \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for the eccentricity of ',planet,'\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                if isdeployed
                    %do nothing
                else
                    edit  FMFT_of_ecc.txt
                end
            elseif strcmp(element,'Inclination');
                incl_x = sin(pi/180*incl).*cos(pi/180*longasc);
                incl_y = sin(pi/180*incl).*sin(pi/180*longasc);
                fid = fopen(['input_incl.txt'], 'wt');
                fprintf(fid, '%14.10f %14.10f %14.10f\n',...
                    [time'; incl_x'; incl_y']);
                fclose(fid);
            
                cmd_string = ['!',app.PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                    data_sep,' ',ndata,' <input_incl.txt >FMFT_of_incl.txt'];
                eval(cmd_string)
                delete input_incl.txt
            
                [Freq  Amp Phase] = textread('FMFT_of_incl.txt', '%f %f %f', 'headerlines', 4);
                Freq = Freq/1000;
                figure
                bar(Freq,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
                title(['FMFT for Inclination of ', planet])
                xlabel('Frequency (revolution/yr)', 'FontSize',10)
            
                fid = fopen(['FMFT_of_incl.txt'], 'a');
                fprintf(fid,'   \n');
                fprintf(fid,'**************************************************************\n');
                fprintf(fid, ['Frequency analysis for the inclination of ',planet,'\n']);
                fprintf(fid,'**************************************************************\n');
                fclose(fid);
            
                if isdeployed
                    %do nothing
                else
                    edit  FMFT_of_incl.txt
                end
            else
                warndlg(sprintf(['For the frequency analysis you must select one of the following \n',...
                    '1) ''Semimajor axis'' for Frequency 1 \n'...
                    '2) ''Eccentricity'' for Frequency 2 \n'...
                    '3) ''Inclination'' for Frequency 3']),...
                    'Frequency Analysis');
            end
            
            close(hhh)            
        end

        % Button pushed function: pushbutton_Wavelet
        function exec_Wavelet(app, event)
            global jumprow
            item_element = get(app.popupmenu_element,'Value');
            Items = get(app.popupmenu_element,'Items');            
            element = Items{str2num(item_element)}; 
            item_planet = get(app.popupmenu_planet,'Value');
            Items = get(app.popupmenu_planet,'Items');            
            planet = Items{str2num(item_planet)};
            file = [planet,'.dat'];
            if strcmp(planet,'Absent')
                warndlg('Planet absent. Please, change item.')
                return
            end
            h = helpdlg(sprintf(['Please wait, LAPLACE is calculating.']),...
                'Wavelet transform');
            min_freq = get(app.edit_min_freq,'Value');
            max_freq = get(app.edit_max_freq,'Value');
            eta = 1;
            sigma2 = (2*pi*get(app.edit_Variance,'Value'))^2;
            fourier_factor = 2/(eta + sqrt(2/sigma2 + eta^2));
            OutStep = get(app.edit_outstep, 'Value');
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [time semi ecc incl argperi longasc meananom] = ...
                textread(file,'%f %f %f %f %f %f %f %*f %*f %*f %*f','headerlines',jumprow);
            longperi = longasc + argperi;
            meanlong = longasc + argperi + meananom;
            
            if strcmp(element,'Semimajor axis');
                if strcmp(planet,'Mercury')
                    sma = str2num(get(app.edit_sma1,'Value'));
                elseif strcmp(planet,'Venus')
                    sma = str2num(get(app.edit_sma2,'Value'));
                elseif strcmp(planet,'Earth-Moon')
                    sma = str2num(get(app.edit_sma3,'Value'));
                elseif strcmp(planet,'Mars')
                    sma = str2num(get(app.edit_sma4,'Value'));
                elseif strcmp(planet,'Asteroid')
                    sma = str2num(get(app.edit_sma5,'Value'));
                elseif strcmp(planet,'Jupiter')
                    sma = str2num(get(app.edit_sma6,'Value'));
                elseif strcmp(planet,'Saturn')
                    sma = str2num(get(app.edit_sma7,'Value'));
                elseif strcmp(planet,'Uranus')
                    sma = str2num(get(app.edit_sma8,'Value'));
                elseif strcmp(planet,'Neptune')
                    sma = str2num(get(app.edit_sma9,'Value'));
                elseif strcmp(planet,'Pluto')
                    sma = str2num(get(app.edit_sma10,'Value'));
                end
                period = sqrt(sma^3);
                if OutStep > 1/2*period
                    close(h)
                    warndlg(['Output step too large ( > ', num2str(1/2*period), ...
                        ' ). Wavelet analysis for the semimajor axis of ',planet,' not computed.']);
                    return
                else
                    semi_x = semi.*cos(pi/180*meanlong);
                    semi_y = semi.*sin(pi/180*meanlong);
                    signal = semi_x + i*semi_y;
                end
            
            elseif strcmp(element,'Eccentricity');
                ecc_x = ecc.*cos(pi/180*longperi);
                ecc_y = ecc.*sin(pi/180*longperi);
                signal = ecc_x + i*ecc_y;
            elseif strcmp(element,'Inclination');
                incl_x = sin(pi/180*incl).*cos(pi/180*longasc);
                incl_y = sin(pi/180*incl).*sin(pi/180*longasc);
                signal = incl_x + i*incl_y;
            else
                close(h)
                warndlg(sprintf(['For the wavelet analysis you must select one of the following \n',...
                    '1) ''Semimajor axis'' for Frequency 1 \n'...
                    '2) ''Eccentricity'' for Frequency 2 \n'...
                    '3) ''Inclination'' for Frequency 3']),...
                    'Wavelet Analysis');
                return
            end
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            [wt, Frequency] = wavelet_transf(signal, OutStep,min_freq, max_freq, eta, sigma2, fourier_factor);
            spectrum = (abs(wt)).^2;
            
            if spectrum < 1000*eps
                warndlg('Frequency out of range')
                close(h)
                return
            end
            
            figure
            colormap(gray)
            contour(time, Frequency, spectrum)
            xlabel('time (yr)', 'FontSize',12), ylabel('Frequency \times 1000', 'FontSize',12),
            title(['Frequency for the ',element,' of ',planet], 'FontSize',12)
            
            close(h)            
        end

        % Value changed function: edit_mass1
        function edit_mass1_ValueChanged(app, event)
            DisableAll
            k_str = '1';
            massOnOff
        end

        % Value changed function: edit_mass2
        function edit_mass2_ValueChanged(app, event)
            DisableAll
            k_str = '2';
            massOnOff       
        end

        % Value changed function: edit_mass3
        function edit_mass3_ValueChanged(app, event)
            DisableAll
            k_str = '3';
            massOnOff      
        end

        % Value changed function: edit_mass4
        function edit_mass4_ValueChanged(app, event)
            DisableAll
            k_str = '4';
            massOnOff          
        end

        % Value changed function: edit_mass5
        function edit_mass5_ValueChanged(app, event)
            DisableAll
            k_str = '5';
            massOnOff      
        end

        % Value changed function: edit_mass6
        function edit_mass6_ValueChanged(app, event)
            DisableAll
            k_str = '6';
            massOnOff      
        end

        % Value changed function: edit_mass7
        function edit_mass7_ValueChanged(app, event)
            DisableAll
            k_str = '7';
            massOnOff      
        end

        % Value changed function: edit_mass8
        function edit_mass8_ValueChanged(app, event)
            DisableAll
            k_str = '8';
            massOnOff      
        end

        % Value changed function: edit_mass9
        function edit_mass9_9ValueChanged(app, event)
            DisableAll
            k_str = '9';
            massOnOff      
        end

        % Value changed function: edit_mass10
        function edit_mass10_ValueChanged(app, event)
            DisableAll
            k_str = '10';
            massOnOff      
        end

        % Value changed function: edit_sma1
        function edit_sma1_ValueChanged(app, event)
            DisableAll            
        end

        % Value changed function: edit_sma2
        function edit_sma2_ValueChanged(app, event)
            DisableAll            
        end

        % Value changed function: edit_sma3
        function edit_sma3_ValueChanged(app, event)
            DisableAll            
        end

        % Value changed function: edit_sma4
        function edit_sma4_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_sma5
        function edit_sma5_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_sma6
        function edit_sma6_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_sma7
        function edit_sma7_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_sma8
        function edit_sma8_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_sma9
        function edit_sma9_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_sma10
        function edit_sma10_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_ecc1
        function edit_ecc1_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_ecc2
        function edit_ecc2_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_ecc3
        function edit_ecc3_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_ecc4
        function edit_ecc4_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_ecc5
        function edit_ecc5_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_ecc6
        function edit_ecc6_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_ecc7
        function edit_ecc7_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_ecc8
        function edit_ecc8_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_ecc9
        function edit_ecc9_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_ecc10
        function edit_ecc10_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_incl1
        function edit_incl1_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_incl2
        function edit_incl2_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_incl3
        function edit_incl3_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_incl4
        function edit_incl4_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_incl5
        function edit_incl5_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_incl6
        function edit_incl6_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_incl7
        function edit_incl7_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_incl8
        function edit_incl8_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_incl9
        function edit_incl9_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_incl10
        function edit_incl10_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_longmean1
        function edit_longmean1_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_longmean2
        function edit_longmean2_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_longmean3
        function edit_longmean3_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_longmean4
        function edit_longmean4_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_longmean5
        function edit_longmean5_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_longmean6
        function edit_longmean6_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_longmean7
        function edit_longmean7_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_longmean8
        function edit_longmean8_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_longmean9
        function edit_longmean9_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_longmean10
        function edit_longmean10_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_peri1
        function edit_peri1_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_peri2
        function edit_peri2_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_peri3
        function edit_peri3_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_peri4
        function edit_peri4_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_peri5
        function edit_peri5_ValueChanged(app, event)
            value = app.edit_peri5.Value;
            
        end

        % Value changed function: edit_peri6
        function edit_peri6_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_peri7
        function edit_peri7_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_peri8
        function edit_peri8_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_peri9
        function edit_peri9_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_peri10
        function edit_peri10_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_asc1
        function edit_asc1_ValueChanged(app, event)
           DisableAll
        end

        % Value changed function: edit_asc2
        function edit_asc2_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_asc3
        function edit_asc3_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_asc4
        function edit_asc4_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_asc5
        function edit_asc5_ValueChanged(app, event)
           DisableAll
        end

        % Value changed function: edit_asc6
        function edit_asc6_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_asc7
        function edit_asc7_ValueChanged(app, event)
           DisableAll
        end

        % Value changed function: edit_asc8
        function edit_asc8_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_asc9
        function edit_asc9_ValueChanged(app, event)
           DisableAll
        end

        % Value changed function: edit_asc10
        function edit_asc10_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: popupmenu_ODE
        function popupmenu_ODE_ValueChanged(app, event)
            DisableAll
            val = str2num(get(app.popupmenu_ODE, 'Value'));      
            if val == 1 | val == 2 | val == 3
                set(app.PostNewton,'Enable','on')
                set(app.pushbutton_Calculate,'Enable','on')
            else
                set(app.PostNewton,'Enable','off')
                set(app.PostNewton,'Value',false)
%                set(app.pushbutton_Calculate,'Enable','off')
            end
        end

        % Value changed function: PostNewton
        function PostNewton_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_integstep
        function edit_integstep_ValueChanged(app, event)
            DisableAll
            clear global
            set(app.pushbutton_ClearAppend,'Enable','off')         
        end

        % Value changed function: edit_totint
        function edit_totint_ValueChanged(app, event)
            DisableAll
            clear global
            set(app.pushbutton_ClearAppend,'Enable','off')                
        end

        % Value changed function: edit_outstep
        function edit_outstep_ValueChanged(app, event)
            DisableAll
            clear global
            set(app.pushbutton_ClearAppend,'Enable','off')                
        end

        % Button pushed function: pushbutton_Calculate
        function exec_Calculate(app, event)
            delete *.dat *.dmp *.tmp *.out *.aei
            DisableAll
            Items = get(app.popupmenu_planetFMI_int,'Items');
            item_int = str2num(get(app.popupmenu_planetFMI_int,'Value'));
            planetFMI_int = [Items{item_int},'.dat'];
            if strcmp(planetFMI_int,'Absent.dat')
                warndlg('2nd Planet absent. Please, change item.')
                return
            end
            
            Items = get(app.popupmenu_planetFMI_ext,'Items');
            item_ext = str2num(get(app.popupmenu_planetFMI_ext,'Value'));
            planetFMI_ext = [Items{item_ext},'.dat'];
            if strcmp(planetFMI_ext,'Absent.dat')
                warndlg('1st Planet absent. Please, change item.')
                return
            end
            
            planet = Items{item_int};
            if strcmp(planet,'Mercury')
                sma = str2num(get(app.edit_sma1,'Value'));
            elseif strcmp(planet,'Venus')
                sma = str2num(get(app.edit_sma2,'Value'));
            elseif strcmp(planet,'Earth-Moon')
                sma = str2num(get(app.edit_sma3,'Value'));
            elseif strcmp(planet,'Mars')
                sma = str2num(get(app.edit_sma4,'Value'));
            elseif strcmp(planet,'Asteroid')
                sma = str2num(get(app.edit_sma5,'Value'));
            elseif strcmp(planet,'Jupiter')
                sma = str2num(get(app.edit_sma6,'Value'));
            elseif strcmp(planet,'Saturn')
                sma = str2num(get(app.edit_sma7,'Value'));
            elseif strcmp(planet,'Uranus')
                sma = str2num(get(app.edit_sma8,'Value'));
            elseif strcmp(planet,'Neptune')
                sma = str2num(get(app.edit_sma9,'Value'));
            elseif strcmp(planet,'Pluto')
                sma = str2num(get(app.edit_sma10,'Value'));
            end
            global period_int
            period_int = sqrt(sma^3);
            
            planet = Items{item_ext};;
            if strcmp(planet,'Mercury')
                sma = str2num(get(app.edit_sma1,'Value'));
            elseif strcmp(planet,'Venus')
                sma = str2num(get(app.edit_sma2,'Value'));
            elseif strcmp(planet,'Earth-Moon')
                sma = str2num(get(app.edit_sma3,'Value'));
            elseif strcmp(planet,'Mars')
                sma = str2num(get(app.edit_sma4,'Value'));
            elseif strcmp(planet,'Asteroid')
                sma = str2num(get(app.edit_sma5,'Value'));
            elseif strcmp(planet,'Jupiter')
                sma = str2num(get(app.edit_sma6,'Value'));
            elseif strcmp(planet,'Saturn')
                sma = str2num(get(app.edit_sma7,'Value'));
            elseif strcmp(planet,'Uranus')
                sma = str2num(get(app.edit_sma8,'Value'));
            elseif strcmp(planet,'Neptune')
                sma = str2num(get(app.edit_sma9,'Value'));
            elseif strcmp(planet,'Pluto')
                sma = str2num(get(app.edit_sma10,'Value'));
            end
            global period_ext
            period_ext = sqrt(sma^3);
            
            global freq_ext_1 freq_ext_2 freq_ext_3 freq_int_1 freq_int_2 freq_int_3 FMI1 FMI2 FMI3
            delete *.dat
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Items = get(app.popupmenu_ODE,'Items');
            item = str2num(get(app.popupmenu_ODE,'Value'));
            Method = Items{item};
            display('                                                                 ')
            display('###################################################################')
            display(['    STARTING COMPUTATION with ' Method ' !!!'])
            disp('    ')
            tic
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            begin = datestr(now, 'dd mmm HH:MM');
           
            if ~isnan(findstr(app.LaplaceDir, 'Slave'))
                % do nothing
            else
                Totalbar = waitbar(0,'Please wait..., LAPLACE is calculating.',...
                    'Units', 'pixel', 'Position', [392 470 380 70]);
            end
            
            %---------------------- Start multicore -----------------------------------
            extfrom = get(app.edit_extfrom,'Value');
            extto = get(app.edit_extto,'Value');
            extstep = get(app.edit_extstep,'Value');
            extdelta = (extto - extfrom)/extstep;
            ext_tot = extstep + 1;                                         % Quindi ext_tot è il numero (numeric) delle colonne da calcolare
            parameterCell = cell(1,ext_tot);
            subcell = cell(1,4);
            ext_count = 1;
            ParalStructure
            %app = rmfield(app,'figure1');
            %app = rmfield(app,'output');
            for ext = extfrom : extdelta : extto
                subcell{1,1} = [ext];
                subcell{1,2} = [InitData];
                subcell{1,3} = [Totalbar];
                subcell{1,4} = [begin];
                parameterCell{1,ext_count} = {subcell};
                ext_count = ext_count + 1;
            end
            
            cd ..
            fid = fopen('WaitBar.txt','w');
            fwrite(fid,'0');
            fclose(fid);
            cd(app.LaplaceDir)
            
            warning('off')
            settings.multicoreDir = '../multicorefiles';
            settings.useWaitbar = false;
            settings.nrOfEvalsAtOnce = 1;
            resultCell = startmulticoremaster(@paralfor, parameterCell, settings);
            warning('on')
            %---------------------- End multicore -----------------------------------
            freq_ext_1 = [resultCell{1,1}(1,:)];
            for k = 2 : ext_tot
                freq_ext_1 = [freq_ext_1; resultCell{1,k}(1,:)];
            end
            freq_ext_2 = [resultCell{1,1}(2,:)];
            for k = 2 : ext_tot
                freq_ext_2 = [freq_ext_2; resultCell{1,k}(2,:)];
            end
            freq_ext_3 = [resultCell{1,1}(3,:)];
            for k = 2 : ext_tot
                freq_ext_3 = [freq_ext_3; resultCell{1,k}(3,:)];
            end
            freq_int_1 = [resultCell{1,1}(4,:)];
            for k = 2 : ext_tot
                freq_int_1 = [freq_int_1; resultCell{1,k}(4,:)];
            end
            freq_int_2 = [resultCell{1,1}(5,:)];
            for k = 2 : ext_tot
                freq_int_2 = [freq_int_2; resultCell{1,k}(5,:)];
            end
            freq_int_3 = [resultCell{1,1}(6,:)];
            for k = 2 : ext_tot
                freq_int_3 = [freq_int_3; resultCell{1,k}(6,:)];
            end
            FMI1 = [resultCell{1,1}(7,:)];
            for k = 2 : ext_tot
                FMI1 = [FMI1; resultCell{1,k}(7,:)];
            end
            FMI2 = [resultCell{1,1}(8,:)];
            for k = 2 : ext_tot
                FMI2 = [FMI2; resultCell{1,k}(8,:)];
            end
            FMI3 = [resultCell{1,1}(9,:)];
            for k = 2 : ext_tot
                FMI3 = [FMI3; resultCell{1,k}(9,:)];
            end
            
            freq_ext_1 = freq_ext_1/1000;
            freq_ext_2 = freq_ext_2/1000*360*60*60;
            freq_ext_3 = freq_ext_3/1000*360*60*60;
            freq_int_1 = freq_int_1/1000;
            freq_int_2 = freq_int_2/1000*360*60*60;
            freq_int_3 = freq_int_3/1000*360*60*60;
            
            %-------------------- Save to save_now ------------------------------------
            freq_ext_1 = freq_ext_1';
            freq_ext_2 = freq_ext_2';
            freq_ext_3 = freq_ext_3';
            freq_int_1 = freq_int_1';
            freq_int_2 = freq_int_2';
            freq_int_3 = freq_int_3';
            FMI1 = FMI1';
            FMI2 = FMI2';
            FMI3 = FMI3';
            save freq1st1.dat freq_ext_1 -ascii
            save freq1st2.dat freq_ext_2 -ascii
            save freq1st3.dat freq_ext_3 -ascii
            save freq2nd1.dat freq_int_1 -ascii
            save freq2nd2.dat freq_int_2 -ascii
            save freq2nd3.dat freq_int_3 -ascii
            save FMI1.dat FMI1 -ascii
            save FMI2.dat FMI2 -ascii
            save FMI3.dat FMI3 -ascii
            freq_ext_1 = freq_ext_1';
            freq_ext_2 = freq_ext_2';
            freq_ext_3 = freq_ext_3';
            freq_int_1 = freq_int_1';
            freq_int_2 = freq_int_2';
            freq_int_3 = freq_int_3';
            FMI1 = FMI1';
            FMI2 = FMI2';
            FMI3 = FMI3';
            delete *.txt
            %--------------------------------------------------------------------------
            if get(app.check1st1,'Value')|get(app.check1st2,'Value')|get(app.check1st3,'Value')...
                    |get(app.check2nd1,'Value')|get(app.check2nd2,'Value')|get(app.check2nd3,'Value')
                set(app.pushbutton_Frequencies, 'Enable', 'on')
                intstep = get(app.edit_intstep,'Value');
                if intstep ~= 0
                    set(app.pushbutton_LevelLines, 'Enable', 'on')
                end
            end
            
            if strcmp(num2str(get(app.edit_stepFMI1,'Value')),'0') & strcmp(num2str(get(app.edit_stepFMI2,'Value')),'0') & strcmp(num2str(get(app.edit_stepFMI3,'Value')),'0')
                %do nothing
            else
                set(app.pushbuttonFMI, 'Enable', 'on')
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%(get(num2str
            display('-------------------------------------------------------------------')
            display('                       FINISH !!!')
            seconds = toc;
            minutes = 0;
            hours = 0;
            days = 0;
            if seconds >= 60
                minutes = floor(seconds/60);
                seconds = seconds - 60*minutes;
                if minutes >= 60
                    hours = floor(minutes/60);
                    minutes = minutes - 60*hours;
                    if hours >= 24;
                        days = floor(hours/24);
                        hours = hours - 24*days;
                    end
                end
            end
            disp(['Elapsed time: ' num2str(days) ' days, ' num2str(hours) ' hours, '  num2str(minutes) ' minutes, ' num2str(seconds) ' seconds.'])
            display('###################################################################')            
        end

        % Button pushed function: pushbutton_Slave
        function exec_StartSlave(app, event)
        list = strread(cd, '%s','delimiter',strrep(filesep,'\','\\'));             %#ok<*DSTRRD> 
        dim = size(list);
        Slave = list{dim(1,1), dim(1,2)};
        set(app.pushbutton_Slave, 'Enable', 'off', 'Text', [Slave,' is running'], ...
            'FontSize', 16, 'Icon', 'Runner.jpg', 'Position', [12 44 211 72])
        startmulticoreslave('../multicorefiles')            
        end

        % Value changed function: edit_extfrom
        function edit_extfrom_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_extto
        function edit_extto_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_extstep
        function edit_extstep_ValueChanged(app, event)
            if get(app.edit_extstep,'Value') < 1
                warndlg('The entry must be a positive integer','Invalid entry')
                set(app.edit_extstep,'Value',1);
            end
            DisableAll
        end

        % Value changed function: edit_intfrom
        function edit_intfrom_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_intto
        function edit_intto_ValueChanged(app, event)
            DisableAll
        end

        % Value changed function: edit_intstep
        function edit_intstep_ValueChanged(app, event)
            if get(app.edit_intstep,'Value') == 0
                set(app.leqLabel_3,'Text','=') 
                set(app.leqLabel_4,'Enable','off')
                set(app.edit_intto,'Enable','off')
            else
                set(app.leqLabel_3,'Text','$\leq$')
                set(app.leqLabel_4,'Enable','on')
                set(app.edit_intto,'Enable','on')
            end
            DisableAll
        end

        % Menu selected function: Save
        function Save_Menu(app, event)
            [filename, pathname] = uiputfile( ...
                {'*.mat', 'MAT-Files (*.mat)'; ...
                '*.*','All Files (*.*)'}, ...
                'Save Initial Data','Init/');
            if isequal([filename,pathname],[0,0])
                return
            else
                InitStructure
                save([pathname,filename], '-struct', 'InitStr')
            end            
        end

        % Button pushed function: pushbuttonFMI
        function plot_FMI(app, event)
            global FMI1 FMI2 FMI3
            extfrom = get(app.edit_extfrom,'Value');
            extto = get(app.edit_extto,'Value');
            extstep = get(app.edit_extstep,'Value');
            extdelta = (extto - extfrom)/extstep;
            intfrom = get(app.edit_intfrom,'Value');
            intto = get(app.edit_intto,'Value');
            intstep = get(app.edit_intstep,'Value');
            intdelta = (intto - intfrom)/intstep;
            ext = extfrom : extdelta : extto;
            int = intfrom : intdelta : intto;
            Items = get(app.popupmenu_action_ext,'Items');
            item = str2num(get(app.popupmenu_action_ext,'Value'));
            action_ext = Items{item};
            Items = get(app.popupmenu_action_int,'Items');
            item = str2num(get(app.popupmenu_action_int,'Value'));
            action_int = Items{item};
            Items = get(app.popupmenu_planetFMI_int,'Items');
            item_int = str2num(get(app.popupmenu_planetFMI_int,'Value'));
            planetFMI_int = Items{item_int};
            Items = get(app.popupmenu_planetFMI_ext,'Items');
            item_ext = str2num(get(app.popupmenu_planetFMI_ext,'Value'));
            planetFMI_ext = Items{item_ext};
            
            if intstep > 0
                if get(app.edit_stepFMI1,'Value') > 0;
                    figure
                    colormap(jet)
                    surf(ext, int, FMI1', 'FaceColor', 'interp', 'LineStyle', 'None');
                    view(2)
                    xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                    title('FMI 1', 'FontSize',14)
                end
            
                if get(app.edit_stepFMI2,'Value') > 0;
                    figure
                    colormap(jet)
                    surf(ext, int, FMI2', 'FaceColor', 'interp', 'LineStyle', 'None');
                    view(2)
                    xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                    title('FMI 2', 'FontSize',14)
                end
            
                if get(app.edit_stepFMI3,'Value') > 0;
                    figure
                    colormap(jet)
                    surf(ext, int, FMI3', 'FaceColor', 'interp', 'LineStyle', 'None');
                    view(2)
                    xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                    title('FMI 3', 'FontSize',14)
                end
            elseif intstep == 0
                if get(app.edit_stepFMI1,'Value') > 0;
                    figure
                    plot(ext, FMI1(:,:))
                    title('FMI 1', 'FontSize',12)
                    xlabel([action_ext,' - ',planetFMI_ext], 'FontSize',14)
                end
            
                if get(app.edit_stepFMI2,'Value') > 0;
                    figure
                    plot(ext, FMI2(:,:))
                    title('FMI 2', 'FontSize',12)
                    xlabel([action_ext,' - ',planetFMI_ext], 'FontSize',14)
                end
            
                if get(app.edit_stepFMI3,'Value') > 0;
                    figure
                    plot(ext, FMI3(:,:))
                    title('FMI 3', 'FontSize',12)
                    xlabel([action_ext,' - ',planetFMI_ext], 'FontSize',14)
                end
            end            
        end

        % Button pushed function: pushbutton_Frequencies
        function plot_Frequencies(app, event)
           global freq_ext_1 freq_ext_2 freq_ext_3 freq_int_1 freq_int_2 freq_int_3 period_ext period_int
            extfrom = get(app.edit_extfrom,'Value');
            extto = get(app.edit_extto,'Value');
            extstep = get(app.edit_extstep,'Value');
            extdelta = (extto - extfrom)/extstep;
            intfrom = get(app.edit_intfrom,'Value');
            intto = get(app.edit_intto,'Value');
            intstep = get(app.edit_intstep,'Value');
            intdelta = (intto - intfrom)/intstep;
            ext = extfrom : extdelta : extto;
            int = intfrom : intdelta : intto;
            Items = get(app.popupmenu_action_ext,'Items');
            item = str2num(get(app.popupmenu_action_ext,'Value'));
            action_ext = Items{item};
            Items = get(app.popupmenu_action_int,'Items');
            item = str2num(get(app.popupmenu_action_int,'Value'));
            action_int = Items{item};
            Items = get(app.popupmenu_planetFMI_int,'Items');
            item_int = str2num(get(app.popupmenu_planetFMI_int,'Value'));
            planetFMI_int = Items{item_int};
            Items = get(app.popupmenu_planetFMI_ext,'Items');
            item_ext = str2num(get(app.popupmenu_planetFMI_ext,'Value'));
            planetFMI_ext = Items{item_ext};

            if intstep > 0
                if get(app.check1st1,'Value')
                    if get(app.edit_outstep,'Value') < 1/2*period_ext;
                        figure
                        colormap(gray)
                        surfc(ext, int, freq_ext_1', 'FaceColor', 'interp', 'LineStyle', ':');
                        xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                        title(['Freq 1 (rev/yr) of ',planetFMI_ext], 'FontSize',14)
                    else
                        warndlg(['Output step > ', num2str(1/2*period_ext), '.  The values relative to Frequency 1 will not be displayed (Nyquist-Shannon theorem)'], 'Output step too large');
                    end
                end
            
                if get(app.check1st2,'Value')
                    figure
                    colormap(gray)
                    surfc(ext, int, freq_ext_2', 'FaceColor', 'interp', 'LineStyle', ':');
                    xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                    title(['Freq 2 (arcsec/yr) of ',planetFMI_ext], 'FontSize',14)
                end
            
                if get(app.check1st3,'Value')
                    figure
                    colormap(gray)
                    surfc(ext, int, freq_ext_3', 'FaceColor', 'interp', 'LineStyle', ':');
                    xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                    title(['Freq 3 (arcsec/yr) of ',planetFMI_ext], 'FontSize',14)
                end
            
                if get(app.check2nd1,'Value')
                    if get(app.edit_outstep,'Value') < 1/2*period_int;
                        figure
                        colormap(gray)
                        surfc(ext, int, freq_int_1', 'FaceColor', 'interp', 'LineStyle', ':');
                        xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                        title(['Freq 1 (rev/yr) of ',planetFMI_int], 'FontSize',14)
                    else
                        warndlg(['Output step > ', num2str(1/2*period_int), '.  The values relative to Frequency 1 will not be displayed (Nyquist-Shannon theorem)'], 'Output step too large');
                    end
                end
            
                if get(app.check2nd2,'Value')
                    figure
                    colormap(gray)
                    surfc(ext, int, freq_int_2', 'FaceColor', 'interp', 'LineStyle', ':');
                    xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                    title(['Freq 2 (arcsec/yr) of ',planetFMI_int], 'FontSize',14)
                end
            
                if get(app.check2nd3,'Value')
                    figure
                    colormap(gray)
                    surfc(ext, int, freq_int_3', 'FaceColor', 'interp', 'LineStyle', ':');
                    xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                    title(['Freq 3 (arcsec/yr) of ',planetFMI_int], 'FontSize',14)
                end
            
            elseif intstep == 0
                if get(app.check1st1,'Value')
                    if get(app.edit_outstep,'Value') < 1/2*period_ext;
                        figure
                        plot(ext, freq_ext_1(:,:))
                    else
                        warndlg(['Output step > ', num2str(1/2*period_ext), '.  The values relative to Frequency 1 will not be displayed (Nyquist-Shannon theorem)'], 'Output step too large');
                    end
                    title(['Freq 1 (rev/yr) of ',planetFMI_ext],'FontSize',12)
                    xlabel([action_ext,' - ',planetFMI_ext], 'FontSize',14)
                end
            
                if get(app.check1st2,'Value')
                    figure
                    plot(ext, freq_ext_2(:,:))
                    title(['Freq 2 (arcsec/yr) of ',planetFMI_ext],'FontSize',12)
                    xlabel([action_ext,' - ',planetFMI_ext], 'FontSize',14)
                end
            
                if get(app.check1st3,'Value')
                    figure
                    plot(ext, freq_ext_3(:,:))
                    title(['Freq 3 (arcsec/yr) of ',planetFMI_ext],'FontSize',12)
                    xlabel([action_ext,' - ',planetFMI_ext], 'FontSize',14)
                end
            
                if get(app.check2nd1,'Value')
                    if get(app.edit_outstep,'Value') < 1/2*period_int;
                        figure
                        plot(ext, freq_int_1(:,:))
                    else
                        warndlg(['Output step > ', num2str(1/2*period_int), '.  The values relative to Frequency 1 will not be displayed (Nyquist-Shannon theorem)'], 'Output step too large');
                    end
                    title(['Freq 1(rev/yr) of ',planetFMI_int],'FontSize',12)
                    xlabel([action_ext,' - ',planetFMI_ext], 'FontSize',14)
                end
            
                if get(app.check2nd2,'Value')
                    figure
                    plot(ext, freq_int_2(:,:))
                    title(['Freq 2 (arcsec/yr) of ',planetFMI_int],'FontSize',12)
                    xlabel([action_ext,' - ',planetFMI_ext], 'FontSize',14)
                end
            
                if get(app.check2nd3,'Value')
                    figure
                    plot(ext, freq_int_3(:,:))
                    title(['Freq 3 (arcsec/yr) of ',planetFMI_int],'FontSize',12)
                    xlabel([action_ext,' - ',planetFMI_ext], 'FontSize',14)
                end
            end            
        end

        % Button pushed function: pushbutton_LevelLines
        function plot_Levels(app, event)
            global freq_ext_1 freq_ext_2 freq_ext_3 freq_int_1 freq_int_2 freq_int_3 period_ext period_int
            extfrom = get(app.edit_extfrom,'Value');
            extto = get(app.edit_extto,'Value');
            extstep = get(app.edit_extstep,'Value');
            extdelta = (extto - extfrom)/extstep;
            intfrom = get(app.edit_intfrom,'Value');
            intto = get(app.edit_intto,'Value');
            intstep = get(app.edit_intstep,'Value');
            intdelta = (intto - intfrom)/intstep;
            ext = extfrom : extdelta : extto;
            int = intfrom : intdelta : intto;
            Items = get(app.popupmenu_action_ext,'Items');
            item = str2num(get(app.popupmenu_action_ext,'Value'));
            action_ext = Items{item};
            Items = get(app.popupmenu_action_int,'Items');
            item = str2num(get(app.popupmenu_action_int,'Value'));
            action_int = Items{item};
            Items = get(app.popupmenu_planetFMI_int,'Items');
            item_int = str2num(get(app.popupmenu_planetFMI_int,'Value'));
            planetFMI_int = Items{item_int};
            Items = get(app.popupmenu_planetFMI_ext,'Items');
            item_ext = str2num(get(app.popupmenu_planetFMI_ext,'Value'));
            planetFMI_ext = Items{item_ext};
            
            if get(app.check1st1,'Value')
                if get(app.edit_outstep,'Value') < 1/2*period_ext;
                    figure
                    colormap(gray)
                    [C,h] = contour(ext, int, freq_ext_1');
                    clabel(C,h);
                    xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                    title(['Freq 1 (rev/yr) of ',planetFMI_ext], 'FontSize',14)
                else
                    warndlg(['Output step > ', num2str(1/2*period_ext), '.  The values relative to Frequency 1 will not be displayed (Nyquist-Shannon theorem)'], 'Output step too large');
                end
            end
            
            if get(app.check1st2,'Value')
                figure
                colormap(gray)
                [C,h] = contour(ext, int, freq_ext_2');
                clabel(C,h);
                xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                title(['Freq 2 (arcsec/yr) of ',planetFMI_ext], 'FontSize',14)
            end
            
            if get(app.check1st3,'Value')
                figure
                colormap(gray)
                [C,h] = contour(ext, int, freq_ext_3');
                clabel(C,h);
                xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                title(['Freq 3 (arcsec/yr) of ',planetFMI_ext], 'FontSize',14)
            end
            
            if get(app.check2nd1,'Value')
                if get(app.edit_outstep,'Value') < 1/2*period_int;
                    figure
                    colormap(gray)
                    [C,h] = contour(ext, int, freq_int_1');
                    clabel(C,h);
                    xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                    title(['Freq 1 (rev/yr) of ',planetFMI_int], 'FontSize',14)
                else
                    warndlg(['Output step > ', num2str(1/2*period_int), '.  The values relative to Frequency 1 will not be displayed (Nyquist-Shannon theorem)'], 'Output step too large');
                end
            end
            
            if get(app.check2nd2,'Value')
                figure
                colormap(gray)
                [C,h] = contour(ext, int, freq_int_2');
                clabel(C,h);
                xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                title(['Freq 2 (arcsec/yr) of ',planetFMI_int], 'FontSize',14)
            end
            
            if get(app.check2nd3,'Value')
                figure
                colormap(gray)
                [C,h] = contour(ext, int, freq_int_3');
                clabel(C,h);
                xlabel([action_ext,' - ',planetFMI_ext],'FontSize',14), ylabel([action_int,' - ',planetFMI_int], 'FontSize',14)
                title(['Freq 3 (arcsec/yr) of ',planetFMI_int], 'FontSize',14)
            end            
        end

        % Menu selected function: Save_now
        function Save_now_Menu(app, event)
            InitStructure
            cd(app.LaplaceDir)
            cd ..
            save('Setting.mat', '-struct', 'InitStr')
            cd(app.LaplaceDir)            
        end

        % Menu selected function: Save_exit
        function Save_exit_Menu(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end            
        end

        % Menu selected function: Load
        function Load_Menu(app, event)
            [filename, pathname] = uigetfile( ...
                {'*.mat', 'All MAT-Files (*.mat)'; ...
                '*.*','All Files (*.*)'}, ...
                'Load Initial Data','Init/');
            if isequal([filename,pathname],[0,0])
                return
            else
                [pathstr, name, ext] = fileparts(filename);
                cd(pathname)
                load([name,'.mat'])
                cd(app.LaplaceDir)
            end
            
            SetInit
            DisableAll
            if get(app.edit_intstep,'Value') == 0
                set(app.edit_intto,'Enable','off')
            else
                set(app.edit_intto,'Enable','on')
            end            
        end

        % Menu selected function: verbose
        function verbose_Menu(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end            
        end

        % Menu selected function: Percentage_of_Column
        function Percentage_of_ColumnMenuSelected(app, event)
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end             
        end

        % Menu selected function: Column_Completed
        function Column_Completed_MenuSelected(app, event)
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end             
        end

        % Menu selected function: Total_bars
        function Total_bars_MenuSelected(app, event)
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end             
        end

        % Menu selected function: Wait_bars
        function Wait_bars_MenuSelected(app, event)
if strcmp(get(gcbo, 'Checked'),'on')
    set(gcbo, 'Checked','off');
else
    set(gcbo, 'Checked','on')
end             
        end

        % Callback function: CloseFig, uipanel20
        function CloseFigure_Menu(app, event)
            close all            
        end

        % Menu selected function: no_degree
        function no_degree_Menu(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end
        end

        % Menu selected function: Rectify
        function Rectify_Menu(app, event)
            if strcmp(get(gcbo, 'Checked'),'on')
                set(gcbo, 'Checked','off');
            else
                set(gcbo, 'Checked','on');
            end            
        end

        % Menu selected function: Global3body
        function Global3body_Menu(app, event)
            ThreeBodyPlanar            
        end

        % Menu selected function: La_La
        function LaLa_Menu(app, event)
            LaLa
        end

        % Menu selected function: HelpMenu
        function Help_Menu(app, event)
            popupmessage('Help.txt','Help for the impatient')
        end

        % Menu selected function: About
        function About_Menu(app, event)
            About_LAPLACE
        end

        % Callback function: LAPLACEUIFigure, closeall
        function LAPLACE_closing(app, event)
if ~isnan(findstr(app.LaplaceDir, 'Slave'))
    %do nothing
else
    if strcmp(get(app.Save_exit, 'Checked'),'on')    
        InitStructure
        cd(app.LaplaceDir);
        cd ..        
        save('Setting.mat', '-struct', 'InitStr')
        cd(app.LaplaceDir);
    end
end
delete('*.dat')
delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LAPLACEUIFigure = uifigure('Visible', 'off');
            app.LAPLACEUIFigure.Name = 'LAPLACE';
            app.LAPLACEUIFigure.Icon = 'Laplace.jpg';
            app.LAPLACEUIFigure.CloseRequestFcn = createCallbackFcn(app, @LAPLACE_closing, true);
            app.File = uimenu(app.LAPLACEUIFigure);
            app.File.Text = '  File';
            app.File.Tag = 'File';
            app.Load = uimenu(app.File);
            app.Load.MenuSelectedFcn = createCallbackFcn(app, @Load_Menu, true);
            app.Load.Text = 'Load new initial data';
            app.Load.Tag = 'Load';
            app.Save = uimenu(app.File);
            app.Save.MenuSelectedFcn = createCallbackFcn(app, @Save_Menu, true);
            app.Save.Text = 'Save current initial data';
            app.Save.Tag = 'Save';
            app.closeall = uimenu(app.File);
            app.closeall.MenuSelectedFcn = createCallbackFcn(app, @LAPLACE_closing, true);
            app.closeall.Text = 'Close LAPLACE';
            app.closeall.Tag = 'closeall';
            app.Save_exit = uimenu(app.File);
            app.Save_exit.MenuSelectedFcn = createCallbackFcn(app, @Save_exit_Menu, true);
            app.Save_exit.Separator = 'on';
            app.Save_exit.Checked = 'on';
            app.Save_exit.Text = 'Save setting on exit';
            app.Save_exit.Tag = 'Save_exit';
            app.Save_now = uimenu(app.File);
            app.Save_now.MenuSelectedFcn = createCallbackFcn(app, @Save_now_Menu, true);
            app.Save_now.Text = 'Save setting now';
            app.Save_now.Tag = 'Save_now';
            app.Window = uimenu(app.LAPLACEUIFigure);
            app.Window.Text = '   Window';
            app.Window.Tag = 'Window';
            app.CloseFig = uimenu(app.Window);
            app.CloseFig.MenuSelectedFcn = createCallbackFcn(app, @CloseFigure_Menu, true);
            app.CloseFig.Text = 'Close all figures';
            app.CloseFig.Tag = 'CloseFig';
            app.verbose = uimenu(app.Window);
            app.verbose.MenuSelectedFcn = createCallbackFcn(app, @verbose_Menu, true);
            app.verbose.Text = 'Verbose';
            app.verbose.Tag = 'verbose';
            app.View = uimenu(app.LAPLACEUIFigure);
            app.View.Text = '   View';
            app.Percentage_of_Column = uimenu(app.View);
            app.Percentage_of_Column.MenuSelectedFcn = createCallbackFcn(app, @Percentage_of_ColumnMenuSelected, true);
            app.Percentage_of_Column.Checked = 'on';
            app.Percentage_of_Column.Text = 'Percentage of the Column';
            app.Column_Completed = uimenu(app.View);
            app.Column_Completed.MenuSelectedFcn = createCallbackFcn(app, @Column_Completed_MenuSelected, true);
            app.Column_Completed.Checked = 'on';
            app.Column_Completed.Text = 'Columns Completed';
            app.Total_bars = uimenu(app.View);
            app.Total_bars.MenuSelectedFcn = createCallbackFcn(app, @Total_bars_MenuSelected, true);
            app.Total_bars.Checked = 'on';
            app.Total_bars.Text = 'Total progress';
            app.Wait_bars = uimenu(app.View);
            app.Wait_bars.MenuSelectedFcn = createCallbackFcn(app, @Wait_bars_MenuSelected, true);
            app.Wait_bars.Checked = 'on';
            app.Wait_bars.Text = 'Total progress waitbar';
            app.Figure = uimenu(app.LAPLACEUIFigure);
            app.Figure.Text = '   Figure';
            app.Figure.Tag = 'Figure';
            app.no_degree = uimenu(app.Figure);
            app.no_degree.MenuSelectedFcn = createCallbackFcn(app, @no_degree_Menu, true);
            app.no_degree.Checked = 'on';
            app.no_degree.Text = 'No degree symbol';
            app.no_degree.Tag = 'no_degree';
            app.Rectify = uimenu(app.Figure);
            app.Rectify.MenuSelectedFcn = createCallbackFcn(app, @Rectify_Menu, true);
            app.Rectify.Text = 'Rectify angle';
            app.Rectify.Tag = 'Rectify';
            app.NormalForm = uimenu(app.LAPLACEUIFigure);
            app.NormalForm.Text = '   Normal Form';
            app.NormalForm.Tag = 'NormalForm';
            app.Global3body = uimenu(app.NormalForm);
            app.Global3body.MenuSelectedFcn = createCallbackFcn(app, @Global3body_Menu, true);
            app.Global3body.Text = 'Global planar secular 3-body ';
            app.Global3body.Tag = 'Global3body';
            app.La_La = uimenu(app.NormalForm);
            app.La_La.MenuSelectedFcn = createCallbackFcn(app, @LaLa_Menu, true);
            app.La_La.Text = 'Lagrange-Laplace theory';
            app.La_La.Tag = 'La_La';
            app.Help = uimenu(app.LAPLACEUIFigure);
            app.Help.Text = '   Help';
            app.Help.Tag = 'Help';
            app.HelpMenu = uimenu(app.Help);
            app.HelpMenu.MenuSelectedFcn = createCallbackFcn(app, @Help_Menu, true);
            app.HelpMenu.Text = 'Help';
            app.About = uimenu(app.Help);
            app.About.MenuSelectedFcn = createCallbackFcn(app, @About_Menu, true);
            app.About.Text = 'About LAPLACE';
            app.About.Tag = 'About';
            app.uipanel20 = uipanel(app.LAPLACEUIFigure);
            app.uipanel20.Title = ' Check Resonances ';
            app.uipanel20.Visible = 'off';
            app.uipanel20.ButtonDownFcn = createCallbackFcn(app, @CloseFigure_Menu, true);
            app.uipanel20.Tag = 'uipanel20';
            app.popupmenu_R1 = uidropdown(app.uipanel20);
            app.popupmenu_R1.Items = {'mean longitude', 'longitude of pericenter', 'longitude of ascending node'};
            app.popupmenu_R1.ItemsData = {'1', '2', '3'};
            app.popupmenu_R1.Tag = 'popupmenu_R1';
            app.popupmenu_R1.Value = '1';
            app.popupmenu_R2 = uidropdown(app.uipanel20);
            app.popupmenu_R2.Items = {'mean longitude', 'longitude of pericenter', 'longitude of ascending node'};
            app.popupmenu_R2.ItemsData = {'1', '2', '3'};
            app.popupmenu_R2.Tag = 'popupmenu_R2';
            app.popupmenu_R2.Value = '1';
            app.popupmenu_R3 = uidropdown(app.uipanel20);
            app.popupmenu_R3.Items = {'mean longitude', 'longitude of pericenter', 'longitude of ascending node'};
            app.popupmenu_R3.ItemsData = {'1', '2', '3'};
            app.popupmenu_R3.Tag = 'popupmenu_R3';
            app.popupmenu_R3.Value = '1';
            app.popupmenu_R4 = uidropdown(app.uipanel20);
            app.popupmenu_R4.Items = {'mean longitude', 'longitude of pericenter', 'longitude of ascending node'};
            app.popupmenu_R4.ItemsData = {'1', '2', '3'};
            app.popupmenu_R4.Tag = 'popupmenu_R4';
            app.popupmenu_R4.Value = '1';
            app.popupmenu_R5 = uidropdown(app.uipanel20);
            app.popupmenu_R5.Items = {'mean longitude', 'longitude of pericenter', 'longitude of ascending node'};
            app.popupmenu_R5.ItemsData = {'1', '2', '3'};
            app.popupmenu_R5.Tag = 'popupmenu_R5';
            app.popupmenu_R5.Value = '1';
            app.popupmenu_R6 = uidropdown(app.uipanel20);
            app.popupmenu_R6.Items = {'mean longitude', 'longitude of pericenter', 'longitude of ascending node'};
            app.popupmenu_R6.ItemsData = {'1', '2', '3'};
            app.popupmenu_R6.Tag = 'popupmenu_R6';
            app.popupmenu_R6.Value = '1';
            app.popupmenu_R7 = uidropdown(app.uipanel20);
            app.popupmenu_R7.Items = {'mean longitude', 'longitude of pericenter', 'longitude of ascending node'};
            app.popupmenu_R7.ItemsData = {'1', '2', '3'};
            app.popupmenu_R7.Tag = 'popupmenu_R7';
            app.popupmenu_R7.Value = '1';
            app.popupmenu_R8 = uidropdown(app.uipanel20);
            app.popupmenu_R8.Items = {'mean longitude', 'longitude of pericenter', 'longitude of ascending node'};
            app.popupmenu_R8.ItemsData = {'1', '2', '3'};
            app.popupmenu_R8.Tag = 'popupmenu_R8';
            app.popupmenu_R8.Value = '1';
            app.popupmenu_R9 = uidropdown(app.uipanel20);
            app.popupmenu_R9.Items = {'mean longitude', 'longitude of pericenter', 'longitude of ascending node'};
            app.popupmenu_R9.ItemsData = {'1', '2', '3'};
            app.popupmenu_R9.Tag = 'popupmenu_R9';
            app.popupmenu_R9.Value = '1';
            app.popupmenu_P1 = uidropdown(app.uipanel20);
            app.popupmenu_P1.Items = {'Mercury', 'Venus', 'Earth - Moon', 'Mars', 'Asteroid', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.popupmenu_P1.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_P1.Tag = 'popupmenu_P1';
            app.popupmenu_P1.Value = '1';
            app.popupmenu_P2 = uidropdown(app.uipanel20);
            app.popupmenu_P2.Items = {'Mercury', 'Venus', 'Earth - Moon', 'Mars', 'Asteroid', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.popupmenu_P2.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_P2.Tag = 'popupmenu_P2';
            app.popupmenu_P2.Value = '2';
            app.popupmenu_P3 = uidropdown(app.uipanel20);
            app.popupmenu_P3.Items = {'Mercury', 'Venus', 'Earth - Moon', 'Mars', 'Asteroid', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.popupmenu_P3.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_P3.Tag = 'popupmenu_P3';
            app.popupmenu_P3.Value = '1';
            app.popupmenu_P4 = uidropdown(app.uipanel20);
            app.popupmenu_P4.Items = {'Mercury', 'Venus', 'Earth - Moon', 'Mars', 'Asteroid', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.popupmenu_P4.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_P4.Tag = 'popupmenu_P4';
            app.popupmenu_P4.Value = '1';
            app.popupmenu_P5 = uidropdown(app.uipanel20);
            app.popupmenu_P5.Items = {'Mercury', 'Venus', 'Earth - Moon', 'Mars', 'Asteroid', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.popupmenu_P5.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_P5.Tag = 'popupmenu_P5';
            app.popupmenu_P5.Value = '1';
            app.popupmenu_P6 = uidropdown(app.uipanel20);
            app.popupmenu_P6.Items = {'Mercury', 'Venus', 'Earth - Moon', 'Mars', 'Asteroid', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.popupmenu_P6.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_P6.Tag = 'popupmenu_P6';
            app.popupmenu_P6.Value = '1';
            app.popupmenu_P7 = uidropdown(app.uipanel20);
            app.popupmenu_P7.Items = {'Mercury', 'Venus', 'Earth - Moon', 'Mars', 'Asteroid', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.popupmenu_P7.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_P7.Tag = 'popupmenu_P7';
            app.popupmenu_P7.Value = '1';
            app.popupmenu_P8 = uidropdown(app.uipanel20);
            app.popupmenu_P8.Items = {'Mercury', 'Venus', 'Earth - Moon', 'Mars', 'Asteroid', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.popupmenu_P8.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_P8.Tag = 'popupmenu_P8';
            app.popupmenu_P8.Value = '1';
            app.popupmenu_P9 = uidropdown(app.uipanel20);
            app.popupmenu_P9.Items = {'Mercury', 'Venus', 'Earth - Moon', 'Mars', 'Asteroid', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.popupmenu_P9.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_P9.Tag = 'popupmenu_P9';
            app.popupmenu_P9.Value = '1';
            app.pushbutton_PlotRes = uibutton(app.uipanel20, 'push');
            app.pushbutton_PlotRes.ButtonPushedFcn = createCallbackFcn(app, @exec_PlotRes, true);
            app.pushbutton_PlotRes.Tag = 'pushbutton_PlotRes';
            app.pushbutton_PlotRes.Enable = 'off';
            app.pushbutton_PlotRes.Text = 'Plot';
            app.pushbutton_close = uibutton(app.uipanel20, 'push');
            app.pushbutton_close.ButtonPushedFcn = createCallbackFcn(app, @exec_close, true);
            app.pushbutton_close.Tag = 'pushbutton_close';
            app.pushbutton_close.Text = 'Close';
            app.edit_k1 = uieditfield(app.uipanel20, 'numeric');
            app.edit_k1.ValueDisplayFormat = '%1.15g';
            app.edit_k1.Value = 1;
            app.edit_k2 = uieditfield(app.uipanel20, 'numeric');
            app.edit_k2.ValueDisplayFormat = '%1.15g';
            app.edit_k2.Value = 1;
            app.edit_k3 = uieditfield(app.uipanel20, 'numeric');
            app.edit_k3.ValueDisplayFormat = '%1.15g';
            app.edit_k4 = uieditfield(app.uipanel20, 'numeric');
            app.edit_k4.ValueDisplayFormat = '%1.15g';
            app.edit_k5 = uieditfield(app.uipanel20, 'numeric');
            app.edit_k5.ValueDisplayFormat = '%1.15g';
            app.edit_k6 = uieditfield(app.uipanel20, 'numeric');
            app.edit_k6.ValueDisplayFormat = '%1.15g';
            app.edit_k7 = uieditfield(app.uipanel20, 'numeric');
            app.edit_k7.ValueDisplayFormat = '%1.15g';
            app.edit_k8 = uieditfield(app.uipanel20, 'numeric');
            app.edit_k8.ValueDisplayFormat = '%1.15g';
            app.edit_k9 = uieditfield(app.uipanel20, 'numeric');
            app.edit_k9.ValueDisplayFormat = '%1.15g';
            app.uipanel2 = uipanel(app.LAPLACEUIFigure);
            app.uipanel2.Title = ' Integration ';
            app.uipanel2.Tag = 'uipanel2';
            app.popupmenu_ODE = uidropdown(app.uipanel2);
            app.popupmenu_ODE.Items = {'Symplectic (Hnbody)', 'Bulirsch-Stoer (Hnbody)', 'Runge-Kutta (Hnbody)', 'Symplectic (Mercury)', 'Radau (Mercury)', 'Bulirsch-Stoer (Mercury)', 'Hybrid (Mercury)'};
            app.popupmenu_ODE.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.popupmenu_ODE.ValueChangedFcn = createCallbackFcn(app, @popupmenu_ODE_ValueChanged, true);
            app.popupmenu_ODE.Tag = 'popupmenu_ODE';
            app.popupmenu_ODE.Value = '1';
            app.text18 = uilabel(app.uipanel2);
            app.text18.Tag = 'text18';
            app.text18.Text = 'Method';
            app.text19 = uilabel(app.uipanel2);
            app.text19.Tag = 'text19';
            app.text19.Text = 'Integration step (yr)';
            app.text20 = uilabel(app.uipanel2);
            app.text20.Tag = 'text20';
            app.text20.Text = 'Total integration time (yr)';
            app.text21 = uilabel(app.uipanel2);
            app.text21.Tag = 'text21';
            app.text21.Text = 'Output step (yr)';
            app.pushbutton_Integrate = uibutton(app.uipanel2, 'push');
            app.pushbutton_Integrate.ButtonPushedFcn = createCallbackFcn(app, @exec_Integrate, true);
            app.pushbutton_Integrate.Tag = 'pushbutton_Integrate';
            app.pushbutton_Integrate.Text = 'Integrate';
            app.PostNewton = uicheckbox(app.uipanel2);
            app.PostNewton.ValueChangedFcn = createCallbackFcn(app, @PostNewton_ValueChanged, true);
            app.PostNewton.Tag = 'PostNewton';
            app.PostNewton.Text = 'Relativistic (post Newtonian) correction';
            app.edit_integstep = uieditfield(app.uipanel2, 'numeric');
            app.edit_integstep.ValueChangedFcn = createCallbackFcn(app, @edit_integstep_ValueChanged, true);
            app.edit_integstep.Value = 0.01;
            app.edit_totint = uieditfield(app.uipanel2, 'numeric');
            app.edit_totint.ValueChangedFcn = createCallbackFcn(app, @edit_totint_ValueChanged, true);
            app.edit_totint.Value = 100;
            app.edit_outstep = uieditfield(app.uipanel2, 'numeric');
            app.edit_outstep.ValueChangedFcn = createCallbackFcn(app, @edit_outstep_ValueChanged, true);
            app.edit_outstep.Value = 1;
            app.uipanel14 = uipanel(app.LAPLACEUIFigure);
            app.uipanel14.Title = ' Frequency Modulation Indicator ';
            app.uipanel14.Tag = 'uipanel14';
            app.uipanel4 = uipanel(app.uipanel14);
            app.uipanel4.Tag = 'uipanel4';
            app.text35 = uilabel(app.uipanel4);
            app.text35.Tag = 'text35';
            app.text35.Text = 'FMI frequency 1 :   Semimajor axis - Mean longitude';
            app.text36 = uilabel(app.uipanel4);
            app.text36.Tag = 'text36';
            app.text36.Text = {'Compute the first'; 'frequency between'};
            app.text37 = uilabel(app.uipanel4);
            app.text37.Tag = 'text37';
            app.text37.Text = 'and';
            app.text38 = uilabel(app.uipanel4);
            app.text38.Tag = 'text38';
            app.text38.Text = 'Step number for FMI';
            app.edit_minfreq1 = uieditfield(app.uipanel4, 'numeric');
            app.edit_minfreq1.ValueDisplayFormat = '%1.15g';
            app.edit_minfreq1.Value = 1e-07;
            app.edit_maxfreq1 = uieditfield(app.uipanel4, 'numeric');
            app.edit_maxfreq1.ValueDisplayFormat = '%1.15g';
            app.edit_maxfreq1.Value = 5;
            app.edit_stepFMI1 = uieditfield(app.uipanel4, 'numeric');
            app.edit_stepFMI1.ValueDisplayFormat = '%1.15g';
            app.edit_stepFMI1.Value = 5;
            app.uipanel5 = uipanel(app.uipanel14);
            app.uipanel5.Tag = 'uipanel5';
            app.text40 = uilabel(app.uipanel5);
            app.text40.Tag = 'text40';
            app.text40.Text = {'Compute the first'; 'frequency between'};
            app.text41 = uilabel(app.uipanel5);
            app.text41.Tag = 'text41';
            app.text41.Text = 'and';
            app.text39 = uilabel(app.uipanel5);
            app.text39.Tag = 'text39';
            app.text39.Text = 'FMI frequency 2 :   Eccentricity - Longitude perihelion';
            app.text42 = uilabel(app.uipanel5);
            app.text42.Tag = 'text42';
            app.text42.Text = 'Step number for FMI';
            app.edit_minfreq2 = uieditfield(app.uipanel5, 'numeric');
            app.edit_minfreq2.ValueDisplayFormat = '%1.15g';
            app.edit_minfreq2.Value = 1e-07;
            app.edit_maxfreq2 = uieditfield(app.uipanel5, 'numeric');
            app.edit_maxfreq2.ValueDisplayFormat = '%1.15g';
            app.edit_maxfreq2.Value = 2;
            app.edit_stepFMI2 = uieditfield(app.uipanel5, 'numeric');
            app.edit_stepFMI2.ValueDisplayFormat = '%1.15g';
            app.uipanel6 = uipanel(app.uipanel14);
            app.uipanel6.Tag = 'uipanel6';
            app.text44 = uilabel(app.uipanel6);
            app.text44.Tag = 'text44';
            app.text44.Text = {'Compute the first'; 'frequency between'};
            app.text45 = uilabel(app.uipanel6);
            app.text45.Tag = 'text45';
            app.text45.Text = 'and';
            app.text43 = uilabel(app.uipanel6);
            app.text43.Tag = 'text43';
            app.text43.Text = 'FMI frequency 3 :   Inclination - Long. ascending node';
            app.text46 = uilabel(app.uipanel6);
            app.text46.Tag = 'text46';
            app.text46.Text = 'Step number for FMI';
            app.edit_minfreq3 = uieditfield(app.uipanel6, 'numeric');
            app.edit_minfreq3.ValueDisplayFormat = '%1.15g';
            app.edit_minfreq3.Value = -2;
            app.edit_maxfreq3 = uieditfield(app.uipanel6, 'numeric');
            app.edit_maxfreq3.ValueDisplayFormat = '%1.15g';
            app.edit_maxfreq3.Value = -1e-07;
            app.edit_stepFMI3 = uieditfield(app.uipanel6, 'numeric');
            app.edit_stepFMI3.ValueDisplayFormat = '%1.15g';
            app.uipanel7 = uipanel(app.uipanel14);
            app.uipanel7.Title = '... and plot';
            app.uipanel7.Tag = 'uipanel7';
            app.pushbutton_Frequencies = uibutton(app.uipanel7, 'push');
            app.pushbutton_Frequencies.ButtonPushedFcn = createCallbackFcn(app, @plot_Frequencies, true);
            app.pushbutton_Frequencies.Tag = 'pushbutton_Frequencies';
            app.pushbutton_Frequencies.Enable = 'off';
            app.pushbutton_Frequencies.Text = 'Frequencies';
            app.pushbutton_LevelLines = uibutton(app.uipanel7, 'push');
            app.pushbutton_LevelLines.ButtonPushedFcn = createCallbackFcn(app, @plot_Levels, true);
            app.pushbutton_LevelLines.Tag = 'pushbutton_LevelLines';
            app.pushbutton_LevelLines.Enable = 'off';
            app.pushbutton_LevelLines.Text = 'Frequencies (level lines)';
            app.pushbuttonFMI = uibutton(app.uipanel7, 'push');
            app.pushbuttonFMI.ButtonPushedFcn = createCallbackFcn(app, @plot_FMI, true);
            app.pushbuttonFMI.Tag = 'pushbuttonFMI';
            app.pushbuttonFMI.Enable = 'off';
            app.pushbuttonFMI.Text = 'F M I';
            app.uipanel11 = uipanel(app.uipanel14);
            app.uipanel11.Tag = 'uipanel11';
            app.text31 = uilabel(app.uipanel11);
            app.text31.Tag = 'text31';
            app.text31.Text = 'step number ';
            app.popupmenu_planetFMI_ext = uidropdown(app.uipanel11);
            app.popupmenu_planetFMI_ext.Items = {'Mercury', 'Venus', 'Earth-Moon', 'Mars', 'Asteroid', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.popupmenu_planetFMI_ext.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_planetFMI_ext.Tag = 'popupmenu_planetFMI_ext';
            app.popupmenu_planetFMI_ext.Value = '1';
            app.popupmenu_action_ext = uidropdown(app.uipanel11);
            app.popupmenu_action_ext.Items = {'Semimajor axis', 'Eccentricity', 'Inclination'};
            app.popupmenu_action_ext.ItemsData = {'1', '2', '3'};
            app.popupmenu_action_ext.Tag = 'popupmenu_action_ext';
            app.popupmenu_action_ext.Value = '1';
            app.text30 = uilabel(app.uipanel11);
            app.text30.Tag = 'text30';
            app.text30.Text = 'with';
            app.text29 = uilabel(app.uipanel11);
            app.text29.Tag = 'text29';
            app.text29.Text = {'1st'; 'planet'};
            app.edit_extfrom = uieditfield(app.uipanel11, 'numeric');
            app.edit_extfrom.ValueDisplayFormat = '%1.15g';
            app.edit_extfrom.ValueChangedFcn = createCallbackFcn(app, @edit_extfrom_ValueChanged, true);
            app.edit_extfrom.Value = 0.3;
            app.edit_extto = uieditfield(app.uipanel11, 'numeric');
            app.edit_extto.ValueDisplayFormat = '%1.15g';
            app.edit_extto.ValueChangedFcn = createCallbackFcn(app, @edit_extto_ValueChanged, true);
            app.edit_extto.Value = 0.45;
            app.edit_extstep = uieditfield(app.uipanel11, 'numeric');
            app.edit_extstep.ValueDisplayFormat = '%1.15g';
            app.edit_extstep.ValueChangedFcn = createCallbackFcn(app, @edit_extstep_ValueChanged, true);
            app.edit_extstep.Value = 4;
            app.leqLabel = uilabel(app.uipanel11);
            app.leqLabel.Interpreter = 'latex';
            app.leqLabel.Text = '$\leq$';
            app.leqLabel_2 = uilabel(app.uipanel11);
            app.leqLabel_2.Interpreter = 'latex';
            app.leqLabel_2.Text = '$\leq$';
            app.uipanel10 = uipanel(app.uipanel14);
            app.uipanel10.Tag = 'uipanel10';
            app.popupmenu_planetFMI_int = uidropdown(app.uipanel10);
            app.popupmenu_planetFMI_int.Items = {'Mercury', 'Venus', 'Earth-Moon', 'Mars', 'Asteroid', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.popupmenu_planetFMI_int.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_planetFMI_int.Tag = 'popupmenu_planetFMI_int';
            app.popupmenu_planetFMI_int.Value = '6';
            app.popupmenu_action_int = uidropdown(app.uipanel10);
            app.popupmenu_action_int.Items = {'Semimajor axis', 'Eccentricity', 'Inclination'};
            app.popupmenu_action_int.ItemsData = {'1', '2', '3'};
            app.popupmenu_action_int.Tag = 'popupmenu_action_int';
            app.popupmenu_action_int.Value = '1';
            app.text33 = uilabel(app.uipanel10);
            app.text33.Tag = 'text33';
            app.text33.Text = 'with';
            app.text32 = uilabel(app.uipanel10);
            app.text32.Tag = 'text32';
            app.text32.Text = {'2nd'; 'planet'};
            app.edit_intfrom = uieditfield(app.uipanel10, 'numeric');
            app.edit_intfrom.ValueDisplayFormat = '%1.15g';
            app.edit_intfrom.ValueChangedFcn = createCallbackFcn(app, @edit_intfrom_ValueChanged, true);
            app.edit_intfrom.Value = 5;
            app.edit_intto = uieditfield(app.uipanel10, 'numeric');
            app.edit_intto.ValueDisplayFormat = '%1.15g';
            app.edit_intto.ValueChangedFcn = createCallbackFcn(app, @edit_intto_ValueChanged, true);
            app.edit_intto.Value = 5.4;
            app.edit_intstep = uieditfield(app.uipanel10, 'numeric');
            app.edit_intstep.ValueDisplayFormat = '%1.15g';
            app.edit_intstep.ValueChangedFcn = createCallbackFcn(app, @edit_intstep_ValueChanged, true);
            app.edit_intstep.Value = 4;
            app.leqLabel_3 = uilabel(app.uipanel10);
            app.leqLabel_3.Interpreter = 'latex';
            app.leqLabel_3.Text = '$\leq$';
            app.leqLabel_4 = uilabel(app.uipanel10);
            app.leqLabel_4.Interpreter = 'latex';
            app.leqLabel_4.Text = '$\leq$';
            app.text31_2 = uilabel(app.uipanel10);
            app.text31_2.Tag = 'text31';
            app.text31_2.Text = 'step number ';
            app.pushbutton_Calculate = uibutton(app.uipanel14, 'push');
            app.pushbutton_Calculate.ButtonPushedFcn = createCallbackFcn(app, @exec_Calculate, true);
            app.pushbutton_Calculate.Tag = 'pushbutton_Calculate';
            app.pushbutton_Calculate.Text = {'Compute'; ''};
            app.uipanel22 = uipanel(app.uipanel14);
            app.uipanel22.Tag = 'uipanel22';
            app.check1st1 = uicheckbox(app.uipanel22);
            app.check1st1.Tag = 'check1st1';
            app.check1st1.Text = 'freq 1';
            app.check1st2 = uicheckbox(app.uipanel22);
            app.check1st2.Tag = 'check1st2';
            app.check1st2.Text = 'freq 2';
            app.check1st3 = uicheckbox(app.uipanel22);
            app.check1st3.Tag = 'check1st3';
            app.check1st3.Text = 'freq 3';
            app.check2nd1 = uicheckbox(app.uipanel22);
            app.check2nd1.Tag = 'check2nd1';
            app.check2nd1.Text = 'freq 1';
            app.check2nd2 = uicheckbox(app.uipanel22);
            app.check2nd2.Tag = 'check2nd2';
            app.check2nd2.Text = 'freq 2';
            app.check2nd3 = uicheckbox(app.uipanel22);
            app.check2nd3.Tag = 'check2nd3';
            app.check2nd3.Text = 'freq 3';
            app.text53 = uilabel(app.uipanel22);
            app.text53.Tag = 'text53';
            app.text53.Text = {'1st planet'; '2nd planet'};
            app.text54 = uilabel(app.uipanel22);
            app.text54.Tag = 'text54';
            app.text54.Text = 'Frequencies for';
            app.pushbutton_Slave = uibutton(app.uipanel14, 'push');
            app.pushbutton_Slave.ButtonPushedFcn = createCallbackFcn(app, @exec_StartSlave, true);
            app.pushbutton_Slave.Tag = 'pushbutton_StartSlave';
            app.pushbutton_Slave.Visible = 'off';
            app.pushbutton_Slave.Text = {'Start'; 'Slave'};
            app.uipanel15 = uipanel(app.LAPLACEUIFigure);
            app.uipanel15.Title = ' Plot , Frequency Analysis, and Lyapunov ';
            app.uipanel15.Tag = 'uipanel15';
            app.pushbutton_StopLFA = uibutton(app.uipanel15, 'push');
            app.pushbutton_StopLFA.ButtonPushedFcn = createCallbackFcn(app, @exec_StopLFA, true);
            app.pushbutton_StopLFA.Tag = 'pushbutton_StopLFA';
            app.pushbutton_StopLFA.Enable = 'off';
            app.pushbutton_StopLFA.Visible = 'off';
            app.pushbutton_StopLFA.Text = 'Stop Demodulation';
            app.pushbutton_StartLFA = uibutton(app.uipanel15, 'push');
            app.pushbutton_StartLFA.ButtonPushedFcn = createCallbackFcn(app, @exec_StartLFA, true);
            app.pushbutton_StartLFA.Tag = 'pushbutton_StartLFA';
            app.pushbutton_StartLFA.Enable = 'off';
            app.pushbutton_StartLFA.Text = 'Start Demodulation';
            app.popupmenu_element = uidropdown(app.uipanel15);
            app.popupmenu_element.Items = {'Semimajor axis', 'Eccentricity', 'Inclination', 'Mean anomaly', 'Mean longitude', 'Argument of pericenter', 'Longitude of pericenter', 'Longitude of ascending node'};
            app.popupmenu_element.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.popupmenu_element.Tag = 'popupmenu_element';
            app.popupmenu_element.Enable = 'off';
            app.popupmenu_element.Value = '1';
            app.popupmenu_planet = uidropdown(app.uipanel15);
            app.popupmenu_planet.Items = {'Mercury', 'Venus', 'Earth-Moon', 'Mars', 'Asteroid', 'Jupiter', 'Saturn', 'Uranus', 'Neptune', 'Pluto'};
            app.popupmenu_planet.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10'};
            app.popupmenu_planet.Tag = 'popupmenu_planet';
            app.popupmenu_planet.Enable = 'off';
            app.popupmenu_planet.Value = '1';
            app.text22 = uilabel(app.uipanel15);
            app.text22.Tag = 'text22';
            app.text22.Text = 'of';
            app.checkboxUser = uicheckbox(app.uipanel15);
            app.checkboxUser.ValueChangedFcn = createCallbackFcn(app, @checkboxUserValueChanged, true);
            app.checkboxUser.Tag = 'checkboxUser';
            app.checkboxUser.Enable = 'off';
            app.checkboxUser.Text = 'User function';
            app.text23 = uilabel(app.uipanel15);
            app.text23.Tag = 'text23';
            app.text23.Text = 'or';
            app.uipanel16 = uipanel(app.uipanel15);
            app.uipanel16.Tag = 'uipanel16';
            app.text24 = uilabel(app.uipanel16);
            app.text24.Tag = 'text24';
            app.text24.Text = 'Compute the first';
            app.text25 = uilabel(app.uipanel16);
            app.text25.Tag = 'text25';
            app.text25.Text = 'frequencies';
            app.text26 = uilabel(app.uipanel16);
            app.text26.Tag = 'text26';
            app.text26.Text = 'between freq.';
            app.text27 = uilabel(app.uipanel16);
            app.text27.Tag = 'text27';
            app.text27.Text = 'and freq.';
            app.text28 = uilabel(app.uipanel16);
            app.text28.Tag = 'text28';
            app.text28.Text = 'with method';
            app.popupmenu_FMFT_flag = uidropdown(app.uipanel16);
            app.popupmenu_FMFT_flag.Items = {' FMFT base', ' FMFT linear correction', ' FMFT nonlinear correction'};
            app.popupmenu_FMFT_flag.ItemsData = {'1', '2', '3'};
            app.popupmenu_FMFT_flag.Tag = 'popupmenu_FMFT_flag';
            app.popupmenu_FMFT_flag.Value = '1';
            app.pushbutton_FMFT = uibutton(app.uipanel16, 'push');
            app.pushbutton_FMFT.ButtonPushedFcn = createCallbackFcn(app, @exec_FMFT, true);
            app.pushbutton_FMFT.Tag = 'pushbutton_FMFT';
            app.pushbutton_FMFT.Enable = 'off';
            app.pushbutton_FMFT.Text = 'FMFT';
            app.edit_nfreq = uieditfield(app.uipanel16, 'numeric');
            app.edit_nfreq.Value = 8;
            app.edit_fromfreq = uieditfield(app.uipanel16, 'numeric');
            app.edit_fromfreq.Value = -5;
            app.edit_tofreq = uieditfield(app.uipanel16, 'numeric');
            app.edit_tofreq.Value = 5;
            app.uipanel17 = uipanel(app.uipanel15);
            app.uipanel17.Tag = 'uipanel17';
            app.check_log = uicheckbox(app.uipanel17);
            app.check_log.Tag = 'check_log';
            app.check_log.Text = 'log scale';
            app.check_log.Value = true;
            app.pushbutton_FFT = uibutton(app.uipanel17, 'push');
            app.pushbutton_FFT.ButtonPushedFcn = createCallbackFcn(app, @exec_FFT, true);
            app.pushbutton_FFT.Tag = 'pushbutton_FFT';
            app.pushbutton_FFT.Enable = 'off';
            app.pushbutton_FFT.Text = 'Fast Fourier Transform';
            app.uipanel19 = uipanel(app.uipanel15);
            app.uipanel19.Tag = 'uipanel19';
            app.pushbutton_Wavelet = uibutton(app.uipanel19, 'push');
            app.pushbutton_Wavelet.ButtonPushedFcn = createCallbackFcn(app, @exec_Wavelet, true);
            app.pushbutton_Wavelet.Tag = 'pushbutton_Wavelet';
            app.pushbutton_Wavelet.Enable = 'off';
            app.pushbutton_Wavelet.Text = {'Wavelet'; 'Transform'; ''};
            app.text50 = uilabel(app.uipanel19);
            app.text50.Tag = 'text50';
            app.text50.Text = 'Between freq.';
            app.text51 = uilabel(app.uipanel19);
            app.text51.Tag = 'text51';
            app.text51.Text = 'and freq.';
            app.text52 = uilabel(app.uipanel19);
            app.text52.Tag = 'text52';
            app.text52.Text = 'Variance';
            app.edit_min_freq = uieditfield(app.uipanel19, 'numeric');
            app.edit_min_freq.ValueDisplayFormat = '%1.e';
            app.edit_min_freq.Value = 0.001;
            app.edit_max_freq = uieditfield(app.uipanel19, 'numeric');
            app.edit_max_freq.Value = 5;
            app.edit_Variance = uieditfield(app.uipanel19, 'numeric');
            app.edit_Variance.Value = 10;
            app.pushbutton_Plot = uibutton(app.uipanel15, 'push');
            app.pushbutton_Plot.ButtonPushedFcn = createCallbackFcn(app, @exec_plot, true);
            app.pushbutton_Plot.Tag = 'pushbutton_Plot';
            app.pushbutton_Plot.Enable = 'off';
            app.pushbutton_Plot.Text = 'Plot';
            app.pushbutton_ClearAppend = uibutton(app.uipanel15, 'push');
            app.pushbutton_ClearAppend.ButtonPushedFcn = createCallbackFcn(app, @pushbutton_ClearAppendButton, true);
            app.pushbutton_ClearAppend.Tag = 'pushbutton_ClearAppend';
            app.pushbutton_ClearAppend.Enable = 'off';
            app.pushbutton_ClearAppend.Text = 'Clear Append';
            app.pushbutton_Resonance = uibutton(app.uipanel15, 'push');
            app.pushbutton_Resonance.ButtonPushedFcn = createCallbackFcn(app, @exec_Resonances, true);
            app.pushbutton_Resonance.Tag = 'pushbutton_Resonance';
            app.pushbutton_Resonance.Enable = 'off';
            app.pushbutton_Resonance.Text = 'Check resonances';
            app.pushbutton_Lyapunov = uibutton(app.uipanel15, 'push');
            app.pushbutton_Lyapunov.ButtonPushedFcn = createCallbackFcn(app, @exec_Lyapunov, true);
            app.pushbutton_Lyapunov.Tag = 'pushbutton_Lyapunov';
            app.pushbutton_Lyapunov.Enable = 'off';
            app.pushbutton_Lyapunov.Text = 'Lyapunov time';
            app.uipanel1_2 = uipanel(app.LAPLACEUIFigure);
            app.uipanel1_2.Title = ' Initial conditions ';
            app.uipanel1_2.Tag = 'uipanel1';
            app.edit_mass2 = uieditfield(app.uipanel1_2, 'text');
            app.edit_mass2.ValueChangedFcn = createCallbackFcn(app, @edit_mass2_ValueChanged, true);
            app.edit_mass2.Tag = 'edit_mass2';
            app.edit_mass2.Value = '2.44783959796682e-6';
            app.edit_mass3 = uieditfield(app.uipanel1_2, 'text');
            app.edit_mass3.ValueChangedFcn = createCallbackFcn(app, @edit_mass3_ValueChanged, true);
            app.edit_mass3.Tag = 'edit_mass3';
            app.edit_mass3.Value = '3.04043292359547e-6';
            app.edit_mass4 = uieditfield(app.uipanel1_2, 'text');
            app.edit_mass4.ValueChangedFcn = createCallbackFcn(app, @edit_mass4_ValueChanged, true);
            app.edit_mass4.Tag = 'edit_mass4';
            app.edit_mass4.Value = '3.22714936215393e-7';
            app.edit_mass5 = uieditfield(app.uipanel1_2, 'text');
            app.edit_mass5.ValueChangedFcn = createCallbackFcn(app, @edit_mass5_ValueChanged, true);
            app.edit_mass5.Tag = 'edit_mass5';
            app.edit_mass5.Value = '1e-12';
            app.edit_mass6 = uieditfield(app.uipanel1_2, 'text');
            app.edit_mass6.ValueChangedFcn = createCallbackFcn(app, @edit_mass6_ValueChanged, true);
            app.edit_mass6.Tag = 'edit_mass6';
            app.edit_mass6.Value = '9.54786104043042e-4';
            app.edit_mass7 = uieditfield(app.uipanel1_2, 'text');
            app.edit_mass7.ValueChangedFcn = createCallbackFcn(app, @edit_mass7_ValueChanged, true);
            app.edit_mass7.Tag = 'edit_mass7';
            app.edit_mass7.Value = '2.85836787194512e-4';
            app.edit_mass8 = uieditfield(app.uipanel1_2, 'text');
            app.edit_mass8.ValueChangedFcn = createCallbackFcn(app, @edit_mass8_ValueChanged, true);
            app.edit_mass8.Tag = 'edit_mass8';
            app.edit_mass8.Value = '4.37273164545892e-5';
            app.edit_mass9 = uieditfield(app.uipanel1_2, 'text');
            app.edit_mass9.ValueChangedFcn = createCallbackFcn(app, @edit_mass9_9ValueChanged, true);
            app.edit_mass9.Tag = 'edit_mass9';
            app.edit_mass9.Value = '5.17759138448794e-5';
            app.edit_mass10 = uieditfield(app.uipanel1_2, 'text');
            app.edit_mass10.ValueChangedFcn = createCallbackFcn(app, @edit_mass10_ValueChanged, true);
            app.edit_mass10.Tag = 'edit_mass10';
            app.edit_mass10.Value = '6.5884e-9';
            app.edit_sma1 = uieditfield(app.uipanel1_2, 'text');
            app.edit_sma1.ValueChangedFcn = createCallbackFcn(app, @edit_sma1_ValueChanged, true);
            app.edit_sma1.Tag = 'edit_sma1';
            app.edit_sma1.Value = '0.38709893';
            app.edit_sma2 = uieditfield(app.uipanel1_2, 'text');
            app.edit_sma2.ValueChangedFcn = createCallbackFcn(app, @edit_sma2_ValueChanged, true);
            app.edit_sma2.Tag = 'edit_sma2';
            app.edit_sma2.Value = '0.72333199';
            app.edit_sma3 = uieditfield(app.uipanel1_2, 'text');
            app.edit_sma3.ValueChangedFcn = createCallbackFcn(app, @edit_sma3_ValueChanged, true);
            app.edit_sma3.Tag = 'edit_sma3';
            app.edit_sma3.Value = '1.0000001124';
            app.edit_sma4 = uieditfield(app.uipanel1_2, 'text');
            app.edit_sma4.ValueChangedFcn = createCallbackFcn(app, @edit_sma4_ValueChanged, true);
            app.edit_sma4.Tag = 'edit_sma4';
            app.edit_sma4.Value = '1.52366231';
            app.edit_sma5 = uieditfield(app.uipanel1_2, 'text');
            app.edit_sma5.ValueChangedFcn = createCallbackFcn(app, @edit_sma5_ValueChanged, true);
            app.edit_sma5.Tag = 'edit_sma5';
            app.edit_sma5.Value = '3';
            app.edit_sma6 = uieditfield(app.uipanel1_2, 'text');
            app.edit_sma6.ValueChangedFcn = createCallbackFcn(app, @edit_sma6_ValueChanged, true);
            app.edit_sma6.Tag = 'edit_sma6';
            app.edit_sma6.Value = '5.20336301';
            app.edit_sma7 = uieditfield(app.uipanel1_2, 'text');
            app.edit_sma7.ValueChangedFcn = createCallbackFcn(app, @edit_sma7_ValueChanged, true);
            app.edit_sma7.Tag = 'edit_sma7';
            app.edit_sma7.Value = '9.53707032';
            app.edit_sma8 = uieditfield(app.uipanel1_2, 'text');
            app.edit_sma8.ValueChangedFcn = createCallbackFcn(app, @edit_sma8_ValueChanged, true);
            app.edit_sma8.Tag = 'edit_sma8';
            app.edit_sma8.Value = '19.19126393';
            app.edit_sma9 = uieditfield(app.uipanel1_2, 'text');
            app.edit_sma9.ValueChangedFcn = createCallbackFcn(app, @edit_sma9_ValueChanged, true);
            app.edit_sma9.Tag = 'edit_sma9';
            app.edit_sma9.Value = '30.06896348';
            app.edit_sma10 = uieditfield(app.uipanel1_2, 'text');
            app.edit_sma10.ValueChangedFcn = createCallbackFcn(app, @edit_sma10_ValueChanged, true);
            app.edit_sma10.Tag = 'edit_sma10';
            app.edit_sma10.Value = '39.48168677';
            app.edit_ecc1 = uieditfield(app.uipanel1_2, 'text');
            app.edit_ecc1.ValueChangedFcn = createCallbackFcn(app, @edit_ecc1_ValueChanged, true);
            app.edit_ecc1.Tag = 'edit_ecc1';
            app.edit_ecc1.Value = '0.20563069';
            app.edit_ecc2 = uieditfield(app.uipanel1_2, 'text');
            app.edit_ecc2.ValueChangedFcn = createCallbackFcn(app, @edit_ecc2_ValueChanged, true);
            app.edit_ecc2.Tag = 'edit_ecc2';
            app.edit_ecc2.Value = '0.00677323';
            app.edit_ecc3 = uieditfield(app.uipanel1_2, 'text');
            app.edit_ecc3.ValueChangedFcn = createCallbackFcn(app, @edit_ecc3_ValueChanged, true);
            app.edit_ecc3.Tag = 'edit_ecc3';
            app.edit_ecc3.Value = '0.016710219';
            app.edit_ecc4 = uieditfield(app.uipanel1_2, 'text');
            app.edit_ecc4.ValueChangedFcn = createCallbackFcn(app, @edit_ecc4_ValueChanged, true);
            app.edit_ecc4.Tag = 'edit_ecc4';
            app.edit_ecc4.Value = '0.09341233';
            app.edit_ecc5 = uieditfield(app.uipanel1_2, 'text');
            app.edit_ecc5.ValueChangedFcn = createCallbackFcn(app, @edit_ecc5_ValueChanged, true);
            app.edit_ecc5.Tag = 'edit_ecc5';
            app.edit_ecc5.Value = '0.04';
            app.edit_ecc6 = uieditfield(app.uipanel1_2, 'text');
            app.edit_ecc6.ValueChangedFcn = createCallbackFcn(app, @edit_ecc6_ValueChanged, true);
            app.edit_ecc6.Tag = 'edit_ecc6';
            app.edit_ecc6.Value = '0.04839266';
            app.edit_ecc7 = uieditfield(app.uipanel1_2, 'text');
            app.edit_ecc7.ValueChangedFcn = createCallbackFcn(app, @edit_ecc7_ValueChanged, true);
            app.edit_ecc7.Tag = 'edit_ecc7';
            app.edit_ecc7.Value = '0.0541506';
            app.edit_ecc8 = uieditfield(app.uipanel1_2, 'text');
            app.edit_ecc8.ValueChangedFcn = createCallbackFcn(app, @edit_ecc8_ValueChanged, true);
            app.edit_ecc8.Tag = 'edit_ecc8';
            app.edit_ecc8.Value = '0.04716771';
            app.edit_ecc9 = uieditfield(app.uipanel1_2, 'text');
            app.edit_ecc9.ValueChangedFcn = createCallbackFcn(app, @edit_ecc9_ValueChanged, true);
            app.edit_ecc9.Tag = 'edit_ecc9';
            app.edit_ecc9.Value = '0.00858587';
            app.edit_ecc10 = uieditfield(app.uipanel1_2, 'text');
            app.edit_ecc10.ValueChangedFcn = createCallbackFcn(app, @edit_ecc10_ValueChanged, true);
            app.edit_ecc10.Tag = 'edit_ecc10';
            app.edit_ecc10.Value = '0.24880766';
            app.edit_incl1 = uieditfield(app.uipanel1_2, 'text');
            app.edit_incl1.ValueChangedFcn = createCallbackFcn(app, @edit_incl1_ValueChanged, true);
            app.edit_incl1.Tag = 'edit_incl1';
            app.edit_incl1.Value = '7.00487';
            app.edit_incl2 = uieditfield(app.uipanel1_2, 'text');
            app.edit_incl2.ValueChangedFcn = createCallbackFcn(app, @edit_incl2_ValueChanged, true);
            app.edit_incl2.Tag = 'edit_incl2';
            app.edit_incl2.Value = '3.39471';
            app.edit_incl3 = uieditfield(app.uipanel1_2, 'text');
            app.edit_incl3.ValueChangedFcn = createCallbackFcn(app, @edit_incl3_ValueChanged, true);
            app.edit_incl3.Tag = 'edit_incl3';
            app.edit_incl3.Value = '0';
            app.edit_incl4 = uieditfield(app.uipanel1_2, 'text');
            app.edit_incl4.ValueChangedFcn = createCallbackFcn(app, @edit_incl4_ValueChanged, true);
            app.edit_incl4.Tag = 'edit_incl4';
            app.edit_incl4.Value = '1.85061';
            app.edit_incl5 = uieditfield(app.uipanel1_2, 'text');
            app.edit_incl5.ValueChangedFcn = createCallbackFcn(app, @edit_incl5_ValueChanged, true);
            app.edit_incl5.Tag = 'edit_incl5';
            app.edit_incl5.Value = '2';
            app.edit_incl6 = uieditfield(app.uipanel1_2, 'text');
            app.edit_incl6.ValueChangedFcn = createCallbackFcn(app, @edit_incl6_ValueChanged, true);
            app.edit_incl6.Tag = 'edit_incl6';
            app.edit_incl6.Value = '1.30530';
            app.edit_incl7 = uieditfield(app.uipanel1_2, 'text');
            app.edit_incl7.ValueChangedFcn = createCallbackFcn(app, @edit_incl7_ValueChanged, true);
            app.edit_incl7.Tag = 'edit_incl7';
            app.edit_incl7.Value = '2.48446';
            app.edit_incl8 = uieditfield(app.uipanel1_2, 'text');
            app.edit_incl8.ValueChangedFcn = createCallbackFcn(app, @edit_incl8_ValueChanged, true);
            app.edit_incl8.Tag = 'edit_incl8';
            app.edit_incl8.Value = '0.76986';
            app.edit_incl9 = uieditfield(app.uipanel1_2, 'text');
            app.edit_incl9.ValueChangedFcn = createCallbackFcn(app, @edit_incl9_ValueChanged, true);
            app.edit_incl9.Tag = 'edit_incl9';
            app.edit_incl9.Value = '1.76917';
            app.edit_incl10 = uieditfield(app.uipanel1_2, 'text');
            app.edit_incl10.ValueChangedFcn = createCallbackFcn(app, @edit_incl10_ValueChanged, true);
            app.edit_incl10.Tag = 'edit_incl10';
            app.edit_incl10.Value = '17.14175';
            app.edit_peri1 = uieditfield(app.uipanel1_2, 'text');
            app.edit_peri1.ValueChangedFcn = createCallbackFcn(app, @edit_peri1_ValueChanged, true);
            app.edit_peri1.Tag = 'edit_peri1';
            app.edit_peri1.Value = '77.45779628';
            app.edit_peri2 = uieditfield(app.uipanel1_2, 'text');
            app.edit_peri2.ValueChangedFcn = createCallbackFcn(app, @edit_peri2_ValueChanged, true);
            app.edit_peri2.Tag = 'edit_peri2';
            app.edit_peri2.Value = '131.60246718';
            app.edit_peri3 = uieditfield(app.uipanel1_2, 'text');
            app.edit_peri3.ValueChangedFcn = createCallbackFcn(app, @edit_peri3_ValueChanged, true);
            app.edit_peri3.Tag = 'edit_peri3';
            app.edit_peri3.Value = '102.93768193';
            app.edit_peri4 = uieditfield(app.uipanel1_2, 'text');
            app.edit_peri4.ValueChangedFcn = createCallbackFcn(app, @edit_peri4_ValueChanged, true);
            app.edit_peri4.Tag = 'edit_peri4';
            app.edit_peri4.Value = '-23.94362959';
            app.edit_peri5 = uieditfield(app.uipanel1_2, 'text');
            app.edit_peri5.ValueChangedFcn = createCallbackFcn(app, @edit_peri5_ValueChanged, true);
            app.edit_peri5.Tag = 'edit_peri5';
            app.edit_peri5.Value = '0';
            app.edit_peri6 = uieditfield(app.uipanel1_2, 'text');
            app.edit_peri6.ValueChangedFcn = createCallbackFcn(app, @edit_peri6_ValueChanged, true);
            app.edit_peri6.Tag = 'edit_peri6';
            app.edit_peri6.Value = '14.72847983';
            app.edit_peri7 = uieditfield(app.uipanel1_2, 'text');
            app.edit_peri7.ValueChangedFcn = createCallbackFcn(app, @edit_peri7_ValueChanged, true);
            app.edit_peri7.Tag = 'edit_peri7';
            app.edit_peri7.Value = '92.59887831';
            app.edit_peri8 = uieditfield(app.uipanel1_2, 'text');
            app.edit_peri8.ValueChangedFcn = createCallbackFcn(app, @edit_peri8_ValueChanged, true);
            app.edit_peri8.Tag = 'edit_peri8';
            app.edit_peri8.Value = '170.95427630';
            app.edit_peri9 = uieditfield(app.uipanel1_2, 'text');
            app.edit_peri9.ValueChangedFcn = createCallbackFcn(app, @edit_peri9_ValueChanged, true);
            app.edit_peri9.Tag = 'edit_peri9';
            app.edit_peri9.Value = '44.96476227';
            app.edit_peri10 = uieditfield(app.uipanel1_2, 'text');
            app.edit_peri10.ValueChangedFcn = createCallbackFcn(app, @edit_peri10_ValueChanged, true);
            app.edit_peri10.Tag = 'edit_peri10';
            app.edit_peri10.Value = '224.06891629';
            app.edit_longmean1 = uieditfield(app.uipanel1_2, 'text');
            app.edit_longmean1.ValueChangedFcn = createCallbackFcn(app, @edit_longmean1_ValueChanged, true);
            app.edit_longmean1.Tag = 'edit_longmean1';
            app.edit_longmean1.Value = '252.25032350';
            app.edit_longmean2 = uieditfield(app.uipanel1_2, 'text');
            app.edit_longmean2.ValueChangedFcn = createCallbackFcn(app, @edit_longmean2_ValueChanged, true);
            app.edit_longmean2.Tag = 'edit_longmean2';
            app.edit_longmean2.Value = '181.97909950';
            app.edit_longmean3 = uieditfield(app.uipanel1_2, 'text');
            app.edit_longmean3.ValueChangedFcn = createCallbackFcn(app, @edit_longmean3_ValueChanged, true);
            app.edit_longmean3.Tag = 'edit_longmean3';
            app.edit_longmean3.Value = '100.46457166';
            app.edit_longmean4 = uieditfield(app.uipanel1_2, 'text');
            app.edit_longmean4.ValueChangedFcn = createCallbackFcn(app, @edit_longmean4_ValueChanged, true);
            app.edit_longmean4.Tag = 'edit_longmean4';
            app.edit_longmean4.Value = '-4.55343205';
            app.edit_longmean5 = uieditfield(app.uipanel1_2, 'text');
            app.edit_longmean5.ValueChangedFcn = createCallbackFcn(app, @edit_longmean5_ValueChanged, true);
            app.edit_longmean5.Tag = 'edit_longmean5';
            app.edit_longmean5.Value = '0';
            app.edit_longmean6 = uieditfield(app.uipanel1_2, 'text');
            app.edit_longmean6.ValueChangedFcn = createCallbackFcn(app, @edit_longmean6_ValueChanged, true);
            app.edit_longmean6.Tag = 'edit_longmean6';
            app.edit_longmean6.Value = '34.39644051';
            app.edit_longmean7 = uieditfield(app.uipanel1_2, 'text');
            app.edit_longmean7.ValueChangedFcn = createCallbackFcn(app, @edit_longmean7_ValueChanged, true);
            app.edit_longmean7.Tag = 'edit_longmean7';
            app.edit_longmean7.Value = '49.95424423';
            app.edit_longmean8 = uieditfield(app.uipanel1_2, 'text');
            app.edit_longmean8.ValueChangedFcn = createCallbackFcn(app, @edit_longmean8_ValueChanged, true);
            app.edit_longmean8.Tag = 'edit_longmean8';
            app.edit_longmean8.Value = '313.23810451';
            app.edit_longmean9 = uieditfield(app.uipanel1_2, 'text');
            app.edit_longmean9.ValueChangedFcn = createCallbackFcn(app, @edit_longmean9_ValueChanged, true);
            app.edit_longmean9.Tag = 'edit_longmean9';
            app.edit_longmean9.Value = '-55.12002969';
            app.edit_longmean10 = uieditfield(app.uipanel1_2, 'text');
            app.edit_longmean10.ValueChangedFcn = createCallbackFcn(app, @edit_longmean10_ValueChanged, true);
            app.edit_longmean10.Tag = 'edit_longmean10';
            app.edit_longmean10.Value = '238.92903833';
            app.text9 = uilabel(app.uipanel1_2);
            app.text9.Tag = 'text9';
            app.text9.Text = 'Neptune';
            app.text8 = uilabel(app.uipanel1_2);
            app.text8.Tag = 'text8';
            app.text8.Text = 'Uranus';
            app.text7 = uilabel(app.uipanel1_2);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Saturn';
            app.text10 = uilabel(app.uipanel1_2);
            app.text10.Tag = 'text10';
            app.text10.Text = 'Pluto';
            app.text1 = uilabel(app.uipanel1_2);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Mercury';
            app.text6 = uilabel(app.uipanel1_2);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Jupiter';
            app.text3 = uilabel(app.uipanel1_2);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Earth-Moon';
            app.text2 = uilabel(app.uipanel1_2);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Venus';
            app.text5 = uilabel(app.uipanel1_2);
            app.text5.Tag = 'text5';
            app.text5.Text = 'Asteroid';
            app.text4 = uilabel(app.uipanel1_2);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Mars';
            app.text11 = uilabel(app.uipanel1_2);
            app.text11.Tag = 'text11';
            app.text11.Text = {'Mass'; '(Sun mass)'};
            app.text12 = uilabel(app.uipanel1_2);
            app.text12.Tag = 'text12';
            app.text12.Text = {'Semimajor axis'; '(AU)'};
            app.text13 = uilabel(app.uipanel1_2);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Eccentricity';
            app.text14 = uilabel(app.uipanel1_2);
            app.text14.Tag = 'text14';
            app.text14.Text = {'Inclination'; '(°)'};
            app.text16 = uilabel(app.uipanel1_2);
            app.text16.Tag = 'text16';
            app.text16.Text = {'Long. perihelion'; '(°)'};
            app.text17 = uilabel(app.uipanel1_2);
            app.text17.Tag = 'text17';
            app.text17.Text = {'Mean longitude'; '(°)'};
            app.edit_asc1 = uieditfield(app.uipanel1_2, 'text');
            app.edit_asc1.ValueChangedFcn = createCallbackFcn(app, @edit_asc1_ValueChanged, true);
            app.edit_asc1.Tag = 'edit_asc1';
            app.edit_asc1.Value = '48.33167';
            app.edit_asc2 = uieditfield(app.uipanel1_2, 'text');
            app.edit_asc2.ValueChangedFcn = createCallbackFcn(app, @edit_asc2_ValueChanged, true);
            app.edit_asc2.Tag = 'edit_asc2';
            app.edit_asc2.Value = '76.68069';
            app.edit_asc3 = uieditfield(app.uipanel1_2, 'text');
            app.edit_asc3.ValueChangedFcn = createCallbackFcn(app, @edit_asc3_ValueChanged, true);
            app.edit_asc3.Tag = 'edit_asc3';
            app.edit_asc3.Value = '348.73936';
            app.edit_asc4 = uieditfield(app.uipanel1_2, 'text');
            app.edit_asc4.ValueChangedFcn = createCallbackFcn(app, @edit_asc4_ValueChanged, true);
            app.edit_asc4.Tag = 'edit_asc4';
            app.edit_asc4.Value = '49.57854';
            app.edit_asc5 = uieditfield(app.uipanel1_2, 'text');
            app.edit_asc5.ValueChangedFcn = createCallbackFcn(app, @edit_asc5_ValueChanged, true);
            app.edit_asc5.Tag = 'edit_asc5';
            app.edit_asc5.Value = '0';
            app.edit_asc6 = uieditfield(app.uipanel1_2, 'text');
            app.edit_asc6.ValueChangedFcn = createCallbackFcn(app, @edit_asc6_ValueChanged, true);
            app.edit_asc6.Tag = 'edit_asc6';
            app.edit_asc6.Value = '100.55615';
            app.edit_asc7 = uieditfield(app.uipanel1_2, 'text');
            app.edit_asc7.ValueChangedFcn = createCallbackFcn(app, @edit_asc7_ValueChanged, true);
            app.edit_asc7.Tag = 'edit_asc7';
            app.edit_asc7.Value = '113.71504';
            app.edit_asc8 = uieditfield(app.uipanel1_2, 'text');
            app.edit_asc8.ValueChangedFcn = createCallbackFcn(app, @edit_asc8_ValueChanged, true);
            app.edit_asc8.Tag = 'edit_asc8';
            app.edit_asc8.Value = '74.22988';
            app.edit_asc9 = uieditfield(app.uipanel1_2, 'text');
            app.edit_asc9.ValueChangedFcn = createCallbackFcn(app, @edit_asc9_ValueChanged, true);
            app.edit_asc9.Tag = 'edit_asc9';
            app.edit_asc9.Value = '131.72169';
            app.edit_asc10 = uieditfield(app.uipanel1_2, 'text');
            app.edit_asc10.ValueChangedFcn = createCallbackFcn(app, @edit_asc10_ValueChanged, true);
            app.edit_asc10.Tag = 'edit_asc10';
            app.edit_asc10.Value = '110.30347';
            app.text15 = uilabel(app.uipanel1_2);
            app.text15.Tag = 'text15';
            app.text15.Text = {'Long. ascending'; 'node (°)'};
            app.edit_mass1 = uieditfield(app.uipanel1_2, 'text');
            app.edit_mass1.ValueChangedFcn = createCallbackFcn(app, @edit_mass1_ValueChanged, true);
            app.edit_mass1.Value = '1.66013679527193e-7';
            app.Image = uiimage(app.uipanel1_2);
            % Show the figure after all components are created
            app.LAPLACEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Laplace_source

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LAPLACEUIFigure)

            % Execute the startup function
            runStartupFcn(app, @Laplace_startup)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LAPLACEUIFigure)
        end
    end
end