classdef LaLa < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LagrangeLaplacetheoryUIFigure  matlab.ui.Figure
        uipanel6                       matlab.ui.container.Panel
        uipanel8                       matlab.ui.container.Panel
        pushbutton_eig_compute         matlab.ui.control.Button
        text42                         matlab.ui.control.Label
        edit_eig_step                  matlab.ui.control.EditField
        edit_eig_to                    matlab.ui.control.EditField
        edit_eig_from                  matlab.ui.control.EditField
        text41                         matlab.ui.control.Label
        popupmenu_eig_planets          matlab.ui.control.DropDown
        uipanel7                       matlab.ui.container.Panel
        check_log                      matlab.ui.control.CheckBox
        pushbutton_FFT                 matlab.ui.control.Button
        uipanel5                       matlab.ui.container.Panel
        text36                         matlab.ui.control.Label
        edit_nfreq                     matlab.ui.control.EditField
        text37                         matlab.ui.control.Label
        text38                         matlab.ui.control.Label
        edit_fromfreq                  matlab.ui.control.EditField
        text39                         matlab.ui.control.Label
        edit_tofreq                    matlab.ui.control.EditField
        text40                         matlab.ui.control.Label
        popupmenu_FMFT_flag            matlab.ui.control.DropDown
        pushbutton_FMFT                matlab.ui.control.Button
        uipanel2                       matlab.ui.container.Panel
        checkbox_Own                   matlab.ui.control.CheckBox
        pushbutton_Plot                matlab.ui.control.Button
        text18                         matlab.ui.control.Label
        popupmenu_planets              matlab.ui.control.DropDown
        popupmenu_elements             matlab.ui.control.DropDown
        uipanel4                       matlab.ui.container.Panel
        edit_total                     matlab.ui.control.NumericEditField
        TotaltimeyrLabel               matlab.ui.control.Label
        edit_step                      matlab.ui.control.NumericEditField
        StepyrEditFieldLabel           matlab.ui.control.Label
        pushbutton_Compute             matlab.ui.control.Button
        uipanel1                       matlab.ui.container.Panel
        edit_sma4                      matlab.ui.control.EditField
        edit_sma3                      matlab.ui.control.EditField
        edit_sma2                      matlab.ui.control.EditField
        edit_sma1                      matlab.ui.control.EditField
        edit_asc4                      matlab.ui.control.EditField
        edit_asc3                      matlab.ui.control.EditField
        edit_asc2                      matlab.ui.control.EditField
        edit_asc1                      matlab.ui.control.EditField
        text35                         matlab.ui.control.Label
        text34                         matlab.ui.control.Label
        text33                         matlab.ui.control.Label
        text32                         matlab.ui.control.Label
        edit_peri4                     matlab.ui.control.EditField
        edit_peri3                     matlab.ui.control.EditField
        edit_peri2                     matlab.ui.control.EditField
        edit_peri1                     matlab.ui.control.EditField
        edit_incl4                     matlab.ui.control.EditField
        edit_incl3                     matlab.ui.control.EditField
        edit_incl2                     matlab.ui.control.EditField
        edit_incl1                     matlab.ui.control.EditField
        edit_ecc4                      matlab.ui.control.EditField
        edit_ecc3                      matlab.ui.control.EditField
        edit_ecc2                      matlab.ui.control.EditField
        edit_ecc1                      matlab.ui.control.EditField
        edit_mass4                     matlab.ui.control.EditField
        edit_mass3                     matlab.ui.control.EditField
        edit_mass2                     matlab.ui.control.EditField
        edit_mass1                     matlab.ui.control.EditField
        text31                         matlab.ui.control.Label
        edit_sma8                      matlab.ui.control.EditField
        edit_sma7                      matlab.ui.control.EditField
        edit_sma6                      matlab.ui.control.EditField
        edit_sma5                      matlab.ui.control.EditField
        text17                         matlab.ui.control.Label
        edit_asc8                      matlab.ui.control.EditField
        edit_asc7                      matlab.ui.control.EditField
        edit_asc6                      matlab.ui.control.EditField
        edit_asc5                      matlab.ui.control.EditField
        text15                         matlab.ui.control.Label
        text14                         matlab.ui.control.Label
        text13                         matlab.ui.control.Label
        text11                         matlab.ui.control.Label
        text6                          matlab.ui.control.Label
        text3                          matlab.ui.control.Label
        text2                          matlab.ui.control.Label
        text1                          matlab.ui.control.Label
        edit_peri8                     matlab.ui.control.EditField
        edit_peri7                     matlab.ui.control.EditField
        edit_peri6                     matlab.ui.control.EditField
        edit_peri5                     matlab.ui.control.EditField
        edit_incl8                     matlab.ui.control.EditField
        edit_incl7                     matlab.ui.control.EditField
        edit_incl6                     matlab.ui.control.EditField
        edit_incl5                     matlab.ui.control.EditField
        edit_ecc8                      matlab.ui.control.EditField
        edit_ecc7                      matlab.ui.control.EditField
        edit_ecc6                      matlab.ui.control.EditField
        edit_ecc5                      matlab.ui.control.EditField
        edit_mass8                     matlab.ui.control.EditField
        edit_mass7                     matlab.ui.control.EditField
        edit_mass6                     matlab.ui.control.EditField
        edit_mass5                     matlab.ui.control.EditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: pushbutton_Compute
        function exec_compute(app, event)
            global Npl
            Npl = 8;
            m0 = 1;
            for k = 1:Npl
                m(k) = eval(sprintf('str2num(get(app.edit_mass%d,''Value''))',k));
            end
            
            if m
                %
            else
                warndlg('The masses must be all > 0.')
                return
            end
            
            for k = 1 : Npl
                a(k) = eval(sprintf('str2num(get(app.edit_sma%d,''Value''))',k));
            end
            
            alpha = zeros(Npl);
            for h = 1 : Npl-1
                for k = h+1 : Npl
                    alpha(h,k) = a(h)/a(k);
                    alpha(k,h) = a(h)/a(k);
                end
            end
            
            A = ones(Npl);
            for h = 1 : Npl-1
                for k = h+1 : Npl
                    A(h,k) = max(a(h),a(k));
                    A(k,h) = max(a(h),a(k));
                end
            end
            
            Gauss = 6.2830666414875;
            lambda = Gauss*m.*sqrt(a.*(m0+m));
            
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            Lc1 = zeros(Npl,Npl);
            Lc2 = zeros(Npl,Npl);
            for h = 1 : Npl - 1
                for k = h + 1 : Npl
                    Lc1(h,k) = LaplaceCoeff(1,3/2,alpha(h,k));
                    Lc1(k,h) = Lc1(h,k);
                    Lc2(h,k) = LaplaceCoeff(2,3/2,alpha(h,k));
                    Lc2(k,h) = Lc2(h,k);
                end
            end
            
            Hecc = zeros(Npl,Npl);
            for h = 1 : Npl
                Hecc(h,h) = -1/4*Gauss^2*m(h)/lambda(h)*sum((m.*alpha(h,:)./A(h,:).*Lc1(h,:)));
            end
            for h = 1 : Npl - 1
                for k = h + 1 : Npl
                    Hecc(h,k) = 1/4*Gauss^2*m(h)*m(k)/sqrt(lambda(h)*lambda(k))*alpha(h,k)/A(h,k)*Lc2(h,k);
                    Hecc(k,h) = Hecc(h,k);
                end
            end
            
            Hinc = zeros(Npl,Npl);
            for h = 1 : Npl
                Hinc(h,h) = -Hecc(h,h);
            end
            for h = 1 : Npl - 1
                for k = h + 1 : Npl
                    Hinc(h,k) = -1/4*Gauss^2*m(h)*m(k)/sqrt(lambda(h)*lambda(k))*alpha(h,k)/A(h,k)*Lc1(h,k);
                    Hinc(k,h) = Hinc(h,k);
                end
            end

            g = -(eig(Hecc))'/2/pi;
            fid_g = fopen( 'Eigenfreq_g.txt', 'wt');
            fprintf(fid_g, '%%*************************************************************************************************\n');
            fprintf(fid_g, '%%             Eigenfrequencies for Eccentricity-Longitude of pericenter (rev/yr)              \n');
            fprintf(fid_g, '%%*************************************************************************************************\n');        
            fprintf(fid_g, [num2str(g), '\n']);
            if isdeployed
                % do nothing
            else
                edit Eigenfreq_g.txt
            end

            s = -(eig(Hinc))'/2/pi;
            fid_s = fopen( 'Eigenfreq_s.txt', 'wt');
            fprintf(fid_s, '%%*************************************************************************************************\n');
            fprintf(fid_s, '%%             Eigenfrequencies for Inclination-Longitude of the node (rev/yr)              \n');
            fprintf(fid_s, '%%*************************************************************************************************\n');    
            fprintf(fid_s, [num2str(s), '\n']);            
            if isdeployed
                % do nothing
            else
                edit Eigenfreq_s.txt
            end
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            for k = 1 : Npl
                ecc(k) = eval(sprintf('str2num(get(app.edit_ecc%d,''Value''))',k));
            end
            
            for k = 1 : Npl
                peri(k) = eval(sprintf('str2num(get(app.edit_peri%d,''Value''))',k));
            end
            
            for k = 1 : Npl
                incl(k) = eval(sprintf('str2num(get(app.edit_incl%d,''Value''))',k));
            end
            
            for k = 1 : Npl
                asc(k) = eval(sprintf('str2num(get(app.edit_asc%d,''Value''))',k));
            end
            
            Zecc = ecc.*exp(-i*pi/180*peri);
            Zinc = sin(pi/180*incl).*exp(-i*pi/180*asc);
            Zecc = Zecc.';
            Zinc = Zinc.';
            
            Sql = diag(sqrt(lambda));
            
            step = get(app.edit_step,'Value');
            total = get(app.edit_total,'Value');
            n_step = ceil(total/step);
            Tecc = inv(Sql)*expm(i*Hecc*step)*Sql;
            Tinc = inv(Sql)*expm(i*Hinc*step)*Sql;
            %%%%%%%%%%%%%%%%%%%% Eccentricity, Long. perihelion %%%%%%%%%%%%%%%%%%%%%%%%
            global zecc
            zecc = zeros(Npl,n_step+1);
            zecc(:,1) = Zecc;
            for k = 2:n_step+1
                zecc(:,k) = Tecc*zecc(:,k-1);
            end
            
            %%%%%%%%%%%%%%%%%%%%% Inclination, Long asc. node %%%%%%%%%%%%%%%%%%%%%%%%%
            global zinc
            zinc = zeros(Npl,n_step+1);
            zinc(:,1) = Zinc;
            for k = 2:n_step+1
                zinc(:,k) = Tinc*zinc(:,k-1);
            end
            
            %--------------------------------------------------------------------------
            set(app.popupmenu_elements,'Enable','on');
            set(app.popupmenu_planets,'Enable','on');
            set(app.pushbutton_Plot,'Enable','on');
            set(app.pushbutton_eig_compute,'Enable','on');
            set(app.pushbutton_FFT,'Enable','on');
            set(app.pushbutton_FMFT,'Enable','on');
        end

        % Button pushed function: pushbutton_Plot
        function exec_plot(app, event)
            global zecc zinc Npl
            
            zinctrue = zinc;
            if get(app.checkbox_Own,'Value') == 1
                Zo = mean(zinc')';
                for k = 1 : Npl
                    zinc(k,:) = zinc(k,:) - Zo(k);
                end
            
            end
            step = get(app.edit_step,'Value');
            total = get(app.edit_total,'Value');
            n_step = ceil(total/step);
            t = 0 : step : n_step*step;
            item_planet = str2num(get(app.popupmenu_planets,'Value'));
            item_element = str2num(get(app.popupmenu_elements,'Value'));
            
            if item_planet == 1
                if item_element == 1
                    figure
                    plot(real(zecc(1,:)),imag(zecc(1,:)))
                    title('Mercury: \itz_{ecc}(t)', 'FontSize',12)
                elseif item_element == 2
                    figure
                    plot(t,sqrt((real(zecc(1,:))).^2 + (imag(zecc(1,:))).^2))
                    title('Mercury: Eccentricity', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                elseif item_element == 3
                    figure
                    plot(t,-180/pi*atan2((imag(zecc(1,:))),(real(zecc(1,:)))))
                    title('Mercury: Longitude of perihelion (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 4
                    figure
                    plot(real(zinc(1,:)),imag(zinc(1,:)))
                    title('Mercury: \itz_{inc}(t)', 'FontSize',12)
                elseif item_element == 5
                    figure
                    plot(t,180/pi*asin((sqrt((real(zinc(1,:))).^2 + (imag(zinc(1,:))).^2))))
                    title('Mercury: Inclination (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 6
                    figure
                    plot(t,-180/pi*atan2((imag(zinc(1,:))),(real(zinc(1,:)))))
                    title('Mercury: Longitude of ascending node (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                end
            elseif item_planet == 2
                if item_element == 1
                    figure
                    plot(real(zecc(2,:)),imag(zecc(2,:)))
                    title('Venus: \itz_{ecc}(t)', 'FontSize',12)
                elseif item_element == 2
                    figure
                    plot(t,sqrt((real(zecc(2,:))).^2 + (imag(zecc(2,:))).^2))
                    title('Venus: Eccentricity', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                elseif item_element == 3
                    figure
                    plot(t,-180/pi*atan2((imag(zecc(2,:))),(real(zecc(2,:)))))
                    title('Venus: Longitude of perihelion (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 4
                    figure
                    plot(real(zinc(2,:)),imag(zinc(2,:)))
                    title('Venus: \itz_{inc}(t)', 'FontSize',12)
                elseif item_element == 5
                    figure
                    plot(t,180/pi*asin((sqrt((real(zinc(2,:))).^2 + (imag(zinc(2,:))).^2))))
                    title('Venus: Inclination (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 6
                    figure
                    plot(t,-180/pi*atan2((imag(zinc(2,:))),(real(zinc(2,:)))))
                    title('Venus: Longitude of ascending node (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                end
            elseif item_planet == 3
                if item_element == 1
                    figure
                    plot(real(zecc(3,:)),imag(zecc(3,:)))
                    title('Earth: \itz_{ecc}(t)', 'FontSize',12)
                elseif item_element == 2
                    figure
                    plot(t,sqrt((real(zecc(3,:))).^2 + (imag(zecc(3,:))).^2))
                    title('Earth: Eccentricity', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                elseif item_element == 3
                    figure
                    plot(t,-180/pi*atan2((imag(zecc(3,:))),(real(zecc(3,:)))))
                    title('Earth: Longitude of perihelion (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 4
                    figure
                    plot(real(zinc(3,:)),imag(zinc(3,:)))
                    title('Earth: \itz_{inc}(t)', 'FontSize',12)
                elseif item_element == 5
                    figure
                    plot(t,180/pi*asin((sqrt((real(zinc(3,:))).^2 + (imag(zinc(3,:))).^2))))
                    title('Earth: Inclination (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 6
                    figure
                    plot(t,-180/pi*atan2((imag(zinc(3,:))),(real(zinc(3,:)))))
                    title('Earth: Longitude of ascending node (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                end
            elseif item_planet == 4
                if item_element == 1
                    figure
                    plot(real(zecc(4,:)),imag(zecc(4,:)))
                    title('Mars: \itz_{ecc}(t)', 'FontSize',12)
                elseif item_element == 2
                    figure
                    plot(t,sqrt((real(zecc(4,:))).^2 + (imag(zecc(4,:))).^2))
                    title('Mars: Eccentricity', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                elseif item_element == 3
                    figure
                    plot(t,-180/pi*atan2((imag(zecc(4,:))),(real(zecc(4,:)))))
                    title('Mars: Longitude of perihelion (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 4
                    figure
                    plot(real(zinc(4,:)),imag(zinc(4,:)))
                    title('Mars: \itz_{inc}(t)', 'FontSize',12)
                elseif item_element == 5
                    figure
                    plot(t,180/pi*asin((sqrt((real(zinc(4,:))).^2 + (imag(zinc(4,:))).^2))))
                    title('Mars: Inclination (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 6
                    figure
                    plot(t,-180/pi*atan2((imag(zinc(4,:))),(real(zinc(4,:)))))
                    title('Mars: Longitude of ascending node (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                end
            elseif item_planet == 5
                if item_element == 1
                    figure
                    plot(real(zecc(5,:)),imag(zecc(5,:)))
                    title('Jupiter: \itz_{ecc}(t)', 'FontSize',12)
                elseif item_element == 2
                    figure
                    plot(t,sqrt((real(zecc(5,:))).^2 + (imag(zecc(5,:))).^2))
                    title('Jupiter: Eccentricity', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                elseif item_element == 3
                    figure
                    plot(t,-180/pi*atan2((imag(zecc(5,:))),(real(zecc(5,:)))))
                    title('Jupiter: Longitude of perihelion (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 4
                    figure
                    plot(real(zinc(5,:)),imag(zinc(5,:)))
                    title('Jupiter: \itz_{inc}(t)', 'FontSize',12)
                elseif item_element == 5
                    figure
                    plot(t,180/pi*asin((sqrt((real(zinc(5,:))).^2 + (imag(zinc(5,:))).^2))))
                    title('Jupiter: Inclination (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 6
                    figure
                    plot(t,-180/pi*atan2((imag(zinc(5,:))),(real(zinc(5,:)))))
                    title('Jupiter: Longitude of ascending node (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                end
            elseif item_planet == 6
                if item_element == 1
                    figure
                    plot(real(zecc(6,:)),imag(zecc(6,:)))
                    title('Saturn: \itz_{ecc}(t)', 'FontSize',12)
                elseif item_element == 2
                    figure
                    plot(t,sqrt((real(zecc(6,:))).^2 + (imag(zecc(6,:))).^2))
                    title('Saturn: Eccentricity', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                elseif item_element == 3
                    figure
                    plot(t,-180/pi*atan2((imag(zecc(6,:))),(real(zecc(6,:)))))
                    title('Saturn: Longitude of perihelion (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 4
                    figure
                    plot(real(zinc(6,:)),imag(zinc(6,:)))
                    title('Saturn: \itz_{inc}(t)', 'FontSize',12)
                elseif item_element == 5
                    figure
                    plot(t,180/pi*asin((sqrt((real(zinc(6,:))).^2 + (imag(zinc(6,:))).^2))))
                    title('Saturn: Inclination (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 6
                    figure
                    plot(t,-180/pi*atan2((imag(zinc(6,:))),(real(zinc(6,:)))))
                    title('Saturn: Longitude of ascending node (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                end
            elseif item_planet == 7
                if item_element == 1
                    figure
                    plot(real(zecc(7,:)),imag(zecc(7,:)))
                    title('Uranus: \itz_{ecc}(t)', 'FontSize',12)
                elseif item_element == 2
                    figure
                    plot(t,sqrt((real(zecc(7,:))).^2 + (imag(zecc(7,:))).^2))
                    title('Uranus: Eccentricity', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                elseif item_element == 3
                    figure
                    plot(t,-180/pi*atan2((imag(zecc(7,:))),(real(zecc(7,:)))))
                    title('Uranus: Longitude of perihelion (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 4
                    figure
                    plot(real(zinc(7,:)),imag(zinc(7,:)))
                    title('Uranus: \itz_{inc}(t)', 'FontSize',12)
                elseif item_element == 5
                    figure
                    plot(t,180/pi*asin((sqrt((real(zinc(7,:))).^2 + (imag(zinc(7,:))).^2))))
                    title('Uranus: Inclination (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 6
                    figure
                    plot(t,-180/pi*atan2((imag(zinc(7,:))),(real(zinc(7,:)))))
                    title('Uranus: Longitude of ascending node (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                end
            elseif item_planet == 8
                if item_element == 1
                    figure
                    plot(real(zecc(8,:)),imag(zecc(8,:)))
                    title('Neptune: \itz_{ecc}(t)', 'FontSize',12)
                elseif item_element == 2
                    figure
                    plot(t,sqrt((real(zecc(8,:))).^2 + (imag(zecc(8,:))).^2))
                    title('Neptune: Eccentricity', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                elseif item_element == 3
                    figure
                    plot(t,-180/pi*atan2((imag(zecc(8,:))),(real(zecc(8,:)))))
                    title('Neptune: Longitude of perihelion (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 4
                    figure
                    plot(real(zinc(8,:)),imag(zinc(8,:)))
                    title('Neptune: \itz_{inc}(t)', 'FontSize',12)
                elseif item_element == 5
                    figure
                    plot(t,180/pi*asin((sqrt((real(zinc(8,:))).^2 + (imag(zinc(8,:))).^2))))
                    title('Neptune: Inclination (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                elseif item_element == 6
                    figure
                    plot(t,-180/pi*atan2((imag(zinc(8,:))),(real(zinc(8,:)))))
                    title('Neptune: Longitude of ascending node (deg)', 'FontSize',12)
                    xlabel('time  (yr)', 'FontSize',14)
                    Degree_y
                end
            end
            zinc = zinctrue;            
        end

        % Button pushed function: pushbutton_eig_compute
        function exec_eigen(app, event)
            set(app.popupmenu_elements,'Enable','off');
            set(app.popupmenu_planets,'Enable','off');
            set(app.pushbutton_Plot,'Enable','off');
            set(app.pushbutton_FFT,'Enable','off');
            set(app.pushbutton_FMFT,'Enable','off');

            item = str2num(get(app.popupmenu_eig_planets,'Value'));
            list = get(app.popupmenu_eig_planets,'Items');
            planet = list{item};
            sma_min = str2num(get(app.edit_eig_from,'Value'));
            sma_max = str2num(get(app.edit_eig_to,'Value'));
            step = str2num(get(app.edit_eig_step,'Value'));
            
            Npl = 8;
            m0 = 1;
            for k = 1:Npl
                m(k) = eval(sprintf('str2num(get(app.edit_mass%d,''Value''))',k));
            end
            
            if m
                %
            else
                warndlg('The masses must be all > 0.')
                return
            end
            
            for k = 1 : Npl
                a(k) = eval(sprintf('str2num(get(app.edit_sma%d,''Value''))',k));
            end
            
            for sma = sma_min : step : sma_max
                a(item) = sma;
            
                alpha = zeros(Npl);
                for h = 1 : Npl-1
                    for k = h+1 : Npl
                        alpha(h,k) = a(h)/a(k);
                        alpha(k,h) = a(h)/a(k);
                    end
                end
            
                A = ones(Npl);
                for h = 1 : Npl-1
                    for k = h+1 : Npl
                        A(h,k) = max(a(h),a(k));
                        A(k,h) = max(a(h),a(k));
                    end
                end
            
                Gauss = 6.2830666414875;
                lambda = Gauss*m.*sqrt(a.*(m0+m));
            
                %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
                Lc1 = zeros(Npl,Npl);
                Lc2 = zeros(Npl,Npl);
                for h = 1 : Npl - 1
                    for k = h + 1 : Npl
                        Lc1(h,k) = LaplaceCoeff(1,3/2,alpha(h,k));
                        Lc1(k,h) = Lc1(h,k);
                        Lc2(h,k) = LaplaceCoeff(2,3/2,alpha(h,k));
                        Lc2(k,h) = Lc2(h,k);
                    end
                end
            
                Hecc = zeros(Npl,Npl);
                for h = 1 : Npl
                    Hecc(h,h) = -1/4*Gauss^2*m(h)/lambda(h)*sum((m.*alpha(h,:)./A(h,:).*Lc1(h,:)));
                end
                for h = 1 : Npl - 1
                    for k = h + 1 : Npl
                        Hecc(h,k) = 1/4*Gauss^2*m(h)*m(k)/sqrt(lambda(h)*lambda(k))*alpha(h,k)/A(h,k)*Lc2(h,k);
                        Hecc(k,h) = Hecc(h,k);
                    end
                end
            
                Hinc = zeros(Npl,Npl);
                for h = 1 : Npl
                    Hinc(h,h) = -Hecc(h,h);
                end
                for h = 1 : Npl - 1
                    for k = h + 1 : Npl
                        Hinc(h,k) = -1/4*Gauss^2*m(h)*m(k)/sqrt(lambda(h)*lambda(k))*alpha(h,k)/A(h,k)*Lc1(h,k);
                        Hinc(k,h) = Hinc(h,k);
                    end
                end
                g = -(eig(Hecc))'/2/pi;
                s = -(eig(Hinc))'/2/pi;
            
                for k = 1 : 8
                    if abs(s(k)) < 1e-10
                        s(k) = 0;
                    end
                end
            
                global fid_g fid_s
   
                if strcmp(num2str(fid_g), '')
                    fid_g = fopen( 'Eigenfreq_g.txt', 'wt');
                    fprintf(fid_g, '%%*************************************************************************************************\n');
                    fprintf(fid_g, '%%             Eigenfrequencies for Eccentricity-Longitude of pericenter (rev/yr)              \n');
                    fprintf(fid_g, '%%*************************************************************************************************\n');
                end
                fprintf(fid_g, [num2str(g), '\n']);
            
                if strcmp(num2str(fid_s), '')
                    fid_s = fopen( 'Eigenfreq_s.txt', 'wt');
                    fprintf(fid_s, '%%*************************************************************************************************\n');
                    fprintf(fid_s, '%%             Eigenfrequencies for Inclination-Longitude of the node (rev/yr)              \n');
                    fprintf(fid_s, '%%*************************************************************************************************\n');
                end
                fprintf(fid_s, [num2str(s), '\n']);
                        
            end
            clear fid_g;
            clear fid_s;
            
            sma = sma_min : step : sma_max;
            load Eigenfreq_g.txt
            figure
            plot(sma, Eigenfreq_g)
            title('Eigenfrequencies - Eccentricity')
            xlabel([planet,' - Semimajor axis (AU)'],'FontSize',12)
            ylabel('revolution/yr','FontSize',12)
            load Eigenfreq_s.txt
            figure
            plot(sma, Eigenfreq_s)
            title('Eigenfrequencies - Inclination')
            xlabel([planet,' - Semimajor axis (AU)'],'FontSize',12)
            ylabel('revolution/yr','FontSize',12)
            clear all            
        end

        % Button pushed function: pushbutton_FFT
        function exec_FFT(app, event)
            global zecc zinc
            step = get(app.edit_step,'Value');
            total = get(app.edit_total,'Value');
            n_step = ceil(total/step);
            t = 0 : step : n_step*step;
            item = str2num(get(app.popupmenu_eig_planets,'Value'));
            list = get(app.popupmenu_eig_planets,'Items');
            planet = list{item};
            nump = 2^(floor(log2(n_step)));
            valCheck = get(app.check_log,'Value');
            freq_max = 2*pi/step;
            freq = [0 :freq_max/nump: freq_max];
            freq(nump) = [];
            freq = (- freq + freq_max/2)/(2*pi);
            
            F = fft(real(zecc(item,:)) + i*imag(zecc(item,:)), nump);
            F = F';
            figure
            Farray = sqrt(real(F).^2 + imag(F).^2);
            Farray1 = Farray(1:nump/2, 1);
            Farray2 = Farray(nump/2+1:nump, 1);
            Farray = [Farray2;  Farray1];
            if valCheck == 0;
                plot(freq, Farray/nump)
            else
                semilogy(freq, Farray/nump)
            end
            xlim([-0.5e-5 2.5e-5])
            title(['FFT for Eccentricity of ', planet])
            xlabel('Frequency (rev/yr)', 'FontSize',10), ylabel('Amplitude', 'FontSize',10)
            
            F = fft(real(zinc(item,:)) + i*imag(zinc(item,:)), nump);
            F = F';
            figure
            Farray = sqrt(real(F).^2 + imag(F).^2);
            Farray1 = Farray(1:nump/2, 1);
            Farray2 = Farray(nump/2+1:nump, 1);
            Farray = [Farray2;  Farray1];
            if valCheck == 0;
                plot(freq, Farray/nump)
            else
                semilogy(freq, Farray/nump)
            end
            xlim([-2.5e-5 0.5e-5])
            title(['FFT for Inclination of ', planet])
            xlabel('Frequency (rev/yr)', 'FontSize',10), ylabel('Amplitude', 'FontSize',10)            
        end

        % Button pushed function: pushbutton_FMFT
        function exec_FMFT(app, event)
            global zecc zinc
            LaplaceDir = cd;
            cd ..
            cd ..
            cd ..
            PhSpGeoDir = cd;
            cd(LaplaceDir)
            step = get(app.edit_step,'Value');
            total = get(app.edit_total,'Value');
            n_step = ceil(total/step);
            t = 0 : step : n_step*step;
            item = str2num(get(app.popupmenu_eig_planets,'Value'));
            list = get(app.popupmenu_eig_planets,'Items');
            planet = list{item};
            ndata = num2str(2^(floor(log2(n_step))));
            min_f = num2str(1000*str2num(get(app.edit_fromfreq,'Value')),'%6.12f');
            max_f = num2str(1000*str2num(get(app.edit_tofreq,'Value')),'%6.12f');
            flag_FMFT = num2str(get(app.popupmenu_FMFT_flag,'Value'));
            nfreq = get(app.edit_nfreq,'Value');
            data_sep = num2str(2*pi*100000*step,'%6.12f');
            
            if isdeployed
                hhh = helpdlg(sprintf(['Please wait, LAPLACE is calculating. \n',...
                    'When this box will automatically shut down,\n'...
                    'in the LAPLACE folder you will find\n'...
                    'a text file containing the frequencies on KAM tori,\n'...
                    'along with a graphical representation.']),...
                    'Frequency Analysis');
            else
                hhh = helpdlg(sprintf(['Please wait, LAPLACE is calculating. \n',...
                    'When this box will automatically shut down,\n'...
                    'in the MATLAB editor you will find\n'...
                    'a text file containing the frequencies on KAM tori,\n'...
                    'along with a graphical representation.']),...
                    'Frequency Analysis');
            end
            
            ecc_x = real(zecc(item,:));
            ecc_y = -imag(zecc(item,:));
            fid = fopen(['input_ecc.txt'], 'wt');
            fprintf(fid, '%14.10f %14.10f %14.10f\n',...
                [t;  ecc_x;  ecc_y]);
            fclose(fid);
            
            cmd_string = ['!',PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                data_sep,' ',ndata,' <input_ecc.txt >FMFT_of_ecc.txt'];
            eval(cmd_string)
            delete input_ecc.txt
            
            [Freq  Amp Phase] = textread('FMFT_of_ecc.txt', '%f %f %f', 'headerlines', 4);
            Freq = Freq/1000;
            figure
            bar(Freq,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
            title(['FMFT for Eccentricity of ', planet])
            xlabel('Frequency (revolution/yr)', 'FontSize',10)
            
            fid = fopen(['FMFT_of_ecc.txt'], 'a');
            fprintf(fid,'   \n');
            fprintf(fid,'**************************************************************\n');
            fprintf(fid, ['Frequency analysis for the eccentricity of ',planet,'\n']);
            fprintf(fid,'**************************************************************\n');
            fclose(fid);
            if isdeployed
                % do nothing
            else
                edit FMFT_of_ecc.txt
            end

            inc_x = real(zinc(item,:));
            inc_y = -imag(zinc(item,:));
            fid = fopen(['input_inc.txt'], 'wt');
            fprintf(fid, '%14.10f %14.10f %14.10f\n',...
                [t;  inc_x;  inc_y]);
            fclose(fid);
            
            cmd_string = ['!',PhSpGeoDir,'/AddedODEsolver/bin/main_fmft ',min_f,' ',max_f,' ',flag_FMFT,' ',nfreq,' ',...
                data_sep,' ',ndata,' <input_inc.txt >FMFT_of_inc.txt'];
            eval(cmd_string)
            delete input_inc.txt
            
            [Freq  Amp Phase] = textread('FMFT_of_inc.txt', '%f %f %f', 'headerlines', 4);
            Freq = Freq/1000;
            figure
            bar(Freq,(log10(Amp) + ceil(abs(min(log10(Amp))))),0.2)
            title(['FMFT for Inclination of ', planet])
            xlabel('Frequency (revolution/yr)', 'FontSize',10)
            
            fid = fopen(['FMFT_of_inc.txt'], 'a');
            fprintf(fid,'   \n');
            fprintf(fid,'**************************************************************\n');
            fprintf(fid, ['Frequency analysis for the inclination of ',planet,'\n']);
            fprintf(fid,'**************************************************************\n');
            fclose(fid);
            if isdeployed
                % do nothing
            else
                edit FMFT_of_inc.txt
            end

            close(hhh)            
        end

        % Value changed function: edit_step
        function edit_step_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_total
        function edit_total_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_mass1
        function edit_mass1_ValueChanged(app, event)
           Disable_All
        end

        % Value changed function: edit_mass2
        function edit_mass2_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_mass3
        function edit_mass3_ValueChanged(app, event)
             Disable_All
        end

        % Value changed function: edit_mass4
        function edit_mass4_ValueChanged(app, event)
             Disable_All
        end

        % Value changed function: edit_mass5
        function edit_mass5_ValueChanged(app, event)
             Disable_All
        end

        % Value changed function: edit_mass6
        function edit_mass6_ValueChanged(app, event)
             Disable_All
        end

        % Value changed function: edit_mass7
        function edit_mass7_ValueChanged(app, event)
             Disable_All
        end

        % Value changed function: edit_mass8
        function edit_mass8_ValueChanged(app, event)
             Disable_All
        end

        % Value changed function: edit_sma1
        function edit_sma1_ValueChanged(app, event)
           Disable_All
        end

        % Value changed function: edit_sma2
        function edit_sma2_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_sma3
        function edit_sma3_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_sma4
        function edit_sma4_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_sma5
        function edit_sma5_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_sma6
        function edit_sma6_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_sma7
        function edit_sma7_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_sma8
        function edit_sma8_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_ecc1
        function edit_ecc1_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_ecc2
        function edit_ecc2_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_ecc3
        function edit_ecc3_ValueChanged(app, event)
            Disable_All 
        end

        % Value changed function: edit_ecc4
        function edit_ecc4_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_ecc5
        function edit_ecc5_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_ecc6
        function edit_ecc6_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_ecc7
        function edit_ecc7_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_ecc8
        function edit_ecc8_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_incl1
        function edit_incl1_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_incl2
        function edit_incl2_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_incl3
        function edit_incl3_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_incl4
        function edit_incl4_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_incl5
        function edit_incl5_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_incl6
        function edit_incl6_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_incl7
        function edit_incl7_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_incl8
        function edit_incl8_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_peri1
        function edit_peri1_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_peri2
        function edit_peri2_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_peri3
        function edit_peri3_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_peri4
        function edit_peri4_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_peri5
        function edit_peri5_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_peri6
        function edit_peri6_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_peri7
        function edit_peri7_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_peri8
        function edit_peri8_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_asc1
        function edit_asc1_ValueChanged(app, event)
          Disable_All
        end

        % Value changed function: edit_asc2
        function edit_asc2_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_asc3
        function edit_asc3_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_asc4
        function edit_asc4_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_asc5
        function edit_asc5_ValueChanged(app, event)
           Disable_All
        end

        % Value changed function: edit_asc6
        function edit_asc6_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_asc7
        function edit_asc7_ValueChanged(app, event)
            Disable_All
        end

        % Value changed function: edit_asc8
        function edit_asc8_ValueChanged(app, event)
            Disable_All
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LagrangeLaplacetheoryUIFigure = uifigure('Visible', 'off');
            app.LagrangeLaplacetheoryUIFigure.Name = 'Lagrange-Laplace theory';
            app.LagrangeLaplacetheoryUIFigure.Icon = 'Laplace.jpg';
            app.uipanel1 = uipanel(app.LagrangeLaplacetheoryUIFigure);
            app.uipanel1.Title = 'Initial conditions';
            app.uipanel1.Tag = 'uipanel1';
            app.edit_mass5 = uieditfield(app.uipanel1, 'text');
            app.edit_mass5.ValueChangedFcn = createCallbackFcn(app, @edit_mass5_ValueChanged, true);
            app.edit_mass5.Tag = 'edit_mass5';
            app.edit_mass5.Value = '9.54786104043042e-4';
            app.edit_mass6 = uieditfield(app.uipanel1, 'text');
            app.edit_mass6.ValueChangedFcn = createCallbackFcn(app, @edit_mass6_ValueChanged, true);
            app.edit_mass6.Tag = 'edit_mass6';
            app.edit_mass6.Value = '2.85836787194512e-4';
            app.edit_mass7 = uieditfield(app.uipanel1, 'text');
            app.edit_mass7.ValueChangedFcn = createCallbackFcn(app, @edit_mass7_ValueChanged, true);
            app.edit_mass7.Tag = 'edit_mass7';
            app.edit_mass7.Value = '4.37273164545892e-5';
            app.edit_mass8 = uieditfield(app.uipanel1, 'text');
            app.edit_mass8.ValueChangedFcn = createCallbackFcn(app, @edit_mass8_ValueChanged, true);
            app.edit_mass8.Tag = 'edit_mass8';
            app.edit_mass8.Value = '5.17759138448794e-5';
            app.edit_ecc5 = uieditfield(app.uipanel1, 'text');
            app.edit_ecc5.ValueChangedFcn = createCallbackFcn(app, @edit_ecc5_ValueChanged, true);
            app.edit_ecc5.Tag = 'edit_ecc5';
            app.edit_ecc5.Value = '0.04839266';
            app.edit_ecc6 = uieditfield(app.uipanel1, 'text');
            app.edit_ecc6.ValueChangedFcn = createCallbackFcn(app, @edit_ecc6_ValueChanged, true);
            app.edit_ecc6.Tag = 'edit_ecc6';
            app.edit_ecc6.Value = '0.0541506';
            app.edit_ecc7 = uieditfield(app.uipanel1, 'text');
            app.edit_ecc7.ValueChangedFcn = createCallbackFcn(app, @edit_ecc7_ValueChanged, true);
            app.edit_ecc7.Tag = 'edit_ecc7';
            app.edit_ecc7.Value = '0.04716771';
            app.edit_ecc8 = uieditfield(app.uipanel1, 'text');
            app.edit_ecc8.ValueChangedFcn = createCallbackFcn(app, @edit_ecc8_ValueChanged, true);
            app.edit_ecc8.Tag = 'edit_ecc8';
            app.edit_ecc8.Value = '0.00858587';
            app.edit_incl5 = uieditfield(app.uipanel1, 'text');
            app.edit_incl5.ValueChangedFcn = createCallbackFcn(app, @edit_incl5_ValueChanged, true);
            app.edit_incl5.Tag = 'edit_incl5';
            app.edit_incl5.Value = '1.30530';
            app.edit_incl6 = uieditfield(app.uipanel1, 'text');
            app.edit_incl6.ValueChangedFcn = createCallbackFcn(app, @edit_incl6_ValueChanged, true);
            app.edit_incl6.Tag = 'edit_incl6';
            app.edit_incl6.Value = '2.48446';
            app.edit_incl7 = uieditfield(app.uipanel1, 'text');
            app.edit_incl7.ValueChangedFcn = createCallbackFcn(app, @edit_incl7_ValueChanged, true);
            app.edit_incl7.Tag = 'edit_incl7';
            app.edit_incl7.Value = '0.76986';
            app.edit_incl8 = uieditfield(app.uipanel1, 'text');
            app.edit_incl8.ValueChangedFcn = createCallbackFcn(app, @edit_incl8_ValueChanged, true);
            app.edit_incl8.Tag = 'edit_incl8';
            app.edit_incl8.Value = '1.76917';
            app.edit_peri5 = uieditfield(app.uipanel1, 'text');
            app.edit_peri5.ValueChangedFcn = createCallbackFcn(app, @edit_peri5_ValueChanged, true);
            app.edit_peri5.Tag = 'edit_peri5';
            app.edit_peri5.Value = '14.72847983';
            app.edit_peri6 = uieditfield(app.uipanel1, 'text');
            app.edit_peri6.ValueChangedFcn = createCallbackFcn(app, @edit_peri6_ValueChanged, true);
            app.edit_peri6.Tag = 'edit_peri6';
            app.edit_peri6.Value = '92.59887831';
            app.edit_peri7 = uieditfield(app.uipanel1, 'text');
            app.edit_peri7.ValueChangedFcn = createCallbackFcn(app, @edit_peri7_ValueChanged, true);
            app.edit_peri7.Tag = 'edit_peri7';
            app.edit_peri7.Value = '170.95427630';
            app.edit_peri8 = uieditfield(app.uipanel1, 'text');
            app.edit_peri8.ValueChangedFcn = createCallbackFcn(app, @edit_peri8_ValueChanged, true);
            app.edit_peri8.Tag = 'edit_peri8';
            app.edit_peri8.Value = '44.96476227';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Neptune';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Tag = 'text2';
            app.text2.Text = 'Uranus';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Tag = 'text3';
            app.text3.Text = 'Saturn';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Jupiter';
            app.text11 = uilabel(app.uipanel1);
            app.text11.Tag = 'text11';
            app.text11.Text = {'Mass'; '(Sun mass)'};
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tag = 'text13';
            app.text13.Text = 'Eccentricity';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Tag = 'text14';
            app.text14.Text = {'Inclination'; '(deg)'};
            app.text15 = uilabel(app.uipanel1);
            app.text15.Tag = 'text15';
            app.text15.Text = {'Long. perihelion'; '(deg)'};
            app.edit_asc5 = uieditfield(app.uipanel1, 'text');
            app.edit_asc5.ValueChangedFcn = createCallbackFcn(app, @edit_asc5_ValueChanged, true);
            app.edit_asc5.Tag = 'edit_asc5';
            app.edit_asc5.Value = '100.55615';
            app.edit_asc6 = uieditfield(app.uipanel1, 'text');
            app.edit_asc6.ValueChangedFcn = createCallbackFcn(app, @edit_asc6_ValueChanged, true);
            app.edit_asc6.Tag = 'edit_asc6';
            app.edit_asc6.Value = '113.71504';
            app.edit_asc7 = uieditfield(app.uipanel1, 'text');
            app.edit_asc7.ValueChangedFcn = createCallbackFcn(app, @edit_asc7_ValueChanged, true);
            app.edit_asc7.Tag = 'edit_asc7';
            app.edit_asc7.Value = '74.22988';
            app.edit_asc8 = uieditfield(app.uipanel1, 'text');
            app.edit_asc8.ValueChangedFcn = createCallbackFcn(app, @edit_asc8_ValueChanged, true);
            app.edit_asc8.Tag = 'edit_asc8';
            app.edit_asc8.Value = '131.72169';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Tag = 'text17';
            app.text17.Text = {'Long. ascending'; 'node (deg)'};
            app.edit_sma5 = uieditfield(app.uipanel1, 'text');
            app.edit_sma5.ValueChangedFcn = createCallbackFcn(app, @edit_sma5_ValueChanged, true);
            app.edit_sma5.Tag = 'edit_sma5';
            app.edit_sma5.Value = '5.20336301';
            app.edit_sma6 = uieditfield(app.uipanel1, 'text');
            app.edit_sma6.ValueChangedFcn = createCallbackFcn(app, @edit_sma6_ValueChanged, true);
            app.edit_sma6.Tag = 'edit_sma6';
            app.edit_sma6.Value = '9.53707032';
            app.edit_sma7 = uieditfield(app.uipanel1, 'text');
            app.edit_sma7.ValueChangedFcn = createCallbackFcn(app, @edit_sma7_ValueChanged, true);
            app.edit_sma7.Tag = 'edit_sma7';
            app.edit_sma7.Value = '19.19126393';
            app.edit_sma8 = uieditfield(app.uipanel1, 'text');
            app.edit_sma8.ValueChangedFcn = createCallbackFcn(app, @edit_sma8_ValueChanged, true);
            app.edit_sma8.Tag = 'edit_sma8';
            app.edit_sma8.Value = '30.06896348';
            app.text31 = uilabel(app.uipanel1);
            app.text31.Tag = 'text31';
            app.text31.Text = {'Semimajor axis'; '(AU)'};
            app.edit_mass1 = uieditfield(app.uipanel1, 'text');
            app.edit_mass1.ValueChangedFcn = createCallbackFcn(app, @edit_mass1_ValueChanged, true);
            app.edit_mass1.Tag = 'edit_mass1';
            app.edit_mass1.Value = '1.66013679527193e-7';
            app.edit_mass2 = uieditfield(app.uipanel1, 'text');
            app.edit_mass2.ValueChangedFcn = createCallbackFcn(app, @edit_mass2_ValueChanged, true);
            app.edit_mass2.Tag = 'edit_mass2';
            app.edit_mass2.Value = '2.44783959796682e-6';
            app.edit_mass3 = uieditfield(app.uipanel1, 'text');
            app.edit_mass3.ValueChangedFcn = createCallbackFcn(app, @edit_mass3_ValueChanged, true);
            app.edit_mass3.Tag = 'edit_mass3';
            app.edit_mass3.Value = '3.04043292359547e-6';
            app.edit_mass4 = uieditfield(app.uipanel1, 'text');
            app.edit_mass4.ValueChangedFcn = createCallbackFcn(app, @edit_mass4_ValueChanged, true);
            app.edit_mass4.Tag = 'edit_mass4';
            app.edit_mass4.Value = '3.22714936215393e-7';
            app.edit_ecc1 = uieditfield(app.uipanel1, 'text');
            app.edit_ecc1.ValueChangedFcn = createCallbackFcn(app, @edit_ecc1_ValueChanged, true);
            app.edit_ecc1.Tag = 'edit_ecc1';
            app.edit_ecc1.Value = '0.20563069';
            app.edit_ecc2 = uieditfield(app.uipanel1, 'text');
            app.edit_ecc2.ValueChangedFcn = createCallbackFcn(app, @edit_ecc2_ValueChanged, true);
            app.edit_ecc2.Tag = 'edit_ecc2';
            app.edit_ecc2.Value = '0.00677323';
            app.edit_ecc3 = uieditfield(app.uipanel1, 'text');
            app.edit_ecc3.ValueChangedFcn = createCallbackFcn(app, @edit_ecc3_ValueChanged, true);
            app.edit_ecc3.Tag = 'edit_ecc3';
            app.edit_ecc3.Value = '0.016710219';
            app.edit_ecc4 = uieditfield(app.uipanel1, 'text');
            app.edit_ecc4.ValueChangedFcn = createCallbackFcn(app, @edit_ecc4_ValueChanged, true);
            app.edit_ecc4.Tag = 'edit_ecc4';
            app.edit_ecc4.Value = '0.09341233';
            app.edit_incl1 = uieditfield(app.uipanel1, 'text');
            app.edit_incl1.ValueChangedFcn = createCallbackFcn(app, @edit_incl1_ValueChanged, true);
            app.edit_incl1.Tag = 'edit_incl1';
            app.edit_incl1.Value = '7.00487';
            app.edit_incl2 = uieditfield(app.uipanel1, 'text');
            app.edit_incl2.ValueChangedFcn = createCallbackFcn(app, @edit_incl2_ValueChanged, true);
            app.edit_incl2.Tag = 'edit_incl2';
            app.edit_incl2.Value = '3.39471';
            app.edit_incl3 = uieditfield(app.uipanel1, 'text');
            app.edit_incl3.ValueChangedFcn = createCallbackFcn(app, @edit_incl3_ValueChanged, true);
            app.edit_incl3.Tag = 'edit_incl3';
            app.edit_incl3.Value = '0';
            app.edit_incl4 = uieditfield(app.uipanel1, 'text');
            app.edit_incl4.ValueChangedFcn = createCallbackFcn(app, @edit_incl4_ValueChanged, true);
            app.edit_incl4.Tag = 'edit_incl4';
            app.edit_incl4.Value = '1.85061';
            app.edit_peri1 = uieditfield(app.uipanel1, 'text');
            app.edit_peri1.ValueChangedFcn = createCallbackFcn(app, @edit_peri1_ValueChanged, true);
            app.edit_peri1.Tag = 'edit_peri1';
            app.edit_peri1.Value = '77.45779628';
            app.edit_peri2 = uieditfield(app.uipanel1, 'text');
            app.edit_peri2.ValueChangedFcn = createCallbackFcn(app, @edit_peri2_ValueChanged, true);
            app.edit_peri2.Tag = 'edit_peri2';
            app.edit_peri2.Value = '131.60246718';
            app.edit_peri3 = uieditfield(app.uipanel1, 'text');
            app.edit_peri3.ValueChangedFcn = createCallbackFcn(app, @edit_peri3_ValueChanged, true);
            app.edit_peri3.Tag = 'edit_peri3';
            app.edit_peri3.Value = '102.93768193';
            app.edit_peri4 = uieditfield(app.uipanel1, 'text');
            app.edit_peri4.ValueChangedFcn = createCallbackFcn(app, @edit_peri4_ValueChanged, true);
            app.edit_peri4.Tag = 'edit_peri4';
            app.edit_peri4.Value = '-23.94362959';
            app.text32 = uilabel(app.uipanel1);
            app.text32.Tag = 'text32';
            app.text32.Text = 'Mars';
            app.text33 = uilabel(app.uipanel1);
            app.text33.Tag = 'text33';
            app.text33.Text = 'Earth';
            app.text34 = uilabel(app.uipanel1);
            app.text34.Tag = 'text34';
            app.text34.Text = 'Venus';
            app.text35 = uilabel(app.uipanel1);
            app.text35.Tag = 'text35';
            app.text35.Text = 'Mercury';
            app.edit_asc1 = uieditfield(app.uipanel1, 'text');
            app.edit_asc1.ValueChangedFcn = createCallbackFcn(app, @edit_asc1_ValueChanged, true);
            app.edit_asc1.Tag = 'edit_asc1';
            app.edit_asc1.Value = '48.33167';
            app.edit_asc2 = uieditfield(app.uipanel1, 'text');
            app.edit_asc2.ValueChangedFcn = createCallbackFcn(app, @edit_asc2_ValueChanged, true);
            app.edit_asc2.Tag = 'edit_asc2';
            app.edit_asc2.Value = '76.68069';
            app.edit_asc3 = uieditfield(app.uipanel1, 'text');
            app.edit_asc3.ValueChangedFcn = createCallbackFcn(app, @edit_asc3_ValueChanged, true);
            app.edit_asc3.Tag = 'edit_asc3';
            app.edit_asc3.Value = '348.73936';
            app.edit_asc4 = uieditfield(app.uipanel1, 'text');
            app.edit_asc4.ValueChangedFcn = createCallbackFcn(app, @edit_asc4_ValueChanged, true);
            app.edit_asc4.Tag = 'edit_asc4';
            app.edit_asc4.Value = '49.57854';
            app.edit_sma1 = uieditfield(app.uipanel1, 'text');
            app.edit_sma1.ValueChangedFcn = createCallbackFcn(app, @edit_sma1_ValueChanged, true);
            app.edit_sma1.Tag = 'edit_sma1';
            app.edit_sma1.Value = '0.38709893';
            app.edit_sma2 = uieditfield(app.uipanel1, 'text');
            app.edit_sma2.ValueChangedFcn = createCallbackFcn(app, @edit_sma2_ValueChanged, true);
            app.edit_sma2.Tag = 'edit_sma2';
            app.edit_sma2.Value = '0.72333199';
            app.edit_sma3 = uieditfield(app.uipanel1, 'text');
            app.edit_sma3.ValueChangedFcn = createCallbackFcn(app, @edit_sma3_ValueChanged, true);
            app.edit_sma3.Tag = 'edit_sma3';
            app.edit_sma3.Value = '1.0000000';
            app.edit_sma4 = uieditfield(app.uipanel1, 'text');
            app.edit_sma4.ValueChangedFcn = createCallbackFcn(app, @edit_sma4_ValueChanged, true);
            app.edit_sma4.Tag = 'edit_sma4';
            app.edit_sma4.Value = '1.52366231';
            app.uipanel4 = uipanel(app.LagrangeLaplacetheoryUIFigure);
            app.uipanel4.Title = 'Compute ...';
            app.uipanel4.Tag = 'uipanel4';
            app.pushbutton_Compute = uibutton(app.uipanel4, 'push');
            app.pushbutton_Compute.ButtonPushedFcn = createCallbackFcn(app, @exec_compute, true);
            app.pushbutton_Compute.Tag = 'pushbutton_Compute';
            app.pushbutton_Compute.Text = 'Compute';
            app.StepyrEditFieldLabel = uilabel(app.uipanel4);
            app.StepyrEditFieldLabel.Text = 'Step (yr)';
            app.edit_step = uieditfield(app.uipanel4, 'numeric');
            app.edit_step.ValueChangedFcn = createCallbackFcn(app, @edit_step_ValueChanged, true);
            app.edit_step.Value = 1000;
            app.TotaltimeyrLabel = uilabel(app.uipanel4);
            app.TotaltimeyrLabel.Text = 'Total time (yr)';
            app.edit_total = uieditfield(app.uipanel4, 'numeric');
            app.edit_total.ValueChangedFcn = createCallbackFcn(app, @edit_total_ValueChanged, true);
            app.edit_total.Value = 200000000;
            app.uipanel2 = uipanel(app.LagrangeLaplacetheoryUIFigure);
            app.uipanel2.Title = '... and plot';
            app.uipanel2.Tag = 'uipanel2';
            app.popupmenu_elements = uidropdown(app.uipanel2);
            app.popupmenu_elements.Items = {'Eccentricity complex vector', 'Eccentricity', 'Longitude of perihelion', 'Inclination complex vector', 'Inclination', 'Longitude of ascending node'};
            app.popupmenu_elements.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.popupmenu_elements.Tag = 'popupmenu_elements';
            app.popupmenu_elements.Value = '1';
            app.popupmenu_planets = uidropdown(app.uipanel2);
            app.popupmenu_planets.Items = {'Mercury', 'Venus', 'Earth', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune'};
            app.popupmenu_planets.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.popupmenu_planets.Tag = 'popupmenu_planets';
            app.popupmenu_planets.Value = '1';
            app.text18 = uilabel(app.uipanel2);
            app.text18.Tag = 'text18';
            app.text18.Text = 'of';
            app.pushbutton_Plot = uibutton(app.uipanel2, 'push');
            app.pushbutton_Plot.ButtonPushedFcn = createCallbackFcn(app, @exec_plot, true);
            app.pushbutton_Plot.Tag = 'pushbutton_Plot';
            app.pushbutton_Plot.Enable = 'off';
            app.pushbutton_Plot.Text = 'Plot';
            app.checkbox_Own = uicheckbox(app.uipanel2);
            app.checkbox_Own.Tag = 'checkbox_Own';
            app.checkbox_Own.Text = 'Own ecliptic plane';
            app.uipanel6 = uipanel(app.LagrangeLaplacetheoryUIFigure);
            app.uipanel6.Title = 'Frequency analysis';
            app.uipanel6.Tag = 'uipanel6';
            app.uipanel5 = uipanel(app.uipanel6);
            app.uipanel5.Tag = 'uipanel5';
            app.pushbutton_FMFT = uibutton(app.uipanel5, 'push');
            app.pushbutton_FMFT.ButtonPushedFcn = createCallbackFcn(app, @exec_FMFT, true);
            app.pushbutton_FMFT.Tag = 'pushbutton_FMFT';
            app.pushbutton_FMFT.Enable = 'off';
            app.pushbutton_FMFT.Text = 'FMFT';
            app.popupmenu_FMFT_flag = uidropdown(app.uipanel5);
            app.popupmenu_FMFT_flag.Items = {' FMFT base', ' FMFT linear correction', ' FMFT nonlinear correction'};
            app.popupmenu_FMFT_flag.ItemsData = {'1', '2', '3'};
            app.popupmenu_FMFT_flag.Tag = 'popupmenu_FMFT_flag';
            app.popupmenu_FMFT_flag.Value = '1';
            app.text40 = uilabel(app.uipanel5);
            app.text40.Tag = 'text40';
            app.text40.Text = 'with method';
            app.edit_tofreq = uieditfield(app.uipanel5, 'text');
            app.edit_tofreq.Tag = 'edit_tofreq';
            app.edit_tofreq.Value = '5';
            app.text39 = uilabel(app.uipanel5);
            app.text39.Tag = 'text39';
            app.text39.Text = 'and freq.';
            app.edit_fromfreq = uieditfield(app.uipanel5, 'text');
            app.edit_fromfreq.Tag = 'edit_fromfreq';
            app.edit_fromfreq.Value = '-5';
            app.text38 = uilabel(app.uipanel5);
            app.text38.Tag = 'text38';
            app.text38.Text = 'between freq.';
            app.text37 = uilabel(app.uipanel5);
            app.text37.Tag = 'text37';
            app.text37.Text = 'frequencies';
            app.edit_nfreq = uieditfield(app.uipanel5, 'text');
            app.edit_nfreq.Tag = 'edit_nfreq';
            app.edit_nfreq.Value = '8';
            app.text36 = uilabel(app.uipanel5);
            app.text36.Tag = 'text36';
            app.text36.Text = 'Compute the first';
            app.uipanel7 = uipanel(app.uipanel6);
            app.uipanel7.Tag = 'uipanel7';
            app.pushbutton_FFT = uibutton(app.uipanel7, 'push');
            app.pushbutton_FFT.ButtonPushedFcn = createCallbackFcn(app, @exec_FFT, true);
            app.pushbutton_FFT.Tag = 'pushbutton_FFT';
            app.pushbutton_FFT.Enable = 'off';
            app.pushbutton_FFT.Text = 'Fast Fourier Transform';
            app.check_log = uicheckbox(app.uipanel7);
            app.check_log.Tag = 'check_log';
            app.check_log.Text = 'log scale';
            app.popupmenu_eig_planets = uidropdown(app.uipanel6);
            app.popupmenu_eig_planets.Items = {'Mercury', 'Venus', 'Earth', 'Mars', 'Jupiter', 'Saturn', 'Uranus', 'Neptune'};
            app.popupmenu_eig_planets.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.popupmenu_eig_planets.Tag = 'popupmenu_eig_planets';
            app.popupmenu_eig_planets.Value = '1';
            app.uipanel8 = uipanel(app.uipanel6);
            app.uipanel8.Tag = 'uipanel8';
            app.text41 = uilabel(app.uipanel8);
            app.text41.Tag = 'text41';
            app.text41.Text = '<  Semimajor axis  <';
            app.edit_eig_from = uieditfield(app.uipanel8, 'text');
            app.edit_eig_from.Tag = 'edit_eig_from';
            app.edit_eig_from.Value = '0.32';
            app.edit_eig_to = uieditfield(app.uipanel8, 'text');
            app.edit_eig_to.Tag = 'edit_eig_to';
            app.edit_eig_to.Value = '0.44';
            app.edit_eig_step = uieditfield(app.uipanel8, 'text');
            app.edit_eig_step.Tag = 'edit_eig_step';
            app.edit_eig_step.Value = '5e-3';
            app.text42 = uilabel(app.uipanel8);
            app.text42.Tag = 'text42';
            app.text42.Text = 'with step';
            app.pushbutton_eig_compute = uibutton(app.uipanel8, 'push');
            app.pushbutton_eig_compute.ButtonPushedFcn = createCallbackFcn(app, @exec_eigen, true);
            app.pushbutton_eig_compute.Tag = 'pushbutton_eig_compute';
            app.pushbutton_eig_compute.Enable = 'off';
            app.pushbutton_eig_compute.Text = {'Compute and plot'; 'eigenfrequencies'};
            % Show the figure after all components are created
            app.LagrangeLaplacetheoryUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LaLa

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LagrangeLaplacetheoryUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LagrangeLaplacetheoryUIFigure)
        end
    end
end