classdef ThreeBodyPlanar < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Globalplanarsecular3bodyUIFigure  matlab.ui.Figure
        Plot3DPanel                     matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        H_height                        matlab.ui.control.NumericEditField
        HamiltoniansurfaceHeightEditFieldLabel  matlab.ui.control.Label
        pushbutton_plot_3D              matlab.ui.control.Button
        uipanel5                        matlab.ui.container.Panel
        edit_phi                        matlab.ui.control.NumericEditField
        aLabel_6                        matlab.ui.control.Label
        edit_xi1                        matlab.ui.control.NumericEditField
        aLabel_5                        matlab.ui.control.Label
        pushbutton_trjct                matlab.ui.control.Button
        slider_phi                      matlab.ui.control.Slider
        slider_xi1                      matlab.ui.control.Slider
        uipanel4                        matlab.ui.container.Panel
        edit_Tolerance                  matlab.ui.control.NumericEditField
        ToleranceLabel                  matlab.ui.control.Label
        edit_surf_num                   matlab.ui.control.NumericEditField
        NumberoflevellinesEditFieldLabel_3  matlab.ui.control.Label
        edit_Resolution                 matlab.ui.control.NumericEditField
        SmoothnessofthelevellinesLabel_2  matlab.ui.control.Label
        pushbutton_plot_num             matlab.ui.control.Button
        uipanel3                        matlab.ui.container.Panel
        edit_point_num                  matlab.ui.control.NumericEditField
        SmoothnessofthelevellinesLabel  matlab.ui.control.Label
        edit_surf_anal                  matlab.ui.control.NumericEditField
        NumberoflevellinesEditFieldLabel_2  matlab.ui.control.Label
        edit_series_anal                matlab.ui.control.NumericEditField
        TermsindevelopmentseriesLabel_2  matlab.ui.control.Label
        pushbutton_plot_anal            matlab.ui.control.Button
        uipanel2                        matlab.ui.container.Panel
        edit_series_sect                matlab.ui.control.NumericEditField
        TermsindevelopmentseriesLabel   matlab.ui.control.Label
        edit_surf_sect                  matlab.ui.control.NumericEditField
        NumberoflevellinesEditFieldLabel  matlab.ui.control.Label
        pushbutton_plot_sect            matlab.ui.control.Button
        uipanel1                        matlab.ui.container.Panel
        G_totLabel                      matlab.ui.control.Label
        edit_E2                         matlab.ui.control.NumericEditField
        aLabel_4                        matlab.ui.control.Label
        edit_E1                         matlab.ui.control.NumericEditField
        aLabel_3                        matlab.ui.control.Label
        edit_a2                         matlab.ui.control.NumericEditField
        aLabel_2                        matlab.ui.control.Label
        edit_a1                         matlab.ui.control.NumericEditField
        aLabel                          matlab.ui.control.Label
        edit_m2                         matlab.ui.control.NumericEditField
        mLabel_2                        matlab.ui.control.Label
        edit_m1                         matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        edit_m0                         matlab.ui.control.NumericEditField
        m_0EditFieldLabel               matlab.ui.control.Label
        edit_Gtot                       matlab.ui.control.Label
        text22                          matlab.ui.control.Label
        text5                           matlab.ui.control.Label
        text7                           matlab.ui.control.Label
        text1                           matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: edit_m0
        function edit_m0_ValueChanged(app, event)
            ThreeBodyPlanarParameters
            set(app.edit_Gtot,'Text',num2str(Gtotal,'%1.15g'));
            set(app.edit_xi1,'Value',xi1);
            val = (xi1 - ximin)/(ximax - ximin);
            set(app.slider_xi1,'Value',val);
        end

        % Value changed function: edit_m1
        function edit_m1_ValueChanged(app, event)
            ThreeBodyPlanarParameters
            set(app.edit_Gtot,'Text',num2str(Gtotal,'%1.15g'));
            set(app.edit_xi1,'Value',xi1);
            val = (xi1 - ximin)/(ximax - ximin);
            set(app.slider_xi1,'Value',val);
        end

        % Value changed function: edit_m2
        function edit_m2_ValueChanged(app, event)
            ThreeBodyPlanarParameters
            set(app.edit_Gtot,'Text',num2str(Gtotal,'%1.15g'));
            set(app.edit_xi1,'Value',xi1);
            val = (xi1 - ximin)/(ximax - ximin);
            set(app.slider_xi1,'Value',val);;
        end

        % Value changed function: edit_a1
        function edit_a1_ValueChanged(app, event)
            ThreeBodyPlanarParameters
            set(app.edit_Gtot,'Text',num2str(Gtotal,'%1.15g'));
            set(app.edit_xi1,'Value',xi1);
            val = (xi1 - ximin)/(ximax - ximin);
            set(app.slider_xi1,'Value',val);
        end

        % Value changed function: edit_a2
        function edit_a2_ValueChanged(app, event)
            ThreeBodyPlanarParameters
            set(app.edit_Gtot,'Text',num2str(Gtotal,'%1.15g'));
            set(app.edit_xi1,'Value',xi1);
            val = (xi1 - ximin)/(ximax - ximin);
            set(app.slider_xi1,'Value',val);
        end

        % Value changed function: edit_E1
        function edit_E1_ValueChanged(app, event)
            E1 = get(app.edit_E1,'Value');
            if (E1 < 0 | E1 > 1)
                opts = struct('WindowStyle','nonmodal','Interpreter','tex');
                warndlg('\fontsize{10} The allowed range for eccentricity is 0 \leq \itE \leq \rm1.','Warning', opts);
                set(app.edit_E1,'Value','0.5')
            end
            ThreeBodyPlanarParameters
            set(app.edit_Gtot,'Text',num2str(Gtotal,'%1.15g'));
            set(app.edit_xi1,'Value',xi1);
            val = (xi1 - ximin)/(ximax - ximin);
            set(app.slider_xi1,'Value',val);
        end

        % Value changed function: edit_E2
        function edit_E2_ValueChanged(app, event)
            E2 = get(app.edit_E2,'Value');
            if (E2 < 0 | E2 > 1)
                opts = struct('WindowStyle','nonmodal','Interpreter','tex');
                warndlg('\fontsize{10} The allowed range for eccentricity is 0 \leq \itE \leq \rm1.','Warning', opts);
                set(app.edit_E2,'Value','0.5')
            end
            ThreeBodyPlanarParameters
            set(app.edit_Gtot,'Text',num2str(Gtotal,'%1.15g'));
            set(app.edit_xi1,'Value',xi1);
            val = (xi1 - ximin)/(ximax - ximin);
            set(app.slider_xi1,'Value',val);
        end

        % Value changed function: edit_xi1
        function edit_xi1_ValueChanged(app, event)
            ThreeBodyPlanarParameters
            xi1 = get(app.edit_xi1,'Value');
            val = (xi1 - ximin)/(ximax - ximin);
            if (val < 0 | val > 1)
                opts = struct('WindowStyle','nonmodal','Interpreter','tex');
                warndlg('\fontsize{10} Before to change the numerical value of \xi_1 be sure that it is not out of the allowed range: check with the slider.','Warning', opts);
                val = get(app.slider_xi1,'Value');
                xi1 = ximin + val*(ximax - ximin);
                set(app.edit_xi1,'Value',xi1)
            else
                set(app.slider_xi1,'Value',val)
            end
        end

        % Value changed function: slider_xi1
        function slider_xi1_ValueChanged(app, event)
            ThreeBodyPlanarParameters
            val = get(app.slider_xi1,'Value');
            xi1 = ximin + val*(ximax - ximin);
            set(app.edit_xi1,'Value',xi1)
        end

        % Value changed function: edit_phi
        function edit_phi_ValueChanged(app, event)
            val = get(app.edit_phi,'Value');
            set(app.slider_phi,'Value',val)
        end

        % Value changed function: slider_phi
        function slider_phi_ValueChanged(app, event)
            val = get(app.slider_phi,'Value');
            set(app.edit_phi,'Value',val)
        end

        % Button pushed function: pushbutton_trjct
        function plot_trjct(app, event)
            ThreeBodyPlanarParameters
            if (Gtotal < 0 | Gtotal > 1)
                warndlg('Gtot must be positive or null and less than or equal to 1')
                return
            end
            xi1 = ximin + get(app.slider_xi1,'Value')*(ximax-ximin);
            set(app.edit_xi1,'Value',xi1)
            phi = get(app.edit_phi,'Value');
            s = 0 : 0.01 : 2*pi;
            G1 = (Gtot - xi1)/2;
            if G1 < 0
                opts = struct('WindowStyle','nonmodal','Interpreter','tex');
                warndlg('\fontsize{10}The angular momentun of \it m''\rm is < 0,  the motion of \it m''\rm is retrograde. The program does not handle this case.','Warning', opts)
            end
            G2 = (Gtot + xi1)/2;
            if G2 < 0
                opts = struct('WindowStyle','nonmodal','Interpreter','tex');
                warndlg('\fontsize{10}The angular momentun of \it m''''\rm is < 0, the motion of \it m''''\rm is retrograde. the program does not handle this case.','Warning', opts)
            end
            e1 = real(sqrt(1 - G1^2/L1^2));
            e2 = real(sqrt(1 - G2^2/L2^2));
            set(app.edit_E1,'Value',e1);
            set(app.edit_E2,'Value',e2);
            E1str = num2str(e1);
            E2str = num2str(e2);
            Eps1 = G1/L1;
            Eps2 = G2/L2;
            s1 = 0 : 0.01 : 2*pi;
            X1 = a1*(cos(s) - e1);
            Y1 = a1*Eps1*sin(s);
            X2 = cos(pi/180*phi)*a2*(cos(s) - e2) - sin(pi/180*phi)*a2*Eps2*sin(s);
            Y2 = sin(pi/180*phi)*a2*(cos(s) - e2) + cos(pi/180*phi)*a2*Eps2*sin(s);
            figure
            plot(-sigma1*X1,-sigma1*Y1,'LineWidth',2,'Color','r')
            hold on
            plot(sigma0*X1,sigma0*Y1,'LineWidth',2,'Color','b')
            plot(X2,Y2,'LineWidth',2,'Color','k')
            %s = -a2 : 0.01 : a2;
            %plot(s,0); plot(0,s)
            %legend('\it\sigma_0Q''','\it-\sigma_1Q''','\itQ''''')
            legend('\it  m_0','\it  m''','\it  m''''')
            axis equal
            title(['Orbits of the three masses with \itE'' = ', num2str(e1), '\rm and \itE'''' = ', num2str(e2),'.'], 'FontSize',10, 'FontWeight','normal')
            xlabel('\itQ_1', 'FontSize',14), ylabel('\itQ_2', 'FontSize',14)           
        end

        % Button pushed function: pushbutton_plot_3D
        function plot_3D(app, event)
            ThreeBodyPlanarParameters
            if (Gtotal < 0 | Gtotal > 1)
                warndlg('Gtot must be positive or null and less than or equal to 1')
                return
            end
            if (Gtotal == 1)
                return
            end
            %%%%%%%%% Section of reduced manifold and level lines of averaged Hp %%%%%%
            xi1 = ximin - 0.4*(ximax - ximin) : (ximax-ximin)/100 : ximax + 0.4*(ximax - ximin);
            xi2 = -2*L1*L2 : 4*L1*L2/1.8/100 : 2*L1*L2;
            xi3 = xi2;
            [XI1,XI3] = meshgrid(xi1,xi3);
            G1 = (Gtot - XI1)/2;
            G2 = (Gtot + XI1)/2;
            e1 = sqrt(1 - G1.^2/L1^2);
            e2 = sqrt(1 - G2.^2/L2^2);
            Eps2 = G2/L2;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            H2_H10
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            series_sect = get(app.edit_series_sect,'Value');
            if (series_sect < 2 ) | (series_sect > 10)
                opts = struct('WindowStyle','nonmodal','Interpreter','tex');
                warndlg('\fontsize{10}The number of terms in development series must be chosen between 2 and 10.','Warning', opts);
                return
            end
            Hp = H2;
            for k = 3 : series_sect
                ks = num2str(k);
                Hp = eval(['Hp + H',ks,';']);
            end
            Hp = m1*m2*Hp;
            Hp = 1e8*Hp;

            figure
            hold on
                s = ximin : (ximax-ximin)/200 : ximax;
                t = (0 : 2*pi/200 : 2*pi)';
                S = ones(size(s))'*s;
                T = t*ones(size(t))';
                X = S;
                Y = real(2*sqrt(L1^2-(Gtot - S).^2/4).*sqrt(L2^2-(Gtot + S).^2/4)).*sin(T);
                Z = real(2*sqrt(L1^2-(Gtot - S).^2/4).*sqrt(L2^2-(Gtot + S).^2/4)).*cos(T);
                surf(X,Y,Z,'FaceColor',[0.7 0.7 0.7],...
                    'EdgeColor','none',...
                    'FaceLighting','phong')

            [XI1,XI2,XI3] = meshgrid(xi1,xi2,xi3);
            center = 0.5*(length(xi1)+1);
            value = Hp(center, center);
%            Hp3 = repmat( Hp, 1, 1, length(xi1));
            Hp3 = repmat( Hp, [1 1 length(xi1)]);
            Hp3 = permute(Hp3,[3 2 1]);
            height = get(app.H_height,'Value');            
            isosurface(XI1,XI2,XI3,Hp3,height*value)
            colormap gray
            grid on
            view(30,20);
            camlight;
            material metal
            title({'Reduced manifold and';'level surfaces of averaged Hamiltonian'}, 'FontSize',12, 'FontWeight','normal')
            xlabel('\it\xi_1', 'FontSize',14), ylabel('\it\xi_2', 'FontSize',14), zlabel('\it\xi_3', 'FontSize',14)            
            hold off        
        end

        % Button pushed function: pushbutton_plot_sect
        function plot_sect(app, event)
            ThreeBodyPlanarParameters
            if (Gtotal < 0 | Gtotal > 1)
                warndlg('Gtot must be positive or null and less than or equal to 1')
                return
            end
            if (Gtotal == 1)
                return
            end
            %%%%%%%%% Section of reduced manifold and level lines of averaged Hp %%%%%%
            xi1 = ximin - 0.4*(ximax - ximin) : (ximax-ximin)/100 : ximax + 0.4*(ximax - ximin);
            xi2 = -2*L1*L2 : 4*L1*L2/1.8/100 : 2*L1*L2;
            xi3 = xi2;
            [XI1,XI3] = meshgrid(xi1,xi3);
            G1 = (Gtot - XI1)/2;
            G2 = (Gtot + XI1)/2;
            e1 = sqrt(1 - G1.^2/L1^2);
            e2 = sqrt(1 - G2.^2/L2^2);
            Eps2 = G2/L2;
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            H2_H10
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            series_sect = get(app.edit_series_sect,'Value');
            if (series_sect < 2 ) | (series_sect > 10)
                opts = struct('WindowStyle','nonmodal','Interpreter','tex');
                warndlg('\fontsize{10}The number of terms in development series must be chosen between 2 and 10.','Warning', opts);
                return
            end
            Hp = H2;
            for k = 3 : series_sect
                ks = num2str(k);
                Hp = eval(['Hp + H',ks,';']);
            end
            Hp = m1*m2*Hp;
            Hp = 1e8*Hp;
            
            figure
            surf_sect = get(app.edit_surf_sect,'Value');
            contour(XI1,XI3,Hp,surf_sect,'-k')
            hold on
            s = ximin : (ximax-ximin)/200 : ximax;
            XI1 = s;
            XI3 = real(2*sqrt(L1^2-(Gtot - s).^2/4).*sqrt(L2^2-(Gtot + s).^2/4));
            plot(XI1,XI3,'b','LineWidth',3);
            XI3 = -XI3;
            plot(XI1,XI3,'b','LineWidth',3);
            title({'Section of reduced manifold (blue)';'and level lines of averaged Hamiltonian (black)'}, 'FontSize',12, 'FontWeight','normal')
            xlabel('\it\xi_1', 'FontSize',14), ylabel('\it\xi_3', 'FontSize',14,'Rotation',0.0)
            hold off
        end

        % Button pushed function: pushbutton_plot_anal
        function plot_anal(app, event)
            ThreeBodyPlanarParameters
            if (Gtotal < 0 | Gtotal > 1)
                warndlg('Gtot must be positive or null and less than or equal to 1')
                return
            end
            
            if (Gtotal == 1)
                return
            end
            
            npoint = get(app.edit_point_num,'Value');
            xi1 = ximin : (ximax-ximin)/npoint : ximax;
            phi = -180 : 180/npoint : 180;
            [XI1,PHI] = meshgrid(xi1,phi);
            G1 = (Gtot - XI1)/2;
            G2 = (Gtot + XI1)/2;
            e1 = sqrt(abs(1 - G1.^2/L1^2));
            e2 = sqrt(abs(1 - G2.^2/L2^2));
            Eps2 = G2/L2;
            XI3 = -2*L1*L2*e1.*e2.*cos(pi/180*PHI);
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            H2_H10
            %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            series_anal = get(app.edit_series_anal,'Value');
            if (series_anal < 2 )| (series_anal > 10)
                opts = struct('WindowStyle','nonmodal','Interpreter','tex');
                warndlg('\fontsize{10}The number of terms in development series must be chosen between 2 and 10.','Warning', opts);
                return
            end
            Hp = H2;
            for k = 3 : series_anal
                ks = num2str(k);
                Hp = eval(['Hp + H',ks,';']);
            end
            Hp = m1*m2*Hp;
            %------------------ Add homogeneous rings simulating other planets ------
            %Hrings
            %Hp = 1e8*(Hp + Hring);
            %------------------------------------------------------------------------
            Hp = 1e8*Hp;
            if isreal(Hp)
                %do nothing
            else
                opts = struct('WindowStyle','nonmodal','Interpreter','tex');
                warndlg('\fontsize{10}Some values of Hp are not real.','Warning', opts);
                return
            end
            
            figure
            surf_anal = get(app.edit_surf_anal,'Value');
            contour(PHI,XI1,Hp,surf_anal,'-k')
            title('Action-Angle: \it\xi_1, \phi \rm (analytical method)', 'FontSize',12, 'FontWeight','normal')
            ylabel('\it\xi_1', 'FontSize',14,'Rotation',0.0), xlabel('\it\phi \rm(deg)', 'FontSize',14)
            set(gca,'XTickLabel',{['-150' char(176)];['-100' char(176)];['-50' char(176)];['0' char(176)];...
                ['50' char(176)];['100' char(176)];['150' char(176)]});            
        end

        % Button pushed function: pushbutton_plot_num
        function plot_num(app, event)
            ThreeBodyPlanarParameters            
            if (Gtotal < 0 | Gtotal > 1)
                warndlg('Gtot must be positive or null and less than or equal to 1')
                return
            end            
            if (Gtotal == 1)
                return
            end
            
            wait = waitbar(0,'Please, wait ...');
            resol = get(app.edit_Resolution,'Value');
            Z = zeros(resol+1);
            h = 1;  k = 0;
            for xi1 = ximin : (ximax-ximin)/resol : ximax;
                for phi = 0 : 180/resol : 180;
                    G1 = (Gtot - xi1)/2;
                    G2 = (Gtot + xi1)/2;
                    e1 = sqrt(abs(1 - G1^2/L1^2));
                    e2 = sqrt(abs(1 - G2^2/L2^2));
                    Eps1 = G1/L1;
                    Eps2 = G2/L2;
            
                    X1 = @(s1) a1*(cos(s1) - e1);
                    Y1 = @(s1) a1*Eps1*sin(s1);
                    X2 = @(s2) cos(pi/180*phi)*a2*(cos(s2) - e2) - sin(pi/180*phi)*a2*Eps2*sin(s2);
                    Y2 = @(s2) sin(pi/180*phi)*a2*(cos(s2) - e2) + cos(pi/180*phi)*a2*Eps2*sin(s2);
                    q1 = @(s1) a1*(1 - e1*cos(s1));
                    q2 = @(s2) a2*(1 - e2*cos(s2));
                    Hp = @(s1,s2) 1/(2*pi)^2*(q1(s1)*q2(s2)./(sigma1*sqrt(sigma1^2*q1(s1).^2 + q2(s2).^2 ...
                             + 2*sigma1*(X1(s1).*X2(s2) + Y1(s1).*Y2(s2)))) ...
                             + q1(s1)*q2(s2)./(sigma0*sqrt(sigma0^2*q1(s1).^2 + q2(s2).^2 ...
                             - 2*sigma0*(X1(s1).*X2(s2) + Y1(s1).*Y2(s2)))));
                    tol = get(app.edit_Tolerance,'Value');
                    Z(h,k+1) = dblquad(Hp,0,2*pi,0,2*pi,tol);
            
                    h = h + 1;
                end
                h = 1;
                k = k + 1;
                x = (k-1)/resol;
                waitbar(x,wait)
            end
            if isreal(Z)
                %do nothing
            else
                opts = struct('WindowStyle','nonmodal','Interpreter','tex');
                warndlg('\fontsize{10}Tolerance too stringent.','Warning', opts);
                return
            end
            xi1 = ximin : (ximax-ximin)/resol : ximax;
            phi = 0 : 180/resol : 180;
            [PHI,XI1] = meshgrid(phi,xi1);
            figure
            surf_num = get(app.edit_surf_num,'Value');
            contour(PHI,XI1,Z',surf_num,'-k')
            title('Action-Angle: \it\xi_1, \phi \rm (numerical method)', 'FontSize',12, 'FontWeight','normal')
            ylabel('\it\xi_1', 'FontSize',14,'Rotation',0.0), xlabel('\it\phi \rm(deg)', 'FontSize',14)
            set(gca,'XTickLabel',{['0' char(176)];['20' char(176)];['40' char(176)];['60' char(176)];...
                ['80' char(176)];['100' char(176)];['120' char(176)];['140' char(176)];...
                ['160' char(176)];['180' char(176)]});
            close(wait)           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Globalplanarsecular3bodyUIFigure = uifigure('Visible', 'off');
            app.Globalplanarsecular3bodyUIFigure.Name = 'Global planar secular 3-body';
            app.Globalplanarsecular3bodyUIFigure.Icon = 'Laplace.jpg';
            app.uipanel1 = uipanel(app.Globalplanarsecular3bodyUIFigure);
            app.uipanel1.Title = 'Input Data';
            app.uipanel1.Tag = 'uipanel1';
            app.text1 = uilabel(app.uipanel1);
            app.text1.Tag = 'text1';
            app.text1.Text = 'Masses';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Semimajor axes';
            app.text5 = uilabel(app.uipanel1);
            app.text5.Tag = 'text5';
            app.text5.Text = {'Total angular momentun'; '(normalized)'};
            app.text22 = uilabel(app.uipanel1);
            app.text22.Tag = 'text22';
            app.text22.Text = 'Eccentricities';
            app.edit_Gtot = uilabel(app.uipanel1);
            app.edit_Gtot.Tag = 'edit_Gtot';
            app.edit_Gtot.Text = '0.998846472419611';
            app.m_0EditFieldLabel = uilabel(app.uipanel1);
            app.m_0EditFieldLabel.Interpreter = 'latex';
            app.m_0EditFieldLabel.Text = '$m_0=$';
            app.edit_m0 = uieditfield(app.uipanel1, 'numeric');
            app.edit_m0.ValueChangedFcn = createCallbackFcn(app, @edit_m0_ValueChanged, true);
            app.edit_m0.Value = 1;
            app.mLabel = uilabel(app.uipanel1);
            app.mLabel.Interpreter = 'latex';
            app.mLabel.Text = '$m''=$';
            app.edit_m1 = uieditfield(app.uipanel1, 'numeric');
            app.edit_m1.ValueChangedFcn = createCallbackFcn(app, @edit_m1_ValueChanged, true);
            app.edit_m1.Value = 1.66e-07;
            app.mLabel_2 = uilabel(app.uipanel1);
            app.mLabel_2.Interpreter = 'latex';
            app.mLabel_2.Text = '$m''''=$';
            app.edit_m2 = uieditfield(app.uipanel1, 'numeric');
            app.edit_m2.ValueChangedFcn = createCallbackFcn(app, @edit_m2_ValueChanged, true);
            app.edit_m2.Value = 0.001;
            app.aLabel = uilabel(app.uipanel1);
            app.aLabel.Interpreter = 'latex';
            app.aLabel.Text = '$a''=$';
            app.edit_a1 = uieditfield(app.uipanel1, 'numeric');
            app.edit_a1.ValueChangedFcn = createCallbackFcn(app, @edit_a1_ValueChanged, true);
            app.edit_a1.Value = 0.3871;
            app.aLabel_2 = uilabel(app.uipanel1);
            app.aLabel_2.Interpreter = 'latex';
            app.aLabel_2.Text = '$a''''=$';
            app.edit_a2 = uieditfield(app.uipanel1, 'numeric');
            app.edit_a2.ValueChangedFcn = createCallbackFcn(app, @edit_a2_ValueChanged, true);
            app.edit_a2.Value = 5.2;
            app.aLabel_3 = uilabel(app.uipanel1);
            app.aLabel_3.Interpreter = 'latex';
            app.aLabel_3.Text = '$E''=$';
            app.edit_E1 = uieditfield(app.uipanel1, 'numeric');
            app.edit_E1.ValueChangedFcn = createCallbackFcn(app, @edit_E1_ValueChanged, true);
            app.edit_E1.Value = 0.6;
            app.aLabel_4 = uilabel(app.uipanel1);
            app.aLabel_4.Interpreter = 'latex';
            app.aLabel_4.Text = '$E''''=$';
            app.edit_E2 = uieditfield(app.uipanel1, 'numeric');
            app.edit_E2.ValueChangedFcn = createCallbackFcn(app, @edit_E2_ValueChanged, true);
            app.edit_E2.Value = 0.0478301586548614;
            app.G_totLabel = uilabel(app.uipanel1);
            app.G_totLabel.Interpreter = 'latex';
            app.G_totLabel.Text = '$G_{tot}=$';
            app.uipanel2 = uipanel(app.Globalplanarsecular3bodyUIFigure);
            app.uipanel2.Title = 'Plot vertical section';
            app.uipanel2.Tag = 'uipanel2';
            app.pushbutton_plot_sect = uibutton(app.uipanel2, 'push');
            app.pushbutton_plot_sect.ButtonPushedFcn = createCallbackFcn(app, @plot_sect, true);
            app.pushbutton_plot_sect.Tag = 'pushbutton_plot_sect';
            app.pushbutton_plot_sect.Text = 'Plot';
            app.NumberoflevellinesEditFieldLabel = uilabel(app.uipanel2);
            app.NumberoflevellinesEditFieldLabel.Text = {'Number of '; 'level lines'};
            app.edit_surf_sect = uieditfield(app.uipanel2, 'numeric');
            app.edit_surf_sect.Value = 20;
            app.TermsindevelopmentseriesLabel = uilabel(app.uipanel2);
            app.TermsindevelopmentseriesLabel.Text = {'Number of terms in'; 'the series expansion'};
            app.edit_series_sect = uieditfield(app.uipanel2, 'numeric');
            app.edit_series_sect.Value = 10;
            app.uipanel3 = uipanel(app.Globalplanarsecular3bodyUIFigure);
            app.uipanel3.Title = 'Action-Angle (analytical method)';
            app.uipanel3.Tag = 'uipanel3';
            app.pushbutton_plot_anal = uibutton(app.uipanel3, 'push');
            app.pushbutton_plot_anal.ButtonPushedFcn = createCallbackFcn(app, @plot_anal, true);
            app.pushbutton_plot_anal.Tag = 'pushbutton_plot_anal';
            app.pushbutton_plot_anal.Text = 'Plot';
            app.TermsindevelopmentseriesLabel_2 = uilabel(app.uipanel3);
            app.TermsindevelopmentseriesLabel_2.Text = {'Number of terms in'; 'the series expansion'};
            app.edit_series_anal = uieditfield(app.uipanel3, 'numeric');
            app.edit_series_anal.Value = 10;
            app.NumberoflevellinesEditFieldLabel_2 = uilabel(app.uipanel3);
            app.NumberoflevellinesEditFieldLabel_2.Text = {'Number of '; 'level lines'};
            app.edit_surf_anal = uieditfield(app.uipanel3, 'numeric');
            app.edit_surf_anal.Value = 20;
            app.SmoothnessofthelevellinesLabel = uilabel(app.uipanel3);
            app.SmoothnessofthelevellinesLabel.Text = {'Smoothness'; 'of the level lines'};
            app.edit_point_num = uieditfield(app.uipanel3, 'numeric');
            app.edit_point_num.Value = 40;
            app.uipanel4 = uipanel(app.Globalplanarsecular3bodyUIFigure);
            app.uipanel4.Title = 'Action-Angle (numerical method)';
            app.uipanel4.Tag = 'uipanel4';
            app.pushbutton_plot_num = uibutton(app.uipanel4, 'push');
            app.pushbutton_plot_num.ButtonPushedFcn = createCallbackFcn(app, @plot_num, true);
            app.pushbutton_plot_num.Tag = 'pushbutton_plot_num';
            app.pushbutton_plot_num.Text = 'Plot';
            app.SmoothnessofthelevellinesLabel_2 = uilabel(app.uipanel4);
            app.SmoothnessofthelevellinesLabel_2.Text = {'Smoothness'; 'of the level lines'};
            app.edit_Resolution = uieditfield(app.uipanel4, 'numeric');
            app.edit_Resolution.Value = 10;
            app.NumberoflevellinesEditFieldLabel_3 = uilabel(app.uipanel4);
            app.NumberoflevellinesEditFieldLabel_3.Text = {'Number of '; 'level lines'};
            app.edit_surf_num = uieditfield(app.uipanel4, 'numeric');
            app.edit_surf_num.Value = 20;
            app.ToleranceLabel = uilabel(app.uipanel4);
            app.ToleranceLabel.Text = 'Tolerance';
            app.edit_Tolerance = uieditfield(app.uipanel4, 'numeric');
            app.edit_Tolerance.Value = 1e-06;
            app.uipanel5 = uipanel(app.Globalplanarsecular3bodyUIFigure);
            app.uipanel5.Title = 'Unperturbed orbits';
            app.uipanel5.Tag = 'uipanel5';
            app.slider_xi1 = uislider(app.uipanel5);
            app.slider_xi1.Limits = [0 1];
            app.slider_xi1.MajorTicks = [];
            app.slider_xi1.ValueChangedFcn = createCallbackFcn(app, @slider_xi1_ValueChanged, true);
            app.slider_xi1.MinorTicks = [];
            app.slider_xi1.Tag = 'slider_xi1';
            app.slider_xi1.Value = 0.1;
            app.slider_phi = uislider(app.uipanel5);
            app.slider_phi.Limits = [-180 180];
            app.slider_phi.MajorTicks = [];
            app.slider_phi.ValueChangedFcn = createCallbackFcn(app, @slider_phi_ValueChanged, true);
            app.slider_phi.MinorTicks = [];
            app.slider_phi.Tag = 'slider_phi';
            app.pushbutton_trjct = uibutton(app.uipanel5, 'push');
            app.pushbutton_trjct.ButtonPushedFcn = createCallbackFcn(app, @plot_trjct, true);
            app.pushbutton_trjct.Tag = 'pushbutton_trjct';
            app.pushbutton_trjct.Text = 'Plot';
            app.aLabel_5 = uilabel(app.uipanel5);
            app.aLabel_5.Interpreter = 'latex';
            app.aLabel_5.Text = '$\xi_1=$';
            app.edit_xi1 = uieditfield(app.uipanel5, 'numeric');
            app.edit_xi1.ValueChangedFcn = createCallbackFcn(app, @edit_xi1_ValueChanged, true);
            app.edit_xi1.Value = 0.0022765;
            app.aLabel_6 = uilabel(app.uipanel5);
            app.aLabel_6.Interpreter = 'latex';
            app.aLabel_6.Text = '$\phi=$';
            app.edit_phi = uieditfield(app.uipanel5, 'numeric');
            app.edit_phi.ValueChangedFcn = createCallbackFcn(app, @edit_phi_ValueChanged, true);
            app.Plot3DPanel = uipanel(app.Globalplanarsecular3bodyUIFigure);
            app.Plot3DPanel.Title = 'Plot 3D';
            app.pushbutton_plot_3D = uibutton(app.Plot3DPanel, 'push');
            app.pushbutton_plot_3D.ButtonPushedFcn = createCallbackFcn(app, @plot_3D, true);
            app.pushbutton_plot_3D.Text = 'Plot';
            app.HamiltoniansurfaceHeightEditFieldLabel = uilabel(app.Plot3DPanel);
            app.HamiltoniansurfaceHeightEditFieldLabel.Text = 'Hamiltonian surface:   Height';
            app.H_height = uieditfield(app.Plot3DPanel, 'numeric');
            app.H_height.Value = 1;
            app.Label_2 = uilabel(app.Plot3DPanel);
            app.Label_2.Text = {'Modify the Height parameter to select a'; 'different level surface of the Hamiltonian'};
            % Show the figure after all components are created
            app.Globalplanarsecular3bodyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ThreeBodyPlanar

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Globalplanarsecular3bodyUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Globalplanarsecular3bodyUIFigure)
        end
    end
end